classdef MinPlotX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MinPlotXGUI                     matlab.ui.Figure
        MinPlotXMenu                    matlab.ui.container.Menu
        NewMinPlotXProjectMenu          matlab.ui.container.Menu
        LoadMinPlotXProjectMenu         matlab.ui.container.Menu
        SaveMinPlotXProjectMenu         matlab.ui.container.Menu
        SaveMinPlotXProjectAsMenu       matlab.ui.container.Menu
        CheckVersionMenu                matlab.ui.container.Menu
        ExportResultsMenu               matlab.ui.container.Menu
        CustomExportMenu                matlab.ui.container.Menu
        ExportapfuResultsTableMenu      matlab.ui.container.Menu
        ExportStrucFMResultsTableMenu   matlab.ui.container.Menu
        ClearMinPlotXProjectMenu        matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        ImportfilesMenu                 matlab.ui.container.Menu
        ImportfolderMenu                matlab.ui.container.Menu
        AutoimportMenu                  matlab.ui.container.Menu
        AdvancedMenu                    matlab.ui.container.Menu
        MergeTablesMenu                 matlab.ui.container.Menu
        CalculateTotalfromDataMenu      matlab.ui.container.Menu
        DataCalculatorModuleMenu        matlab.ui.container.Menu
        AdvancedPlotModuleMenu          matlab.ui.container.Menu
        EasyPlotColormapMenu            matlab.ui.container.Menu
        EasyPlotSubdivideMenu           matlab.ui.container.Menu
        DocumentationMenu               matlab.ui.container.Menu
        OpenDocumentationMenu           matlab.ui.container.Menu
        InputOutputDocumentationMenu    matlab.ui.container.Menu
        Panel_2                         matlab.ui.container.Panel
        PlotResultsCheckBox             matlab.ui.control.CheckBox
        GridLayout10                    matlab.ui.container.GridLayout
        Button_3                        matlab.ui.control.StateButton
        FilterStringEditField           matlab.ui.control.EditField
        FilterStringEditFieldLabel      matlab.ui.control.Label
        DataCalculatorModuleButton      matlab.ui.control.Button
        DeleteButton                    matlab.ui.control.Button
        SamplesListBox                  matlab.ui.control.ListBox
        MineralDropDown                 matlab.ui.control.DropDown
        MineralDropDownLabel            matlab.ui.control.Label
        ImportFromFolder_Button         matlab.ui.control.Button
        LoadProject_Button              matlab.ui.control.Button
        GeoCPlotButton_2                matlab.ui.control.Button
        SaveDBProjectAs_Button          matlab.ui.control.Button
        SaveDBProject_Button            matlab.ui.control.Button
        ImportFromFiles_Button          matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        ColorbarLabelEditFieldLabel     matlab.ui.control.Label
        ColorbarLabelEditField          matlab.ui.control.EditField
        CbarVarDropDown                 matlab.ui.control.DropDown
        CbarVarDropDownLabel            matlab.ui.control.Label
        GridLayout22                    matlab.ui.container.GridLayout
        DivColDropDown                  matlab.ui.control.DropDown
        DivColDropDownLabel             matlab.ui.control.Label
        MarkerSizeEditField             matlab.ui.control.NumericEditField
        SymbolDropDown                  matlab.ui.control.DropDown
        SymbolDropDownLabel             matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        SwitchtoMultiCalcButton         matlab.ui.control.Button
        SaveAdvancedResultsCheckBox     matlab.ui.control.CheckBox
        MineralSpecificOptionsPanel     matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        MineralSpecificOptionsTable     matlab.ui.control.Table
        MnOptionsPanel                  matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        Mn3MnTotalEditField             matlab.ui.control.NumericEditField
        Mn3MnTotalEditFieldLabel        matlab.ui.control.Label
        CalculatewithMn3CheckBox        matlab.ui.control.CheckBox
        UseMn3MnTotalRatioFromTableCheckBox  matlab.ui.control.CheckBox
        UseGlobalMn3MnTotalRatioCheckBox  matlab.ui.control.CheckBox
        FeOptionsPanel                  matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        Fe3FeTotalEditField             matlab.ui.control.NumericEditField
        Fe3FeTotalEditFieldLabel        matlab.ui.control.Label
        CalculatewithFe3CheckBox        matlab.ui.control.CheckBox
        EstimateFe3fromstoichiometryCheckBox  matlab.ui.control.CheckBox
        UseGlobalFe3FeTotalRatioCheckBox  matlab.ui.control.CheckBox
        UseGlobalFe3tetraCheckBox       matlab.ui.control.CheckBox
        UseFe3FeTotalRatioFromTableCheckBox  matlab.ui.control.CheckBox
        UseKnownFe3tetraFromTableCheckBox  matlab.ui.control.CheckBox
        Fe3TetraEditFieldLabel          matlab.ui.control.Label
        Fe3TetraEditField               matlab.ui.control.NumericEditField
        ExportModuleButton              matlab.ui.control.Button
        QuickplotButton                 matlab.ui.control.Button
        EasyPlotButton                  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        ChemistryTab                    matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        InputTable                      matlab.ui.control.Table
        MetaTableTab                    matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        MetaTable                       matlab.ui.control.Table
        WorkingDirectoryEditField       matlab.ui.control.EditField
        WorkingDirectoryEditFieldLabel  matlab.ui.control.Label
        CalculateallButton              matlab.ui.control.Button
        Switch                          matlab.ui.control.Switch
        SelectDirButton                 matlab.ui.control.Button
        CalculateButton                 matlab.ui.control.Button
        OutputTable                     matlab.ui.control.Table
        MultiMineralCalcTab             matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        FindMineralButton               matlab.ui.control.Button
        DeselectAllButton               matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        ShowfulltableCheckBox           matlab.ui.control.CheckBox
        AddColFilt                      matlab.ui.control.Button
        ResetColFilt                    matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        MultiMineralCalcButton          matlab.ui.control.Button
        MultiMinCalcTable               matlab.ui.control.Table
        ResultsTab                      matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        GridLayout_2                    matlab.ui.container.GridLayout
        Options_LowerColor              matlab.ui.control.DropDown
        Options_InvertColormap          matlab.ui.control.CheckBox
        Options_ColormapDropDown        matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        Options_LogColormap             matlab.ui.control.CheckBox
        Options_ColormapResEditField    matlab.ui.control.NumericEditField
        Options_UpperColor              matlab.ui.control.DropDown
        Options_UpperCheckBox           matlab.ui.control.CheckBox
        Options_LowerCheckBox           matlab.ui.control.CheckBox
        ColorMapPreview                 matlab.ui.control.UIAxes
        EasyPlotModuleButton            matlab.ui.control.Button
        ExportModuleButton_2            matlab.ui.control.Button
        VisModuleButton                 matlab.ui.control.Button
        SendtoGeoCPlotButton            matlab.ui.control.Button
        SpotVisMinPlotButton            matlab.ui.control.Button
        AdvancedPlotModuleButton        matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        ExportTableMenu                 matlab.ui.container.Menu
    end


    properties (Access = private)

        ExportDir

        edit_state
        calculation_id=1
        SelectedNodes_MainTree
        MinPlotXVersion='2.01.50130'
        CurrentReleaseTag='MinPlotX_2.01.50130'


        %     TableDisplayLimit=10000 % Description
        d % uiprogressdlg handle
    end

    properties (Access = public)
        MinPlotXData

        MineralNames
        MineralCalcFunctions
        MineralNamesAlternatives

        active_entry

        installpath 

        ColorMaps
        ColorMapValues
        ColorScale

        MinPlotXFigures


        minerals_with_Fe3_option={'unknown','allanite','amphibole','chlorite','chloritoid','cordierite','epidote','garnet','ilmenite','lawsonite','mica','olivine','pyroxene','clinopyroxene','orthopyroxene','serpentine','oxyspinel','oxyspinel','spinel','magnetite','magnetite','staurolite','talc'};
        minerals_with_tetraFe3_option={'chlorite','mica','serpentine','talc'};
        minerals_with_Mn3_option={'unknown','lawsonite','epidote','allanite','ilmenite'};
        minerals_with_EstimateFe3fromstoichiometry={'amphibole','chloritoid','garnet','ilmenite','olivine','pyroxene','clinopyroxene','orthopyroxene','oxyspinel','spinel','magnetite'};

        f2r={'want_ferric_Fe3','UseKnownFe3','Fe3_ratio','UseKnownMn3','Mn3_ratio','UseKnowntetra_Fe3','tetra_Fe3','RecalcTotalFe','RecalcTotalMn','GUI_Fe3_Option','GUI_Fe3fromstoichiometry','GUI_tetraFe3_Option','GUI_Mn3_Option'};
    end

    methods (Access = private)

        function [data,headers,T,MetaTableInput] = import_table_fun(app,file,path,T)
            MetaTableInput=table();
            Ox={'Al2O3','As2O3','Au2O','B2O3','BaO','BeO','CO2','CaO','CeO2','Ce2O3',...
                'CoO','Cr2O3','Cs2O','CuO','Dy2O3','Er2O3','EuO','Eu2O3','FeO','Fe2O3',...
                'Ga2O3','Gd2O3','GeO2','H2O','HfO2','Ho2O3','K2O','La2O3','Li2O','Lu2O3',...
                'MgO','MnO','Mn2O3','MnO2','Mn3O4','MoO3','Na2O','Nb2O5','Nd2O3','NiO','P2O5','PbO',...
                'Pr2O3','Rb2O','SO3','Sb2O3','Sc2O3','SiO2','Sm2O3','SnO2','SrO','Ta2O5',...
                'Tb2O3','ThO2','TiO2','Ti2O3','Tm2O3','UO2','U3O8','V2O3','V2O5','WO3',...
                'Y2O3','Yb2O3','ZnO','ZrO2',...
                'Total',...
                'H','He','Li','Be','B','C','N','O','F','Ne','Na','Mg','Al','Si','P','S','Cl',...
                'Ar','K','Ca','Sc','Ti','V','Cr','Mn','Fe','Co','Ni','Cu','Zn','Ga','Ge','As',...
                'Se','Br','Kr','Rb','Sr','Y','Zr','Nb','Mo','Tc','Ru','Rh','Pd','Ag','Cd','In',...
                'Sn','Sb','Te','I','Xe','Cs','Ba','La','Ce','Pr','Nd','Pm','Sm','Eu','Gd','Tb',...
                'Dy','Ho','Er','Tm','Yb','Lu','Hf','Ta','W','Re','Os','Ir','Pt','Au','Hg','Tl',...
                'Pb','Bi','Po','At','Rn','Fr','Ra','Ac','Th','Pa','U','Np','Pu','Am','Cm','Bk',...
                'Cf','Es','Fm','Md','No','Lr','Rf','Db','Sg','Bh','Hs','Mt','Uun','Uuu','Uub'};


            if not(isempty(file))
                T=readtable(fullfile(path,file));
                %
                if isequal(T.Properties.VariableNames(1:2),[{'Var1'},{'Var2'}])

                    tmp=readcell(fullfile(path,file));

                    row_save=[];
                    for n=1:67%numel(Ox)
                        searchTerm = Ox{n};
                        %  matches = ~cellfun(@isempty,(cellfun(@(x) startsWith(string(x), searchTerm), tmp,'UniformOutput', false)));
                        matches = ~cellfun(@isempty,(cellfun(@(x) strfind(string(x), searchTerm), tmp,'UniformOutput', false)));
                        [matchingIndicesrow, ~] = find(matches);
                        row_save=[row_save matchingIndicesrow']; %#ok
                    end

                    ImportOptions_ = detectImportOptions(fullfile(path,file),'NumHeaderLines', mode(row_save)-1,'readvariablenames',true);
                    %             %     T=readtable(fullfile(path,file),ImportOptions_, 'Sheet', S_names(ii));  % Recommended fcn: readtable()  or readmatrix() or readcell()
                    T=readtable(fullfile(path,file),ImportOptions_);  % Recommended fcn: readtable()  or readmatrix() or readcell()
                    if isequal(T.Properties.VariableNames(1:2),[{'Var1'},{'Var2'}])
                        ImportOptions_ = detectImportOptions(fullfile(path,file),'NumHeaderLines', mode(row_save));
                        T=readtable(fullfile(path,file),ImportOptions_);  % Recommended fcn: readtable()  or readmatrix() or readcell()

                    end

                end
                %[data,headers,T, MetaTableInput] =import_table_fun(app,[],[],T);%#ok
            end

            headers=T.Properties.VariableNames; %saves a list of the oxides in the header

            if sum(strcmpi(T.Properties.VariableNames,'x'))==1 && sum(strcmpi(T.Properties.VariableNames,'y'))==1 && find(strcmpi(T.Properties.VariableNames,'y'))-find(strcmpi(T.Properties.VariableNames,'x'))==1
                T.Properties.VariableNames{strcmpi(T.Properties.VariableNames,'x')}='x_pos';
                T.Properties.VariableNames{strcmpi(T.Properties.VariableNames,'y')}='y_pos';
                if sum(strcmpi(T.Properties.VariableNames,'z'))==1 && find(strcmpi(T.Properties.VariableNames,'z'))-find(strcmpi(T.Properties.VariableNames,'y_pos'))==1
                    T.Properties.VariableNames{strcmpi(T.Properties.VariableNames,'z')}='z_pos';
                end
                headers=T.Properties.VariableNames; %saves a list of the oxides in the header
            end

            if any(contains(headers,'_Mass__')) %'(Mass%)
                headers=replace(headers,'_Mass__','');
            end

            if any(contains(headers,'_WT__')) %'(Mass%)
                headers=replace(headers,'_WT__','');
            end
            %if % Here changes of headers can be implemented depending
            % on the source of the import data
            %% check for meta data
            % see if anything else than {SiO2, ....}
            if any(not(ismember(lower(headers),lower(Ox))))
                try
                    data_ids=ismember(lower(headers),lower(Ox));

                    if sum(data_ids)==0

                        for n=1:67
                            searchTerm = Ox{n};
                            matches = find(startsWith(headers,searchTerm));
                            if not(isempty(matches))
                                headers(matches(1))=Ox(n);
                            end
                        end
                        T.Properties.VariableNames=headers;
                        data_ids=ismember(lower(headers),lower(Ox));
                    end

                    if any(any(cellfun(@ischar,table2cell(T(:,data_ids))),'all'))
                        T_data_ids=T(:,data_ids);


                        id_2str2double=find(any(cellfun(@ischar,table2cell(T(:,data_ids)))));
                        for st_n=1:numel(id_2str2double)

                            T_data_ids.(id_2str2double(st_n))=str2double(T_data_ids.(id_2str2double(st_n)));
                        end
                        data=table2array(T_data_ids); %data converted to array

                    else
                        data=table2array(T(:,data_ids)); %data converted to array
                    end
                    if iscell(data) && any(not(cellfun(@isnumeric,data)),'all')
                        disp('Fields not containg numeric values replaced with 0')
                        data(not(cellfun(@isnumeric,data)))=0;
                    end
                catch ME
                    DisplayErrorNG(app,ME,'Import not numeric input table data')
                end

                data(isnan(data))=0;
                data(isinf(data))=0;

                %                 T(sum(data==0,2)==size(data,1),:)=[];
                %                 data(sum(data==0,2)==size(data,1),:)=[];
                headers=headers(ismember(lower(headers),lower(Ox))); %saves a list of the oxides in the header
                for n=1:numel(headers)
                    if ismember(lower(headers(n)),lower(Ox))
                        headers(n)=Ox(ismember(lower(Ox),lower(headers(n))));
                    end
                end
                MetaTableInput=TableVarImportChange_fun(app,T(:,~data_ids));

                if any(strcmp(MetaTableInput.Properties.VariableNames,'Mineral'))
                    for l=1:numel( MetaTableInput.Mineral)
                        MetaTableInput.Mineral(l) = {CheckMinName(app,MetaTableInput.Mineral(l))};
                        %MetaTableInput.Mineral(l) = CheckMinName(app,MetaTableInput.Mineral(l));
                    end
                end
            else
                data=table2array(T); %data converted to array
                data(isnan(data))=0;
                data(isinf(data))=0;
                T(sum(data==0,2)==size(data,1),:)=[];
                data(sum(data==0,2)==size(data,1),:)=[];

            end


        end

        function update_meta_table(app)
            if not(isempty(app.active_entry)) && numel(app.active_entry)==1
                %                 if size(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable,1)>app.TableDisplayLimit
                %                     app.MetaTable.Data={'InputTable to large to display'};
                %                     app.MetaTable.ColumnName={'comment'};
                %                 else
                app.MetaTable.Data=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable;
                app.MetaTable.ColumnName=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames;
                %   end
            else
                app.MetaTable.Data=[];
            end
        end


        function ResetTree(app,Tree2Update)

            if not(exist('tree2update','var'))
                Tree2Update=app.Tree;
            end

            if ~isempty(Tree2Update.SelectedNodes)
                app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            end

            a = Tree2Update.Children;
            a.delete;


        end


        function  update_MultiCalcTable_table(app)

            checkedNodes = app.Tree.CheckedNodes;
            if not(isempty(checkedNodes)) || not(isempty(app.Tree.Children))

                T=get_compiled_table(app,app.active_entry);

                T_full=T;

                all_funs=[];
                if not(isempty(checkedNodes))

                    NodeText={checkedNodes.Text}';
                    NodeData=cell2mat({checkedNodes.NodeData}');
                    MineralCalcFunction=NodeText(NodeData(:,2)>0|contains(NodeText,'unknown'));
                    T=T(contains(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction,MineralCalcFunction),:);

                    all_funs= MineralCalcFunction';
                end

                if isempty(all_funs)
                    all_funs=table2cell(app.MineralCalcFunctions);
                    all_funs=string(all_funs(:));
                    all_funs=all_funs(not(ismissing(all_funs)));
                    all_funs= unique((all_funs));
                    all_funs=cellstr(all_funs)';
                end
                % here decise which columns to remove and order of columns
                if isfield(app.MinPlotXData,'ColumnFilter') && not(isempty(app.MinPlotXData.ColumnFilter))
                    T=T(:,ismember(T.Properties.VariableNames,app.MinPlotXData.ColumnFilter));
                end

                if app.ShowfulltableCheckBox.Value==false
                    T=T(:,ismember(T.Properties.VariableNames,{'SpotId','Point','Mineral','MineralCalcFunction','AnalysisName','Sample','AnalysisType'})); %ATH change opitons
                end


                MultiMinCalcTable_Data=table2cell(T);
                MultiMinCalcTable_ColumnName=T.Properties.VariableNames;

                MultiMinCalcTable_ColumnEditable=false(1,size(T,2));
                MultiMinCalcTable_ColumnEditable(ismember(MultiMinCalcTable_ColumnName,'MineralCalcFunction'))=true;
                MultiMinCalcTable_ColumnEditable(ismember(MultiMinCalcTable_ColumnName,'Mineral'))=true;


                [u_MCF, ~,  uid]=unique(T.MineralCalcFunction);

                for k=1:numel(u_MCF)
                    MinCalc_fun=char(u_MCF(k));
                    try
                        if not(strcmp('unknown',u_MCF(k))) %%%%ATH here add the check if any options are set differnet from the defaults
                            try

                                [~, ~,options] = feval(char(u_MCF(k)),[],[],[]);
                            catch ME %#ok
                                %      DisplayErrorNG(app,ME,'update_MultiCalcTable_table')

                                options=struct();
                            end
                        else
                            continue
                        end
                        %options=rmfield(options,{'plot_state','plot_settings','save_state','PathName'});
                        fns=fieldnames(options);


                        if not(isempty(fns))
                            for n=1:numel(fns)
                                fn=fns(n);


                                thx_cell=cell(sum(uid==k),1);
                                thx_cell(:,1)={options.(char(fns(n))).Value};

                                if not(ismember(fns(n),MultiMinCalcTable_ColumnName))
                                    MultiMinCalcTable_Data(uid==k,end+1)=thx_cell;%#ok

                                    MultiMinCalcTable_ColumnName(end+1)=fns(n);%#ok
                                    MultiMinCalcTable_ColumnEditable(end+1)=true;%#ok
                                else
                                    MultiMinCalcTable_ColumnEditable(ismember(fns(n),MultiMinCalcTable_ColumnName))=true;
                                    MultiMinCalcTable_Data(uid==k,ismember(fns(n),MultiMinCalcTable_ColumnName))=thx_cell;

                                end

                                %% % check if input table has values are
                                SpotIds=find(uid==k);

                                if ismember(fns(n),T_full(uid==k,:).Properties.VariableNames)
                                    thx=T_full(uid==k,:).(char(fns(n)));
                                    if isnumeric(T_full(uid==k,:).(char(fns(n))))
                                        test_val=isnumeric(T_full(uid==k,:).(char(fns(n)))) & isfinite(T_full(uid==k,:).(char(fns(n))));
                                        thx(not(test_val))=options.(char(fns(n))).Value;
                                        if isfield(options.(char(fns(n))),'limits') && numel(options.(char(fns(n))).limits)==2
                                            thx(thx>max(options.(char(fns(n))).limits)|thx<min(options.(char(fns(n))).limits))=options.(char(fns(n))).Value;
                                        end
                                        %id_thx=[MultiMinCalcTable_Data{uid==k}];

                                        if isfield(options.(char(fns(n))),'limits') && numel(options.(char(fns(n))).limits)==2
                                            test_val=test_val & not(thx>max(options.(char(fns(n))).limits)|thx<min(options.(char(fns(n))).limits));
                                        end

                                        SpotIds=find((test_val ));
                                        thx=num2cell(thx(test_val));

                                        %spot_id_edit=thx(~cellfun(@isempty, thx));
                                    elseif islogical(thx)
                                        thx=num2cell(thx);
                                    end
                                    MultiMinCalcTable_Data(SpotIds(~cellfun(@isempty, thx)),ismember(MultiMinCalcTable_ColumnName,fns(n)))=thx(~cellfun(@isempty, thx));
                                    %   MultiMinCalcTable_Data(SpotIds(find(~cellfun(@isempty, thx))),ismember(MultiMinCalcTable_ColumnName,fns(n)))=thx(~cellfun(@isempty, thx));
                                    MultiMinCalcTable_ColumnEditable(ismember(fns(n),MultiMinCalcTable_ColumnName))=true;
                                end

                                %% check for if user input value
                                SpotId=SpotIds(strcmp(MultiMinCalcTable_Data(SpotIds,strcmp(MultiMinCalcTable_ColumnName,'MineralCalcFunction')),MinCalc_fun));
                                if isfield(app.MinPlotXData.Sample(app.active_entry),'MultiCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings))&&...
                                        isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings,MinCalc_fun) && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(MinCalc_fun)))&&...
                                        isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(MinCalc_fun),char(fn)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(MinCalc_fun).(char(fn))))&&...
                                        isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(MinCalc_fun).(char(fn)),'Edited') && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(MinCalc_fun).(char(fn)).Edited))

                                    user_edit=app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(MinCalc_fun).(char(fn)).Edited(SpotId);
                                    user_edited_value=app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Value(user_edit);

                                    MultiMinCalcTable_Data(user_edit,ismember(MultiMinCalcTable_ColumnName,fns(n)))=user_edited_value;

                                end
                                MultiMinCalcTable_ColumnEditable(ismember(MultiMinCalcTable_ColumnName,fns(n)))=true;

                            end

                        end


                    catch ME
                        DisplayErrorNG(app,ME,'update_MultiCalcTable_table 1')
                    end
                end
                try
                    app.MultiMinCalcTable.Data=MultiMinCalcTable_Data;
                catch ME
                    DisplayErrorNG(app,ME,'update_MultiCalcTable_table 2')
                end
                app.MultiMinCalcTable.ColumnName=MultiMinCalcTable_ColumnName;
                app.MultiMinCalcTable.ColumnEditable=MultiMinCalcTable_ColumnEditable;

                app.MultiMinCalcTable.ColumnFormat=[];
                app.MultiMinCalcTable.ColumnFormat(1,1:size(T,2))={[]};
                app.MultiMinCalcTable.ColumnFormat(ismember(MultiMinCalcTable_ColumnName,'MineralCalcFunction'))={sort(all_funs)}; % here change to function names
                app.MultiMinCalcTable.ColumnFormat(ismember(MultiMinCalcTable_ColumnName,'Mineral'))={cellstr(app.MineralNames)'}; % here change to function names

                % app.MultiMinCalcTable.ColumnFormat(any(cellfun(@islogical,app.MultiMinCalcTable.Data)))={{true} {false}};

            else
                app.MultiMinCalcTable.Data=[];

            end

        end

        function update_MineralSpecificOptions(app)
            if    not(isfield(app.MinPlotXData,'SampleNames')) ||isempty(app.MinPlotXData.SampleNames) || isempty(app.active_entry) || not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                update_GUI(app)
                app.MineralSpecificOptionsPanel.Visible=false;
                app.MineralSpecificOptionsTable.Data=[];
                return
            end

            MineralName=app.MineralDropDown.Value;
            if app.FeOptionsPanel.Visible==true
                %% Fe3
                if app.CalculatewithFe3CheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Fe3.Value=app.CalculatewithFe3CheckBox.Value;
                end

                if app.EstimateFe3fromstoichiometryCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).EstimateFe3fromstoichiometryCheckBox.Value=app.EstimateFe3fromstoichiometryCheckBox.Value;
                end

                if app.UseFe3FeTotalRatioFromTableCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownFe3.Value=app.UseFe3FeTotalRatioFromTableCheckBox.Value;
                end

                if app.UseGlobalFe3FeTotalRatioCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Fe3.Value=app.UseGlobalFe3FeTotalRatioCheckBox.Value;
                end

                if app.Fe3FeTotalEditField.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Fe3FeTotal_ratio.Value=app.Fe3FeTotalEditField.Value;
                end


                %% Fe3 tetra

                if app.UseKnownFe3tetraFromTableCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnowntetra_Fe3.Value=app.UseKnownFe3tetraFromTableCheckBox.Value;
                end


                if app.UseGlobalFe3tetraCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobalFe3tetra.Value=app.UseGlobalFe3tetraCheckBox.Value;
                end

                if app.Fe3TetraEditField.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).GlobalFe3tetra_ratio.Value=app.Fe3TetraEditField.Value;
                end

            end

            if app.MnOptionsPanel.Visible==true

                %% Mn3
                if app.CalculatewithMn3CheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Mn3.Value=app.CalculatewithMn3CheckBox.Value;
                end

                if app.UseMn3MnTotalRatioFromTableCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownMn3.Value=app.UseMn3MnTotalRatioFromTableCheckBox.Value;
                end

                if app.UseGlobalMn3MnTotalRatioCheckBox.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Mn3.Value=app.UseGlobalMn3MnTotalRatioCheckBox.Value;
                end

                if app.Mn3MnTotalEditField.Visible
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Mn3MnTotal_ratio.Value=app.Mn3MnTotalEditField.Value;
                end
            end
        end

        function update_MineralSpecificOptionsTable(app)
            if    not(isfield(app.MinPlotXData,'SampleNames')) ||isempty(app.MinPlotXData.SampleNames) || isempty(app.active_entry) || not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                app.MineralSpecificOptionsPanel.Visible=false;
                app.MineralSpecificOptionsTable.Data=[];
                return
            end
            u_MCF=app.MineralDropDown.Value;

            try

                % [~,~,options]=MinPlotX_fun([],[],u_MCF,[],[]);
                if strcmp(char(u_MCF),'MultiMineralData')
                    options=struct();
                else
                    [~,~,options]=MinPlotX_fun([],[], app.MineralCalcFunctions.(char(u_MCF))(1),[],[]);
                end
            catch ME
                DisplayErrorNG(app,ME,' - [~,~,options]=MinPlotX_fun([],[],app.MineralCalcFunctions.(char(u_MCF))(1),[],[]);')

                options=struct();
            end

            %    fns=fieldnames(options);

            % options=rmfield(options,fns(ismember(fns,app.f2r)));

            fns=fieldnames(options);

            MineralSpecificOptions_ColumnName=cell(0,0);
            %MineralSpecificOptions_ColumnEditable=[];
            MineralSpecificOptions_Data=cell(0,0);

            try
                for n=1:numel(fns)

                    fn=fns(n);

                    if not(isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,u_MCF) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(u_MCF)))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(u_MCF),char(fn)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(u_MCF).(char(fn)))))

                        app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(u_MCF)).(char(fn)).Value=options.(char(fns(n))).Value;
                    end

                    if not(any(ismember(fn,app.f2r)))

                        if not(ismember(fns(n),MineralSpecificOptions_ColumnName))
                            MineralSpecificOptions_Data(1,end+1)={options.(char(fns(n))).Value};%#ok

                            MineralSpecificOptions_ColumnName(1,end+1)=fns(n);%#ok
                            % MineralSpecificOptions_ColumnEditable(1,end+1)=true;%#ok
                        else
                            %  MineralSpecificOptions_ColumnEditable(1,ismember(fns(n),MineralSpecificOptions_ColumnName))=true;
                            MineralSpecificOptions_Data(1,ismember(fns(n),MineralSpecificOptions_ColumnName))={options.(char(fns(n))).Value};
                        end

                        %% % check if input table has values are


                        %% check for if user input value

                    end

                    if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,u_MCF) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(u_MCF)))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(u_MCF),char(fn)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(u_MCF).(char(fn))))

                        MineralSpecificOptions_Data(1,ismember(MineralSpecificOptions_ColumnName,fns(n)))={app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(u_MCF)).(char(fn)).Value};
                    end

                    %  MineralSpecificOptions_ColumnEditable(1,ismember(MineralSpecificOptions_ColumnName,fns(n)))=true;

                end


            catch ME
                DisplayErrorNG(app,ME,'update_MinSpecificOptionsTable_table 1')
            end

            update_options(app)

            if isempty(fns(not(ismember(fns,app.f2r))))
                app.MineralSpecificOptionsPanel.Visible=false;
                app.MineralSpecificOptionsTable.Data=[];
            else
                app.MineralSpecificOptionsTable.Data=[MineralSpecificOptions_ColumnName; MineralSpecificOptions_Data]';

                app.MineralSpecificOptionsTable.ColumnName={'Option','Value'};
                app.MineralSpecificOptionsTable.ColumnEditable=[false true];

                app.MineralSpecificOptionsPanel.Visible=true;

                if app.MnOptionsPanel.Visible==true
                    app.MineralSpecificOptionsPanel.Layout.Row=[4 7];
                elseif app.FeOptionsPanel.Visible==true
                    app.MineralSpecificOptionsPanel.Layout.Row=3;
                else
                    app.MineralSpecificOptionsPanel.Layout.Row=2;
                end

            end
        end

        function  read_MineralCalcFunctions(app)
            if isdeployed

                MineralCalcFunctions_path=fullfile(app.installpath,'Dev','MineralCalcFunctions.csv');%#exclude
                MineralNames_path=fullfile(app.installpath,'Dev','MineralNames.csv');%#exclude

            else
                MineralCalcFunctions_path=which(fullfile('Dev','MineralCalcFunctions.csv'));%#exclude
                MineralNames_path=which(fullfile('Dev','MineralNames.csv'));%#exclude
            end

            thx_MCF=readcell(MineralCalcFunctions_path);
            thx_MNs=readcell(MineralNames_path);


            app.MineralNames=unique(string([thx_MCF(:,1); thx_MNs(1,:)']),'stable'); %ATH here 'stable'
            app.MineralNamesAlternatives=readtable(MineralNames_path);%#exclude

            app.MineralCalcFunctions=cell2table(thx_MCF(:,2:end)');
            app.MineralCalcFunctions.Properties.VariableNames=thx_MCF(:,1)';

            %ATH add panelty to test if all functions have a MineralName and all
            %MineralNames have a function
        end

        function ReadColorMaps(app)
            if isdeployed
                XMap_ColorMaps_path=fullfile(app.installpath,'Dev','XMT','XMap_ColorMaps.txt');
            else
                XMap_ColorMaps_path=which(fullfile('Dev','XMT','XMap_ColorMaps.txt'));
            end

            fid = fopen(XMap_ColorMaps_path,'r');

            Compt = 0;

            app.ColorMaps(1).Name = 'None';
            app.ColorMaps(1).Code = 0;

            while 1
                tline = fgetl(fid);

                if isequal(tline,-1)
                    break
                end

                if length(tline) >= 1
                    if isequal(tline(1),'>')
                        Compt = Compt+1;
                        app.ColorMaps(Compt).Name = tline(3:end);
                        Row = 0;
                        while 1
                            tline = fgetl(fid);
                            if isequal(tline,-1) || isequal(tline,'')
                                break
                            end
                            NUM = strread(tline,'%f'); %# ok
                            Row = Row+1;
                            app.ColorMaps(Compt).Code(Row,1:3) = NUM(1:3);
                        end

                    end
                end
            end
            fclose(fid);

            % Update the menu
            Items=cell(1,numel(app.ColorMaps));
            for i = 1:numel(app.ColorMaps)
                Items{i} = app.ColorMaps(i).Name;
            end
            app.Options_ColormapDropDown.Items =  Items; %extractfield(ColorMaps,'Name');

        end

        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if not(exist('Resolution','var')) || isempty(Resolution)
                Resolution=app.Options_ColormapResEditField.Value;
            end


            SelColorMap = find(ismember(app.Options_ColormapDropDown.Items,app.Options_ColormapDropDown.Value));
            if app.Options_InvertColormap.Value
                ColorData =app.ColorMaps(SelColorMap).Code;
            else
                ColorData =flip(app.ColorMaps(SelColorMap).Code);
            end
            if Resolution==1
                ColorMap = [0,0,0];
            else
                Step = (Resolution-1)/(size(ColorData,1)-1);
                X = 1:Step:Resolution;


                ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                for i = 1:size(ColorData,2)
                    ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                end

                % upper & lower
                if app.Options_LowerCheckBox.Value
                    if isequal(app.Options_LowerColor.Value,'Black')
                        ColorMap(1,:) = [0,0,0];
                    else
                        ColorMap(1,:) = [1,1,1];
                    end
                end
                if app.Options_UpperCheckBox.Value
                    if isequal(app.Options_UpperColor.Value,'Black')
                        ColorMap(end,:) = [0,0,0];
                    else
                        ColorMap(end,:) = [1,1,1];
                    end
                end
            end
            %log
            %linlogchange start

            app.ColorScale = 'linear';

            if not(exist('no_update','var'))
                app.ColorMapValues = ColorMap;
                imagesc(app.ColorMapPreview,[(1:app.Options_ColormapResEditField.Value);(1:app.Options_ColormapResEditField.Value)])
                colormap(app.ColorMapPreview,app.ColorMapValues)
                app.ColorMapPreview.XLim=[0 app.Options_ColormapResEditField.Value];
                app.ColorMapPreview.YLim=[0.5 2.5];
            end
        end


        function T = TableVarImportChange_fun(~,T)
            test_vars= {{'x_pos','XPos','xpos','xposition'},...
                {'y_pos','YPos','ypos','yposition'}...
                {'z_pos','ZPos','zpos','zposition'}...
                {'ProfileDistance','Profile_Distance','ProfileDis','Distance','distance'},...
                {'Fe3_ratio,Fe3_rat'},...
                {'Mn3_ratio,Mn3_rat'}...
                };
            for n=1:size(test_vars,2)
                [~,~,ic] = intersect(lower(test_vars{1,n}),lower(T.Properties.VariableNames), 'stable');
                if not(isempty(ic))
                    T.Properties.VariableNames(ic) =test_vars{1,n}(1);
                end
            end
        end

        function  update_options(app)
            if    not(isfield(app.MinPlotXData,'SampleNames')) ||isempty(app.MinPlotXData.SampleNames) || isempty(app.active_entry) || not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                % update_GUI(app)

                app.FeOptionsPanel.Visible=false;
                app.MnOptionsPanel.Visible=false;
                app.MineralSpecificOptionsPanel.Visible=false;

                return
            end
            MineralName=app.MineralDropDown.Value;

            %ATH Pyroxene Clinopyroxene Orthopyroxene
            if strcmp(MineralName,'MultiMineralData')
                options=struct();
            else
                [~,~,options]=MinPlotX_fun([],[], app.MineralCalcFunctions.(char(MineralName))(1),[],[]);
            end

            if any(strcmp(MineralName,app.minerals_with_Fe3_option)) || (isfield(options,'GUI_Fe3_Option') && options.GUI_Fe3_Option.Value==true)
                %% want ferric Fe3
                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'want_ferric_Fe3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Fe3))
                    want_ferric_Fe3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Fe3.Value;
                else
                    want_ferric_Fe3_Value=false;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Fe3.Value=want_ferric_Fe3_Value;
                end
                app.CalculatewithFe3CheckBox.Value=want_ferric_Fe3_Value;

                %% Estimate Fe3 from stoichiometry
                if any(strcmp(MineralName,app.minerals_with_EstimateFe3fromstoichiometry)) || (isfield(options,'GUI_Fe3fromstoichiometry') &&options.GUI_Fe3fromstoichiometry.Value==true)
                    app.EstimateFe3fromstoichiometryCheckBox.Visible=true;
                    if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'EstimateFe3fromstoichiometry') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).EstimateFe3fromstoichiometry))
                        EstimateFe3fromstoichiometry_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).EstimateFe3fromstoichiometry.Value;
                    else
                        EstimateFe3fromstoichiometry_Value=false;
                        app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).EstimateFe3fromstoichiometry.Value=EstimateFe3fromstoichiometry_Value;
                    end

                    app.EstimateFe3fromstoichiometryCheckBox.Value=EstimateFe3fromstoichiometry_Value;
                else
                    app.EstimateFe3fromstoichiometryCheckBox.Visible=false;
                end
                %% use known Fe3 from table
                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'UseKnownFe3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownFe3))

                    UseKnownFe3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownFe3.Value;
                else
                    UseKnownFe3_Value=false;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownFe3.Value=UseKnownFe3_Value;
                end
                app.UseFe3FeTotalRatioFromTableCheckBox.Value=UseKnownFe3_Value;

                %% use global Fe3

                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'UseGlobal_Fe3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Fe3))

                    UseGlobal_Fe3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Fe3.Value;
                else
                    UseGlobal_Fe3_Value=false;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Fe3.Value=UseGlobal_Fe3_Value;
                end
                app.UseGlobalFe3FeTotalRatioCheckBox.Value=UseGlobal_Fe3_Value;
                %%  global Fe3 ratio

                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'Global_Fe3FeTotal_ratio') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Fe3FeTotal_ratio))

                    Global_Fe3FeTotal_ratio_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Fe3FeTotal_ratio.Value;
                else
                    Global_Fe3FeTotal_ratio_Value=0;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Fe3FeTotal_ratio.Value=Global_Fe3FeTotal_ratio_Value;
                end
                app.Fe3FeTotalEditField.Value=Global_Fe3FeTotal_ratio_Value;

                UseGlobalFe3FeTotalRatioCheckBoxValueChanged(app)

                %% set visibility
                app.CalculatewithFe3CheckBox.Visible=true;

                app.UseFe3FeTotalRatioFromTableCheckBox.Visible=true;

                app.UseGlobalFe3FeTotalRatioCheckBox.Visible=true;


                if any(strcmp(MineralName,app.minerals_with_tetraFe3_option)) || (isfield(options,'GUI_tetraFe3_Option') &&options.GUI_tetraFe3_Option.Value==true)


                    if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'UseKnowntetra_Fe3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnowntetra_Fe3))
                        UseKnowntetra_Fe3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnowntetra_Fe3.Value;
                    else
                        UseKnowntetra_Fe3_Value=false;
                        app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnowntetra_Fe3.Value=UseKnowntetra_Fe3_Value;
                    end

                    app.UseKnownFe3tetraFromTableCheckBox.Value=UseKnowntetra_Fe3_Value;
                    app.UseKnownFe3tetraFromTableCheckBox.Visible=true;


                    if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'UseGlobalFe3tetra') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobalFe3tetra))
                        UseGlobalFe3tetra_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobalFe3tetra.Value;
                    else
                        UseGlobalFe3tetra_Value=false;
                        app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobalFe3tetra.Value=UseGlobalFe3tetra_Value;
                    end

                    app.UseGlobalFe3tetraCheckBox.Value=UseGlobalFe3tetra_Value;
                    app.UseGlobalFe3tetraCheckBox.Visible=true;

                    if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'GlobalFe3tetra_ratio') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).GlobalFe3tetra_ratio))

                        GlobalFe3tetra_ratio_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).GlobalFe3tetra_ratio.Value;
                    else
                        GlobalFe3tetra_ratio_Value=0;
                        app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).GlobalFe3tetra_ratio.Value=GlobalFe3tetra_ratio_Value;
                    end

                    app.Fe3TetraEditField.Value=GlobalFe3tetra_ratio_Value;

                    UseGlobalFe3tetraCheckBoxValueChanged(app)
                else

                    app.UseKnownFe3tetraFromTableCheckBox.Visible=false;
                    app.UseGlobalFe3tetraCheckBox.Visible=false;
                    app.Fe3TetraEditField.Visible=false;
                    app.Fe3TetraEditFieldLabel.Visible=false;
                end
                app.FeOptionsPanel.Visible=true;
            else

                app.FeOptionsPanel.Visible=false;

            end

            if any(strcmp(MineralName,app.minerals_with_Mn3_option)) || (isfield(options,'GUI_Mn3_Option') &&options.GUI_Mn3_Option.Value==true)
                %% want ferric Mn3
                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'want_ferric_Mn3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Mn3))

                    want_ferric_Mn3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Mn3.Value;
                else
                    want_ferric_Mn3_Value=false;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).want_ferric_Mn3.Value=want_ferric_Mn3_Value;
                end

                app.CalculatewithMn3CheckBox.Value=want_ferric_Mn3_Value;


                %% use known Mn3 from table
                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'UseKnownMn3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownMn3))

                    UseKnownMn3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownMn3.Value;
                else
                    UseKnownMn3_Value=false;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseKnownMn3.Value=UseKnownMn3_Value;
                end
                app.UseMn3MnTotalRatioFromTableCheckBox.Value=UseKnownMn3_Value;

                %% use global Mn3

                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'UseGlobal_Mn3') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Mn3))

                    UseGlobal_Mn3_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Mn3.Value;
                else
                    UseGlobal_Mn3_Value=false;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).UseGlobal_Mn3.Value=UseGlobal_Mn3_Value;
                end
                app.UseGlobalMn3MnTotalRatioCheckBox.Value=UseGlobal_Mn3_Value;
                %%  global Mn3 ratio

                if isfield(app.MinPlotXData.Sample(app.active_entry),'SingleCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings,char(MineralName)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName))))&&...
                        isfield(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)),'Global_Mn3MnTotal_ratio') && not(isempty(app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Mn3MnTotal_ratio))

                    Global_Mn3MnTotal_ratio_Value=app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Mn3MnTotal_ratio.Value;
                else
                    Global_Mn3MnTotal_ratio_Value=0;
                    app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).Global_Mn3MnTotal_ratio.Value=Global_Mn3MnTotal_ratio_Value;
                end
                app.Mn3MnTotalEditField.Value=Global_Mn3MnTotal_ratio_Value;
                UseGlobalMn3MnTotalRatioCheckBoxValueChanged(app)

                %% set visibility
                app.CalculatewithMn3CheckBox.Visible=true;

                app.UseMn3MnTotalRatioFromTableCheckBox.Visible=true;

                app.UseGlobalMn3MnTotalRatioCheckBox.Visible=true;


                app.MnOptionsPanel.Visible=true;

            else

                app.MnOptionsPanel.Visible=false;
            end

            app.CalculatewithFe3CheckBox.Visible=true;
            app.UseFe3FeTotalRatioFromTableCheckBox.Visible=true;


        end


        function check_for_update_fun(app,state)
            if not(exist("state","var"))
                state=false;
            end
            try
                % Define the owner and repo for your GitHub repository

                owner = 'NilsGies';
                repo = 'MinPlotX';
                url = sprintf('https://api.github.com/repos/%s/%s/releases', owner, repo);

                % Make the GET request to the GitHub API without authentication
                releaseData = webread(url);

                % If you want to extract specific fields, such as the tag name:
                if ~isempty(releaseData)
                    latestReleaseTag = releaseData(1).tag_name; % The first release in the array (latest)
                    disp(['Latest release tag: ' latestReleaseTag]);

                    if not(strcmp(latestReleaseTag,app.CurrentReleaseTag))

                        message = sprintf(['A new version of MinPlotX is available: <b>%s</b><br><br>'...
                            'You can download it from the following link: <a href="%s">https://github.com/NilsGies/MinPlotX/releases/latest</a>'], ...
                            latestReleaseTag, 'https://github.com/NilsGies/MinPlotX/releases/latest');

                        uialert(app.MinPlotXGUI,message,'New Version Available','Interpreter','html','Icon','info');
                    elseif state==true
                        message = sprintf('You have the latest version of MinPlotX installed: <b>%s</b><br><br>' , ...
                            latestReleaseTag);
                        uialert(app.MinPlotXGUI,message,'Latest Version Installed','Interpreter','html','Icon','success');

                    end

                else
                    disp('No releases found.');
                end
            catch ME
                if contains(ME.message, 'timeout') || contains(ME.message, 'Unable to resolve host') || contains(ME.message, 'Could not access server. https://api.github.com/repos/NilsGies/MinPlotX/releases.')
                    disp('New Version Check Failed - Unable to connect to GitHub. Please check your internet connection and try again.');
                elseif contains(ME.message, '404')
                    disp('New Version Check Failed - Repository not found. Please check the repository details.');
                else
                    DisplayErrorNG(app,ME,'Startup New Version Check Failed')
                end

            end
        end
    end

    methods (Access = public)

        function EasyPlotCloseRequestFcn(~,src, ~)
            % Call the public function of the app
            try
                % cleanupObjPB = onCleanup(@() close(src));
                delete(src);
                MinPlotXEasyPlot_public_update(EasyPlotModule)
            catch
                delete(src);
            end

            % Delete the figure
        end

        function DisplayErrorNG(app,ME,WhichOperation)
            disp(' ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%%   Error   %%%%%%%%%%%%%%%%')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp('%%')
            disp(['%%' char(datetime('now'))])
            disp('%%')
            disp(['Error occured during: ' WhichOperation])
            % for n=1
            disp('_________________________________________')

            for n=numel(ME.stack):-1:1
                disp('Error in file:')
                disp(ME.stack(n).file)
                disp(['Error in line: ',num2str(ME.stack(n).line)])
                disp('_________________________________________')
                disp('  ')
            end
            disp(['Error in message: ' ME.message])
            disp('  ')
            disp('%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%')
            disp('  ')

        end

        function update_GUI(app,update_all)
            if ~exist('update_all','var') || update_all==true
                if not(isfield(app.MinPlotXData,'SampleNames'))||isempty(app.MinPlotXData.SampleNames) %|| isempty(app.active_entry)
                    app.SamplesListBox.Items={};
                    app.SamplesListBox.ItemsData=[];
                    app.MinPlotXData.ColumnFilter=[];
                else
                    app.SamplesListBox.Items=app.MinPlotXData.SampleNames;
                    app.SamplesListBox.ItemsData=1:numel(app.MinPlotXData.SampleNames);

                    if app.FilterStringEditField.Visible==true
                        It=app.SamplesListBox.Items(contains(lower(app.SamplesListBox.Items),lower(app.FilterStringEditField.Value)));
                        ItDa=app.SamplesListBox.ItemsData(contains(lower(app.SamplesListBox.Items),lower(app.FilterStringEditField.Value)));

                        app.SamplesListBox.Items=It;
                        app.SamplesListBox.ItemsData=ItDa;
                    end
                end
            end


            if   not(isempty(app.active_entry)) && isequal(app.active_entry,app.SamplesListBox.Value)

            elseif (isempty(app.active_entry) && numel(app.SamplesListBox.Items)>0) || (app.Button_3.Value==true && numel(app.SamplesListBox.Items)>0)&&not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                app.active_entry= app.SamplesListBox.ItemsData(1);
            elseif (isempty(app.active_entry) && numel(app.SamplesListBox.Items)>0) || (numel(app.SamplesListBox.Items)>0 && not(ismember(app.active_entry,app.SamplesListBox.ItemsData)))
                app.active_entry= app.SamplesListBox.ItemsData(numel(app.SamplesListBox.Items));
            else
                app.active_entry=0;
            end

            if  app.active_entry==0
                app.InputTable.Data=[];
                app.MineralDropDown.Value='unknown';
                app.active_entry=[];
                app.MinPlotXData.ColumnFilter=[];
            elseif not(isempty(app.active_entry)) || numel(app.active_entry)==1
                app.SamplesListBox.Value=app.active_entry;
                %                 if size(app.MinPlotXData.Sample(app.active_entry).Input.data,1)>app.TableDisplayLimit
                %                     app.InputTable.Data={'InputTable to large to display'};
                %                     app.InputTable.ColumnName={'comment'};
                %
                %                 else
                app.InputTable.Data=app.MinPlotXData.Sample(app.active_entry).Input.data;
                app.InputTable.ColumnName=app.MinPlotXData.Sample(app.active_entry).Input.headers;
                %                end


                app.MineralDropDown.Value=app.MinPlotXData.Sample(app.active_entry).Input.MineralName;
if isfield(app.MinPlotXData.Sample(app.active_entry),'ColumnFilter')
                    app.MinPlotXData.ColumnFilter=app.MinPlotXData.Sample(app.active_entry).ColumnFilter;
end

            else
                app.InputTable.Data=[];
                app.MineralDropDown.Value='unknown';
                                    app.MinPlotXData.ColumnFilter=[];
            end

            if strcmp(app.MineralDropDown.Value, 'MultiMineralData')
                app.SwitchtoMultiCalcButton.Visible=true;
            else
                app.SwitchtoMultiCalcButton.Visible=false;
            end


            update_options(app)
            update_MineralSpecificOptionsTable(app)


            update_output_table(app)
            %add if selected_tab==error abs pee or meta
            update_meta_table(app)


            update_ListBox(app)


            %%NG_Dev


            %if strcmp(app.MineralDropDown.Value,'MultiMineralData')
            if not(isempty(app.active_entry)) && not(contains('Mineral',app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames))
                app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral(:)={'unknown'};
                app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction(:)={'unknown'};
            end

            if not(isempty(app.active_entry)) && not(any(contains(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames,'MineralCalcFunction')))
                for n=1:numel(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral)
                    str={CheckMinName(app,app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral(n))};
                    if contains(str,app.MineralCalcFunctions.Properties.VariableNames)
                        app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction(n)=app.MineralCalcFunctions.(char(str))(1);%{CheckMinName(app,app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral(n))};
                    else
                        app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction(n)={'unknown_MinPlotX'};

                    end
                end
            end


            % add or update settings table if not exist

            %  end

            %tree update
            UpdateTree(app,app.Tree)
            update_MultiCalcTable_table(app)

        end

        function update_MultiCalcTable_table_public(app)
            update_MultiCalcTable_table(app)
        end

        function import_data_fun(app,files_to_load,path_to_load)
            if istable(files_to_load)
                T=files_to_load;
                [data,headers,T, MetaTableInput] =import_table_fun(app,[],[],T);


                Input=struct();
                Input.data=data;
                Input.headers=headers;
                Input.T=T;
                Input.MetaTable=MetaTableInput;

                Input.MineralName = CheckMinName(app,inputname(2));  % ath here if isstruct  path_to_load ; SampleName; Mineral;etc;end

                if istable(Input.MetaTable) && not(isempty(Input.MetaTable)) && ismember('Mineral',Input.MetaTable.Properties.VariableNames)&& numel(unique(Input.MetaTable.Mineral))>1
                    Input.MineralName='MultiMineralData';
                elseif istable(Input.MetaTable) && not(isempty(Input.MetaTable)) && ismember('MineralName',Input.MetaTable.Properties.VariableNames)&& numel(unique(Input.MetaTable.MineralName))>1
                    Input.MineralName='MultiMineralData';
                    Input.MetaTable.Properties.VariableNames(ismember(Input.MetaTable.Properties.VariableNames,'MineralName'))={'Mineral'};
                end

                if istable(Input.MetaTable) && isempty(Input.MetaTable)
                    Input.MetaTable.Mineral(1:size(data,1))={Input.MineralName};
                elseif not(strcmp('Mineral',Input.MetaTable.Properties.VariableNames))
                    Input.MetaTable.Mineral(:)={Input.MineralName};
                end
                % here check if the column Mineral or MineralName is
                % present and update 'MultiMineeeralData'

                Input.file='';
                Input.path='';
                if not(isfield( app.MinPlotXData,'SampleNames'))|| isempty(app.MinPlotXData.SampleNames)
                    app.active_entry=1;
                else
                    app.active_entry=numel(app.MinPlotXData.SampleNames)+1;
                end
                app.MinPlotXData.Sample(app.active_entry).Input=Input;
                try
                    app.MinPlotXData.SampleNames{app.active_entry} = inputname(2);
                catch
                    app.MinPlotXData.SampleNames{app.active_entry}= 'API_IMPORT'; % ath here if isstruct  path_to_load ; SampleName; Mineral;etc;end
                end
                app.active_entry=app.active_entry+1;
            else
                if isempty(files_to_load)
                    files_in_dir=[dir(fullfile(path_to_load, '**','*.txt'));dir(fullfile(path_to_load, '**','*.dpt'));dir(fullfile(path_to_load, '**','*.csv'));dir(fullfile(path_to_load, '**','*.CSV'));dir(fullfile(path_to_load, '**','*.xls'));dir(fullfile(path_to_load, '**','*.xlsx'));];
                    files_in_dir = files_in_dir(~startsWith({files_in_dir.name}, '.'));
                    files_to_load={files_in_dir(:).name};
                    path_to_load={files_in_dir(:).folder};
                end

                error_log={};

                app.d = uiprogressdlg(app.MinPlotXGUI,'Title','Import...',...
                    'Message','Automatic Import Started','Cancelable','on');
                %cleanupObjPB = onCleanup(@() close(app.d));
                if not(isfield( app.MinPlotXData,'SampleNames'))|| isempty(app.MinPlotXData.SampleNames)
                    app.active_entry=1;
                else
                    app.active_entry=numel(app.MinPlotXData.SampleNames)+1;
                end

                for k=1:length(files_to_load)
                    try
                        if not(isfield( app.MinPlotXData,'SampleNames'))|| isempty(app.MinPlotXData.SampleNames)
                            app.active_entry=1;
                        else
                            app.active_entry=numel(app.MinPlotXData.SampleNames)+1;
                        end

                        app.d.Value = k/length(files_to_load);
                        app.d.Message = char(['Import files from Folder : ' num2str(k) ' of ' num2str(length(files_to_load)) ': '  path_to_load{k} files_to_load{k} ]);
                        if app.d.CancelRequested
                            break
                        end

                        if endsWith(files_to_load{k},{'xls','xlsx'}) && numel(sheetnames(fullfile(path_to_load{k},files_to_load{k}))) >1

                            S_names = sheetnames(fullfile(path_to_load{k},files_to_load{k}));   % Recommended fcn: sheetnames()
                            for ii=1:numel(S_names)
                                disp([num2str(ii) ' of ' num2str(numel(S_names)) ': ' S_names{ii}])
                                T=readtable(fullfile(path_to_load{k},files_to_load{k}), 'Sheet', S_names(ii));  % Recommended fcn: readtable()  or readmatrix() or readcell()

                                if isequal(T.Properties.VariableNames(1:2),[{'Var1'},{'Var2'}])
                                    ImportOptions_ = detectImportOptions(fullfile(path_to_load{k},files_to_load{k}),'NumHeaderLines', 0);
                                    T=readtable(fullfile(path_to_load{k},files_to_load{k}),ImportOptions_, 'Sheet', S_names(ii));  % Recommended fcn: readtable()  or readmatrix() or readcell()
                                end

                                [data,headers,T, MetaTableInput] =import_table_fun(app,[],[],T);


                                Input=struct();
                                Input.data=data;
                                Input.headers=headers;
                                Input.T=T;
                                Input.MetaTable=MetaTableInput;


                                Input.MineralName = CheckMinName(app,[files_to_load{k} '_' S_names{ii} ]);

                                if istable(Input.MetaTable) && not(isempty(Input.MetaTable)) && ismember('Mineral',Input.MetaTable.Properties.VariableNames)&& numel(unique(Input.MetaTable.Mineral))>1
                                    Input.MineralName='MultiMineralData';
                                elseif istable(Input.MetaTable) && not(isempty(Input.MetaTable)) && ismember('MineralName',Input.MetaTable.Properties.VariableNames)&& numel(unique(Input.MetaTable.MineralName))>1
                                    Input.MineralName='MultiMineralData';
                                    Input.MetaTable.Properties.VariableNames(ismember(Input.MetaTable.Properties.VariableNames,'MineralName'))={'Mineral'};
                                end

                                if istable(Input.MetaTable) && isempty(Input.MetaTable)
                                    Input.MetaTable.Mineral(1:size(data,1))={Input.MineralName};
                                elseif not(strcmp('Mineral',Input.MetaTable.Properties.VariableNames))
                                    Input.MetaTable.Mineral(:)={Input.MineralName};
                                end
                                % here check if the column Mineral or MineralName is
                                % present and update 'MultiMineeeralData'

                                Input.file=files_to_load{k};
                                Input.path=path_to_load{k};

                                app.MinPlotXData.Sample(app.active_entry).Input=Input;
                                app.MinPlotXData.SampleNames{app.active_entry}= [replace(files_to_load{k},{'.txt','.csv','.dpt','.xlsx','.xls'},'') '_' S_names{ii}];
                                app.active_entry=app.active_entry+1;
                            end
                        else

                            [data,headers,T, MetaTableInput] =import_table_fun(app,files_to_load{k},path_to_load{k});

                            Input=struct();
                            Input.data=data;
                            Input.headers=headers;
                            Input.T=T;
                            Input.MetaTable=MetaTableInput;


                            Input.MineralName = CheckMinName(app,files_to_load{k});

                            if istable(Input.MetaTable) && not(isempty(Input.MetaTable)) && ismember('Mineral',Input.MetaTable.Properties.VariableNames)&& numel(unique(Input.MetaTable.Mineral))>1
                                Input.MineralName='MultiMineralData';
                            elseif istable(Input.MetaTable) && not(isempty(Input.MetaTable)) && ismember('MineralName',Input.MetaTable.Properties.VariableNames)&& numel(unique(Input.MetaTable.MineralName))>1
                                Input.MineralName='MultiMineralData';
                                Input.MetaTable.Properties.VariableNames(ismember(Input.MetaTable.Properties.VariableNames,'MineralName'))={'Mineral'};
                            end

                            if istable(Input.MetaTable) && isempty(Input.MetaTable)
                                Input.MetaTable.Mineral(1:size(data,1))={Input.MineralName};
                            elseif not(strcmp('Mineral',Input.MetaTable.Properties.VariableNames))
                                Input.MetaTable.Mineral(:)={Input.MineralName};
                            end
                            % here check if the column Mineral or MineralName is
                            % present and update 'MultiMineeeralData'

                            Input.file=files_to_load{k};
                            Input.path=path_to_load{k};

                            app.MinPlotXData.Sample(app.active_entry).Input=Input;
                            app.MinPlotXData.SampleNames{app.active_entry}= replace(files_to_load{k},{'.txt','.csv','.dpt','.xlsx','.xls'},'');
                        end

                    catch ME
                        DisplayErrorNG(app,ME,['Import of file: ' [path_to_load{k},files_to_load{k}] ])


                        error_log{end+1,1}=[path_to_load{k},files_to_load{k}]; %#ok
                        error_log{end,2}=ME;

                    end
                end
            end
            delete(app.d)
        end

        function insert_data(app,T,MineralName,SampleName,update_state)
            try
                if not(isfield( app.MinPlotXData,'SampleNames'))|| isempty(app.MinPlotXData.SampleNames)
                    app.active_entry=1;
                else
                    app.active_entry=numel(app.MinPlotXData.SampleNames)+1;
                end

                [data,headers,T,MetaTableInput] = import_table_fun(app,[],[],T);
                Input=struct();
                Input.data=data;
                Input.headers=headers;
                Input.T=T;
                Input.MetaTable=MetaTableInput;
                Input.MineralName = CheckMinName(app,MineralName);

                Input.file=[];
                Input.path=[];

                app.MinPlotXData.Sample(app.active_entry).Input=Input;
                app.MinPlotXData.SampleNames{app.active_entry}= SampleName;
            catch ME
                DisplayErrorNG(app,ME,'insert_data')
            end

            if update_state==true
                update_GUI(app)
            end
        end

        function [StrctFrm,apfu]=calculate_fun(app,data,headers,MineralName,SampleName,options_input,save_in_struct)
            StrctFrm=[];
            apfu=[];


            for n=1:numel(app.active_entry)
                if ~exist('data','var') || isempty(data)
                    data=app.MinPlotXData.Sample(app.active_entry(n)).Input.data; %data converted to array
                    Meta=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable;
                    if   any(ismember(app.f2r,Meta.Properties.VariableNames))
                        data=[data table2array(Meta(:,ismember(Meta.Properties.VariableNames,app.f2r)))];%#ok
                    end
                end
                if ~exist('headers','var') || isempty(headers)
                    headers=app.MinPlotXData.Sample(app.active_entry(n)).Input.headers; %saves a list of the oxides in the header

                    if  any(ismember(app.f2r,Meta.Properties.VariableNames))
                        headers=[headers Meta.Properties.VariableNames(Meta.Properties.VariableNames(ismember(Meta.Properties.VariableNames,app.f2r)))];%#ok
                    end

                end

                if ~exist('MineralName','var') || isempty(MineralName)
                    MineralName = app.MinPlotXData.Sample(app.active_entry).Input.MineralName;
                end
                if ~exist('SampleName','var') || isempty(SampleName)
                    SampleName=app.MinPlotXData.SampleNames{app.active_entry(n)};
                end


                options.save_state=app.SaveAdvancedResultsCheckBox.Value;
                options.PathName=app.WorkingDirectoryEditField.Value;

                %% definition of Fe3 calculation
                if any(strcmp(MineralName,app.minerals_with_Fe3_option))

                    options.want_ferric_Fe3.Value=app.CalculatewithFe3CheckBox.Value; % amph grt cpx 'garnet' 'pyroxene' 'olivine' 'amphibole' ... why not chlorite?
                    options.UseKnownFe3.Value=app.UseFe3FeTotalRatioFromTableCheckBox.Value; % amph grt cpx 'garnet' 'pyroxene' 'olivine' 'amphibole' ... why not chlorite?
                    if options.want_ferric_Fe3.Value==true
                        if options.UseKnownFe3.Value==true
                            if  any(strcmp(headers,'Fe3_ratio'))
                                options.Fe3_ratio.Value=data(:,strcmp(headers,'Fe3_ratio'));
                                options.RecalcTotalFe.Value=true;
                            end

                        end

                        if any(strcmp(MineralName,app.minerals_with_Fe3_option)) && app.UseGlobalFe3FeTotalRatioCheckBox.Visible==true&& app.UseGlobalFe3FeTotalRatioCheckBox.Value==true
                            options.Fe3_ratio.Value=app.Fe3FeTotalEditField.Value; % grt cpx ctd
                            options.UseKnownFe3.Value=true;
                            options.RecalcTotalFe.Value=true;
                        end

                    else
                        options.want_ferric_Fe3.Value=false;
                        options.UseKnownFe3.Value=true;
                        options.RecalcTotalFe.Value=true;
                        options.Fe3_ratio.Value=0;
                    end
                end

                % define the Fe3 tetra option here


                if any(strcmp(MineralName,app.minerals_with_tetraFe3_option))
                    if app.UseGlobalFe3tetraCheckBox.Value==true
                        options.tetra_Fe3.Value=app.Fe3TetraEditField.Value; % grt cpx ctd
                        options.UseKnowntetra_Fe3.Value=true;
                    elseif app.UseKnownFe3tetraFromTableCheckBox.Visible==true && app.UseKnownFe3tetraFromTableCheckBox.Value==true
                        if  any(strcmp(headers,'tetra_Fe3'))
                            options.tetra_Fe3.Value=data(:,strcmp(headers,'tetra_Fe3'));
                            options.UseKnowntetra_Fe3.Value=true;
                        else
                            options.UseKnowntetra_Fe3.Value=false;
                        end
                    end
                end


                if any(strcmp(MineralName,app.minerals_with_Mn3_option))
                    %% definition of Mn3 calculation
                    options.want_ferric_Mn3.Value=app.CalculatewithMn3CheckBox.Value; % amph grt cpx 'garnet' 'pyroxene' 'olivine' 'amphibole' ... why not chlorite?
                    options.UseKnownMn3.Value=app.UseMn3MnTotalRatioFromTableCheckBox.Value; % amph grt cpx 'garnet' 'pyroxene' 'olivine' 'amphibole' ... why not chlorite?
                    if options.want_ferric_Mn3.Value==true
                        if options.UseKnownMn3.Value==true
                            if  any(strcmp(headers,'Mn3_ratio'))
                                options.Mn3_ratio.Value=data(:,strcmp(headers,'Mn3_ratio'));
                                options.RecalcTotalMn.Value=true;
                            end

                        end


                        if app.UseGlobalMn3MnTotalRatioCheckBox.Visible==true && app.UseGlobalMn3MnTotalRatioCheckBox.Value==true
                            options.Mn3_ratio.Value=app.Mn3MnTotalEditField.Value; % grt cpx ctd
                            options.UseKnownMn3.Value=true;
                            options.RecalcTotalMn.Value=true;
                        end


                    else
                        options.want_ferric_Mn3.Value=false;
                        options.Mn3_ratio.Value=0;
                        options.UseKnownMn3.Value=true;
                        options.RecalcTotalMn.Value=true;

                    end
                end


                if not(isempty(app.MineralSpecificOptionsTable.Data))

                    for nn=1:size(app.MineralSpecificOptionsTable.Data,1)
                        options.(char(app.MineralSpecificOptionsTable.Data{nn,1})).Value=app.MineralSpecificOptionsTable.Data{nn,2};
                    end
                end


                if exist('options_input','var')  && not(isempty(options_input))
                    options=options_input;
                end

                if isdeployed &&  strcmp(MineralName,'unknown')
                    options.T_MW.Value=readtable(fullfile(app.installpath,'Dev','Elements_to_oxides.txt'));
                end

                try
                    %     [StrctFrm,apfu]=MinPlotX_fun(data,headers,MineralName,SampleName,options);
                    if iscell(MineralName)
                        [StrctFrm,apfu]=MinPlotX_fun(data,headers,MineralName{:},SampleName,options);
                    else
                        [StrctFrm,apfu]=MinPlotX_fun(data,headers, app.MineralCalcFunctions.(char(MineralName))(1),SampleName,options);
                    end
                catch  ME
                    DisplayErrorNG(app,ME,'[StrctFrm,apfu]=calculate_fun(')
                end


                if ~exist('save_in_struct','var') || save_in_struct==true
                    app.MinPlotXData.Sample(app.active_entry(n)).Output(app.calculation_id).StrctFrm=StrctFrm;
                    app.MinPlotXData.Sample(app.active_entry(n)).Output(app.calculation_id).apfu=apfu;

                    if isfield(options,'plot_state')
                        options=rmfield(options,{'plot_state'});
                    end
                    if isfield(options,'plot_settings')
                        options=rmfield(options,{'plot_settings'});
                    end
                    if isfield(options,'save_state')
                        options=rmfield(options,{'save_state'});
                    end
                    if isfield(options,'PathName')
                        options=rmfield(options,{'PathName'});
                    end

                    fns=fieldnames(options);
                    T_options=table();
                    for k=1:numel(fns)
                        if not(strcmp('T_MW',fns(k)))
                            if numel(options.(char(fns(k))).Value)==1
                                T_options.(char(fns(k)))=repmat(options.(char(fns(k))).Value,size(data,1),1);
                            else
                                T_options.(char(fns(k)))=options.(char(fns(k))).Value;
                            end
                        end
                    end

                    %  T_options=repmat(T_options,size(data,1),1);

                    app.MinPlotXData.Sample(app.active_entry(n)).Output(app.calculation_id).options=T_options;
                end

            end
        end

        function MineralName = CheckMinName(app,str)

            try
                for n=numel(app.MineralNamesAlternatives.Properties.VariableNames):-1:1
                    thx=app.MineralNamesAlternatives.(n);
                    if iscell(thx)&&not(isempty(thx))
                        thx=not(cellfun(@isempty,thx));
                        if   strcmpi(str,string(app.MineralNamesAlternatives.(n)(thx)))
                            MineralName=app.MineralNamesAlternatives.Properties.VariableNames{n};
                            return
                        end
                    end
                    MineralName='unknown';
                end
            catch  ME
                DisplayErrorNG(app,ME,' CheckMinName(app,str)')
            end

            if contains(lower(str),app.MineralNames)
                for n=1:numel(app.MineralNames)
                    if strcmpi(str,app.MineralNames(n))
                        MineralName=app.MineralNames{n};
                        return
                    end
                end
            end

            try
                for n=numel(app.MineralNamesAlternatives.Properties.VariableNames):-1:1
                    thx=app.MineralNamesAlternatives.(n);
                    if iscell(thx)&&not(isempty(thx))
                        thx=not(cellfun(@isempty,thx));
                        if   contains(lower(str),lower(string(app.MineralNamesAlternatives.(n)(thx))))
                            MineralName=app.MineralNamesAlternatives.Properties.VariableNames{n};
                            return
                        end
                    end
                    MineralName='unknown';
                end
            catch  ME
                DisplayErrorNG(app,ME,' CheckMinName(app,str)')
            end

            if contains(lower(str),app.MineralNames)
                for n=1:numel(app.MineralNames)
                    if contains(lower(str),app.MineralNames(n))
                        MineralName=app.MineralNames{n};
                        return
                    end
                end
            end


        end


        function [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo,T_err_per,T_err_abs]=get_compiled_table(app,n)
            T=table();
            T_InputData=table();
            T_StrctFrm=table();
            T_apfu=table();
            T_CalcOut=table();
            T_options=table();
            T_MetaData=table();
            T_err_per=table();
            T_err_abs=table();
            if not(exist('n','var')) || isempty(n)
                return
            end
            if  isfield(app.MinPlotXData.Sample(n),'Input') && not(isempty(app.MinPlotXData.Sample(n).Input))&&...
                    isfield(app.MinPlotXData.Sample(n).Input(app.calculation_id),'MetaTable') && ...
                    not(isempty(app.MinPlotXData.Sample(n).Input(app.calculation_id).MetaTable))&& ...
                    istable(app.MinPlotXData.Sample(n).Input(app.calculation_id).MetaTable)
                T_MetaData=app.MinPlotXData.Sample(n).Input(app.calculation_id).MetaTable;

                T=T_MetaData;
            end

            if isfield(app.MinPlotXData.Sample(n),'Input') && not(isempty(app.MinPlotXData.Sample(n).Input))&&...
                    isfield(app.MinPlotXData.Sample(n).Input(app.calculation_id),'data') && ...
                    not(isempty(app.MinPlotXData.Sample(n).Input(app.calculation_id).data))

                T_InputData=array2table(app.MinPlotXData.Sample(n).Input.data);
                T_InputData.Properties.VariableNames=app.MinPlotXData.Sample(n).Input.headers;
                try
                    T=[T T_InputData];

                catch  ME
                    DisplayErrorNG(app,ME,'get_compiled_table - Duplicate VariableName in MetaTable')

                    T.Properties.VariableNames=strcat('Meta_', T.Properties.VariableNames); % add [StrctFrm_ 'var']
                    T=[T T_InputData];
                end
            end

            if  isfield(app.MinPlotXData.Sample(n),'Output') && not(isempty(app.MinPlotXData.Sample(n).Output))&&...
                    isfield(app.MinPlotXData.Sample(n).Output(app.calculation_id),'apfu') && ...
                    not(isempty(app.MinPlotXData.Sample(n).Output(app.calculation_id).apfu))&& ...
                    istable(app.MinPlotXData.Sample(n).Output(app.calculation_id).apfu)

                T_apfu=app.MinPlotXData.Sample(n).Output(app.calculation_id).apfu;
                T_apfu.Properties.VariableNames=strcat('apfu_', T_apfu.Properties.VariableNames); % add [StrctFrm_ 'var']

                T=[T T_apfu];

            end

            if  isfield(app.MinPlotXData.Sample(n),'Output') && not(isempty(app.MinPlotXData.Sample(n).Output))&&...
                    isfield(app.MinPlotXData.Sample(n).Output(app.calculation_id),'StrctFrm') && ...
                    not(isempty(app.MinPlotXData.Sample(n).Output(app.calculation_id).StrctFrm))&& ...
                    istable(app.MinPlotXData.Sample(n).Output(app.calculation_id).StrctFrm)

                T_StrctFrm=app.MinPlotXData.Sample(n).Output(app.calculation_id).StrctFrm;
                T_StrctFrm.Properties.VariableNames=strcat('StrctFrm_',T_StrctFrm.Properties.VariableNames); % add [StrctFrm_ 'var']
                T=[T T_StrctFrm];

            end

            if  isfield(app.MinPlotXData.Sample(n),'Output') && not(isempty(app.MinPlotXData.Sample(n).Output))&&...
                    isfield(app.MinPlotXData.Sample(n).Output(app.calculation_id),'CalcOut') && ...
                    not(isempty(app.MinPlotXData.Sample(n).Output(app.calculation_id).CalcOut))&& ...
                    istable(app.MinPlotXData.Sample(n).Output(app.calculation_id).CalcOut)

                T_CalcOut=app.MinPlotXData.Sample(n).Output(app.calculation_id).CalcOut;
                T_CalcOut.Properties.VariableNames=strcat('CalcOut_',T_CalcOut.Properties.VariableNames); % add [CalcOut_ 'var']
                T=[T T_CalcOut];

            end

            if not(isempty(T))
                if  isfield(app.MinPlotXData.Sample(n),'Output') && not(isempty(app.MinPlotXData.Sample(n).Output))&& isfield(app.MinPlotXData.Sample(n).Output,'options') && (not(isempty(T_StrctFrm))||not(isempty(T_apfu)))
                    T_options=app.MinPlotXData.Sample(n).Output(app.calculation_id).options;

                    %if not(istable(T_options))
                    %   try
                    %             thx=(app.MinPlotXData.Sample(n).Output(app.calculation_id).options);
                    %                     catch ME
                    %                         ME.stack.line
                    %                         ME
                    %                     end
                    %
                    %                     if isfield(thx,'plot_state')
                    %                         thx=rmfield(thx,{'plot_state'});
                    %                     end
                    %                     if isfield(thx,'plot_settings')
                    %                         thx=rmfield(thx,{'plot_settings'});
                    %                     end
                    %                     if isfield(thx,'save_state')
                    %                         thx=rmfield(thx,{'save_state'});
                    %                     end
                    %                     if isfield(thx,'PathName')
                    %                         thx=rmfield(thx,{'PathName'});
                    %                     end
                    %
                    %
                    %
                    %
                    %                     thx_cell=cell(1,numel(fieldnames(thx)));
                    %                     for n=1:numel(fieldnames(thx))
                    %                         fns=fieldnames(thx);
                    %                       thx_cell{n}=thx.(char(fns(n))).Value;
                    %
                    %                     end
                    %                       T_options=cell2table(thx_cell);
                    %                      % T_options.Properties.VariableNames=strcat('Options_', fns); % add [StrctFrm_ 'var']
                    %                       T_options.Properties.VariableNames=fns; % add [StrctFrm_ 'var']
                    %
                    %                 %    T_options=struct2table(thx);
                    %                     T_options=repmat(T_options,size(T,1),1);
                    %end
                    %       end
                    try
                        for k=1:numel(T_options.Properties.VariableNames)
                            T.(char(T_options.Properties.VariableNames(k)))=T_options.(char(T_options.Properties.VariableNames(k)));

                        end
                        % T=[T T_options];
                    catch  ME
                        DisplayErrorNG(app,ME,'get_compiled_table - Duplicate VariableName in MetaTable 2')

                        T_options.Properties.VariableNames=strcat('Options_', T_options.Properties.VariableNames); % add [StrctFrm_ 'var']
                        T=[T T_options];
                    end

                end

                T_FileInfo=table();
                T_FileInfo.SpotId(1:size(T,1))=(1:size(T,1))';
                try
                    T_FileInfo.SampleName(1:size(T,1))=app.MinPlotXData.SampleNames(n);

                    T_FileInfo.MineralName(:)={app.MinPlotXData.Sample(n).Input.MineralName};
                    var_names_unique = matlab.lang.makeUniqueStrings([T_FileInfo.Properties.VariableNames T.Properties.VariableNames]);
                    T.Properties.VariableNames = var_names_unique(numel(T_FileInfo.Properties.VariableNames)+1:end);
                    T=[T_FileInfo T]; %%ath make komp
                catch ME %#ok

                end

                if  isfield(app.MinPlotXData.Sample(n),'Input') && not(isempty(app.MinPlotXData.Sample(n).Input))&&...
                        isfield(app.MinPlotXData.Sample(n).Input,'data_err_per') &&  not(isempty(app.MinPlotXData.Sample(n).Input.data_err_per)) &&...
                        istable(app.MinPlotXData.Sample(n).Input.data_err_per.T) && not(isempty(app.MinPlotXData.Sample(n).Input.data_err_per.T))
                    T_err_per=app.MinPlotXData.Sample(n).Input.data_err_per.T;
                end


                if isfield(app.MinPlotXData.Sample(n),'Input') && not(isempty(app.MinPlotXData.Sample(n).Input))&&...
                        isfield(app.MinPlotXData.Sample(n).Input,'data_err_abs') &&  not(isempty(app.MinPlotXData.Sample(n).Input.data_err_abs)) &&...
                        istable(app.MinPlotXData.Sample(n).Input.data_err_abs.T) && not(isempty(app.MinPlotXData.Sample(n).Input.data_err_abs.T))
                    T_err_abs=app.MinPlotXData.Sample(n).Input.data_err_abs.T;

                end

            end
        end

        function result =tablevertcat_NG(app,T_cell)
            result=table();
            try
                n = length(T_cell);
                t_ent = find(not(cellfun(@istable,T_cell))); %number of rows for each table

                for k=1:numel(t_ent)
                    T_cell{t_ent(k)}=array2table(T_cell{t_ent(k)});
                end

                for k=1:size(T_cell,2)
                    if isempty(T_cell{k})
                        T_cell{k}=missing;
                    end
                end
                nrowslist = cellfun(@height,T_cell); %number of rows for each table
                ID_to_not_mix_up_the_tables_tmp = [0 cumsum(nrowslist)];
                for n = 1:n
                    ID_to_not_mix_up_the_tables = ID_to_not_mix_up_the_tables_tmp(n)+1:ID_to_not_mix_up_the_tables_tmp(n+1);
                    if ismissing(T_cell{n})
                        T_cell{n} = table(ID_to_not_mix_up_the_tables.','VariableNames',{'ID_to_not_mix_up_the_tables'});
                    else
                        T_cell{n}.ID_to_not_mix_up_the_tables = ID_to_not_mix_up_the_tables.';
                    end
                end
                t1 = T_cell{1};
                for n = 2:n
                    try
                        t2 = T_cell{n};
                        t1names = t1.Properties.VariableNames;

                        t2names = t2.Properties.VariableNames;
                        sharednames = intersect(t1names,t2names);
                        t1 = outerjoin(t1,T_cell{n},'Key',['ID_to_not_mix_up_the_tables',sharednames],'MergeKeys',true);
                    catch
                        %    t1{end+1,:} = missing;%#ok
                        t1Classes = varfun(@class,t1,'OutputFormat','cell');
                        %   t1Classes = varfun(@class,t1.(ismember(t1.Properties.VariableNames,sharednames)),'OutputFormat','cell');
                        T_cell_classes = varfun(@class,T_cell{n},'OutputFormat','cell');

                        for snms=1:numel(sharednames)
                            if not(isequal(t1Classes(ismember(t1.Properties.VariableNames,sharednames(snms))),T_cell_classes(ismember(T_cell{n}.Properties.VariableNames,sharednames(snms)))))

                                if  isnumeric(t1.(find(ismember(t1.Properties.VariableNames,sharednames(snms)))))
                                    t1.(find(ismember(t1.Properties.VariableNames,sharednames(snms))))=cellstr(string(t1.(find(ismember(t1.Properties.VariableNames,sharednames(snms))))));
                                end

                                T_cell{n}.(find(ismember(T_cell{n}.Properties.VariableNames,sharednames(snms))))=cellstr(string(T_cell{n}.(find(ismember(T_cell{n}.Properties.VariableNames,sharednames(snms))))));
                            end
                        end
                        try
                            t1 = outerjoin(t1,T_cell{n},'Key',['ID_to_not_mix_up_the_tables',sharednames],'MergeKeys',true);
                        catch ME
                            DisplayErrorNG(app,ME,'t1 = outerjoin(t1,T_cell{n} failed')
                        end
                    end
                end
                result = removevars(t1,'ID_to_not_mix_up_the_tables');
            catch ME
                DisplayErrorNG(app,ME,'tablevertcat_NG')

            end
        end
        %         function result =tablevertcat_NG(app,T_cell)
        %             result=table();
        %             try
        %                 n_max = length(T_cell);
        %                 t_ent = find(not(cellfun(@istable,T_cell))); %number of rows for each table
        %
        %                 for k=1:numel(t_ent)
        %                     T_cell{t_ent(k)}=array2table(T_cell{t_ent(k)});
        %                 end
        %
        %                 for k=1:size(T_cell,2)
        %                     if isempty(T_cell{k})
        %                     %    T_cell{k}=missing;
        %                         T_cell{k}='';
        %                     end
        %                 end
        %                 nrowslist = cellfun(@height,T_cell); %number of rows for each table
        %                 ID_to_not_mix_up_the_tables_tmp = [0 cumsum(nrowslist)];
        %                 for n = 1:n_max
        %                     ID_to_not_mix_up_the_tables = ID_to_not_mix_up_the_tables_tmp(n)+1:ID_to_not_mix_up_the_tables_tmp(n+1);
        %                     if ismissing(T_cell{n})
        %                         T_cell{n} = table(ID_to_not_mix_up_the_tables.','VariableNames',{'ID_to_not_mix_up_the_tables'});
        %                     else
        %                         T_cell{n}.ID_to_not_mix_up_the_tables = ID_to_not_mix_up_the_tables.';
        %                     end
        %                 end
        %                 t1 = T_cell{1};
        %                 for n = 2:n_max
        %                     try
        %                         t2 = T_cell{n};
        %                         t1names = t1.Properties.VariableNames;
        %
        %                         t2names = t2.Properties.VariableNames;
        %                         sharednames = intersect(t1names,t2names);
        %                         t1 = outerjoin(t1,T_cell{n},'Key',['ID_to_not_mix_up_the_tables',sharednames],'MergeKeys',true);
        %                     catch ME
        %                      %   ME
        %                         t1Classes = varfun(@class,t1,'OutputFormat','cell');
        %                         %   t1Classes = varfun(@class,t1.(ismember(t1.Properties.VariableNames,sharednames)),'OutputFormat','cell');
        %                         T_cell_classes = varfun(@class,T_cell{n},'OutputFormat','cell');
        %
        %                         for snms=1:numel(sharednames)
        %                             if not(isequal(t1Classes(ismember(t1.Properties.VariableNames,sharednames(snms))),T_cell_classes(ismember(T_cell{n}.Properties.VariableNames,sharednames(snms)))))
        %
        %                                 if  isnumeric(t1.(find(ismember(t1.Properties.VariableNames,sharednames(snms)))))
        %                                     t1.(find(ismember(t1.Properties.VariableNames,sharednames(snms))))=cellstr(string(t1.(find(ismember(t1.Properties.VariableNames,sharednames(snms))))));
        %                                 end
        %
        %                                 T_cell{n}.(find(ismember(T_cell{n}.Properties.VariableNames,sharednames(snms))))=cellstr(string(T_cell{n}.(find(ismember(T_cell{n}.Properties.VariableNames,sharednames(snms))))));
        %                             end
        %                         end
        %                         try
        %                             t1 = outerjoin(t1,T_cell{n},'Key',['ID_to_not_mix_up_the_tables',sharednames],'MergeKeys',true);
        %                         catch
        %                             DisplayErrorNG(app,ME,'t1 = outerjoin(t1,T_cell{n} failed')
        %                         end
        %                         %  t1{end+1,:} = missing;%#ok
        %                         %t1{end+1,:} ='';%#ok
        %                     end
        %                 end
        %                 result = removevars(t1,'ID_to_not_mix_up_the_tables');
        %             catch ME
        %                 DisplayErrorNG(app,ME,'tablevertcat_NG')
        %
        %             end
        %         end

        function update_output_table(app)
            if not(isempty(app.active_entry)) &&  numel(app.active_entry)==1
                if isfield(app.MinPlotXData.Sample(app.active_entry),'Output') && not(isempty(app.MinPlotXData.Sample(app.active_entry).Output))
                    if  strcmp(app.Switch.Value,'apfu') && ...
                            isfield(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id),'apfu') && ...
                            not(isempty(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu))&& ...
                            istable(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu)
                        app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu;

                        app.OutputTable.Data=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu;
                        app.OutputTable.ColumnName=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu.Properties.VariableNames;

                    elseif  strcmp(app.Switch.Value,'Structural Formula') &&...
                            isfield(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id),'StrctFrm') && ...
                            not(isempty(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm))&& ...
                            istable(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm)

                        app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm;

                        app.OutputTable.Data=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm;
                        app.OutputTable.ColumnName=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm.Properties.VariableNames;
                    else

                        app.OutputTable.Data=[];
                    end
                else
                    app.OutputTable.Data=[];
                end
            elseif not(isempty(app.active_entry)) &&  numel(app.active_entry)>1 && numel(app.MinPlotXData.Sample(app.active_entry(n)).Output)>1
                %ATH add here MultiCalcResults
            else

                app.OutputTable.Data=[];
            end
        end


        function UpdateTree(app,Tree2Update)


            if not(exist('tree2update','var'))
                Tree2Update=app.Tree;
            end
            %%% [2,i,j,k,l]
            N=Tree2Update.CheckedNodes;

            if not(isempty(N))

                selectedNodes=cell2mat({N.NodeData}');

                T_C=cell2mat({Tree2Update.Children.NodeData}');
                T_C=Tree2Update.Children(T_C(ismember(T_C,unique(selectedNodes(:,1)))));
                N=[N;T_C];

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                update_ts=true;
            else
                update_ts=false;
                selectedNodes=[];
            end

            ResetTree(app,Tree2Update)
            if not(isempty(app.active_entry))  %strcmp(app.MineralDropDown.Value,'MultiMineralData')

                Mineral=unique(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral);
                % Add Data
                if numel(Mineral) > 0
                    for i = 1:numel(Mineral)
                        p = uitreenode(Tree2Update,'Text',string(Mineral{i}),'NodeData',[i,0]);
                        %   p.ContextMenu = ContextMenu_thx;

                        %   MineralCalcFunction=unique(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction);
                        if not(strcmp('unknown',char(CheckMinName(app,Mineral{i}))))
                            u_MNs= app.MineralCalcFunctions.(char(CheckMinName(app,Mineral{i})));
                            for j = 1:length(u_MNs)
                                if any(not(ismissing(u_MNs{j})))
                                    p1 = uitreenode(p,'Text',string(u_MNs{j}),'NodeData',[i,j]);%#ok

                                    %   p1.ContextMenu = ContextMenu_thx;
                                end
                            end
                        end
                    end
                    %         end
                end

                if update_ts==true

                    try
                        TreeNodes_new={Tree2Update.Children.Text};
                        nodes2check_P=Tree2Update.Children(contains(TreeNodes_new,selectedNodes_T(selectedNodes(:,2)==0)));
                        P_old=unique(selectedNodes(selectedNodes(:,2)==0,1),'row');
                        nodes2check_final= [];
                        for n=1:numel(nodes2check_P)
                            nodes2check_P_C=(selectedNodes_T(selectedNodes(:,2)>0&selectedNodes(:,1)==P_old(n)));
                            nodes2check_final=[nodes2check_final; nodes2check_P(n).Children(contains({nodes2check_P(n).Children.Text},nodes2check_P_C))  ];%#ok
                        end
                        Tree2Update.CheckedNodes=nodes2check_final;
                        expand(Tree2Update)
                    catch ME
                        DisplayErrorNG(app,ME,'UpdateTree')

                    end
                end

            end
        end

        function update_ListBox(app,ListBox2update,entry)

            if ~exist('ListBox2update','var') ||isempty(ListBox2update)
                return
            end

            if ~exist('entry','var') ||isempty(entry)
                entry=app.active_entry;
            end

            if not(isempty(entry)) || numel(entry)>1
                T=get_compiled_table(app,entry);
                Items_thx=T.Properties.VariableNames;
                ItemsData_thx=1:numel(Items_thx);
                numeric_cols=sum(cellfun(@isnumeric,table2cell(T)))>0;%==height(T);

                ListBox2update.Items=Items_thx(numeric_cols);
                ListBox2update.ItemsData=ItemsData_thx(numeric_cols);
            else
                ListBox2update.Items={};
                ListBox2update.ItemsData=[];
            end

        end

        function         [x,y,z,profile_distance]=get_profiledata(app,n,value)

            x=[];
            y=[];
            z=[];
            profile_distance=[];

            if  isfield(app.MinPlotXData.Sample(n),'Input') && not(isempty(app.MinPlotXData.Sample(n).Input))&&...
                    isfield(app.MinPlotXData.Sample(n).Input(app.calculation_id),'MetaTable') && ...
                    not(isempty(app.MinPlotXData.Sample(n).Input(app.calculation_id).MetaTable))&& ...
                    istable(app.MinPlotXData.Sample(n).Input(app.calculation_id).MetaTable)

                try
                    T=get_compiled_table(app,n);
                    %  T=T(:,sum(cellfun(@isnumeric,table2cell(T)))>0);

                catch ME
                    DisplayErrorNG(app,ME,'get_profiledata')

                    T=table();
                end
                if not(exist('value','var')) || isempty(value)
                    value = sum(cellfun(@isnumeric,table2cell(T)))>0;
                end

                T=T(:,value);


                plotmat=table2array(T);


                if any(strcmp('profile_distance',T.Properties.VariableNames))
                    profile_distance=T.profile_distance;
                else
                    profile_distance=(1:size(T,1))';
                end

                if any(strcmp('x_pos',T.Properties.VariableNames))
                    x=T.x_pos;
                elseif any(strcmp('x',T.Properties.VariableNames))
                    x=T.x_pos;

                end

                if any(strcmp('y_pos',T.Properties.VariableNames))
                    y=T.y_pos;
                elseif any(strcmp('y',T.Properties.VariableNames))
                    y=T.y_pos;

                end

                z=plotmat;

            end

            if isempty(x)&& isempty(profile_distance)
                x=(1:size(app.MinPlotX.MinPlotXData.Sample(app.selected_sample).Input(sample).data,1))';
            end

            if isempty(profile_distance)
                profile_distance=x;
            end
        end

        function new_name= CalcTableReturn(app,matrix,new_name,~,Sample,hfig)
            if not(exist("hfig","var"))
                hfig=app.MinPlotXGUI;
            end
            RT=app.MinPlotXData.Sample(Sample).Input(app.calculation_id).MetaTable;

            fns=RT.Properties.VariableNames;
            new_name = matlab.lang.makeValidName(new_name);
            if ismember(new_name,fns)

                answer = uiconfirm(hfig,{'Do you want to Upddate the existing field'},...
                    'Field exists already!','Options',{'Yes','No','Cancel'},'Icon','warning');

                if strcmp(answer,'No')
                    new_name = matlab.lang.makeUniqueStrings([fns;{new_name}]);
                elseif strcmp(answer,'Cancel')
                    return
                end
            end
            app.MinPlotXData.Sample(Sample).Input(app.calculation_id).MetaTable.(char(new_name))=matrix;
            if not(isfield(app.MinPlotXData.Sample(Sample),'Output')) || not(isfield(app.MinPlotXData.Sample(Sample).Output,'CalcOut')) || not(istable(app.MinPlotXData.Sample(Sample).Output(app.calculation_id).CalcOut))
                app.MinPlotXData.Sample(Sample).Output(app.calculation_id).CalcOut=table();
            end

            app.MinPlotXData.Sample(Sample).Output(app.calculation_id).CalcOut.(char(new_name))=matrix;
            update_GUI(app,false)
        end

        function [newpath, newname]=ExportSelectedTable(app,T,FilePath,FileName,dlg_state,overwrite_state,delimiter_setting)
            newpath='';
            newname='';

            if not(exist('dlg_state','var'))
                dlg_state=false;
            end

            if not(exist('overwrite_state','var'))
                overwrite_state=false;
            end

            if not(exist('delimiter_setting','var'))
                delimiter_setting='\t';
            end

            if isempty(T) || not(istable(T))
                message={'No Table found'};
                uialert(app.MinPlotXGUI,message,'Error');
                return
            end
            if dlg_state==true
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
                [file,path]=uiputfile({'*.csv';'*.xls';'*.txt';'*.dpt';'*.xlsx';'*.CSV'},'Select File',fullfile(FilePath,FileName));

                delete(f);
                figure(app.MinPlotXGUI);
            else
                file=FileName;
                path=FilePath;
            end

            if isequal(file,0)
                return
            else
                newpath=path;
                newname=file;
            end

            if overwrite_state==false && exist(fullfile(path,FileName),'file')>0
                app.d=dir(path);

                [~, n, ext]=fileparts(fullfile(path,FileName));

                [~, nms, cc]=fileparts({app.d.name});

                FileName=matlab.lang.makeUniqueStrings([nms(ismember(cc,ext)),{n}]);
                FileName=[FileName{end} ext] ;
            end

            if endsWith(FileName,'.xlsx')
                writetable(T,fullfile(path,FileName));
            else
                writetable(T,fullfile(path,FileName),'Delimiter',delimiter_setting);
            end

        end

        function  callEasyPlotModule(app)
            EPM=EasyPlotModule(app);
            EPM.MinPlotXEasyPlot_public_update;
        end

        function CloseMinPlot_cleanup_fun(app)
            if isdeployed
                h2 = findall(groot,'Type','figure');
                delete(h2)
            else
                h2 = findall(groot,'Type','figure');
                %                                 delete(h2)
                % return
                [h2(:).CloseRequestFcn]=deal('closereq');

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.MinPlotXGUI, 'center');
            if isdeployed %standalone mode.
                try

                    %                         f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                    %                         user_path=uigetdir(pwd,'select working directory with read/write permission');
                    %                         delete(f)
                    if ispc
                        [~, result] = system('path');
                        app.installpath = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                    elseif ismac
                        path=ctfroot;
                        disp('ctfroot:')
                        disp(path)

                        path_split=split(path,{'MinPlotX.app','MinPlotX_dev.app','MinPlotX.exe','MinPlotX_dev.exe'});
                        disp('path_split')
                        disp(path_split)

                        app.installpath=path_split{1};
                        disp('Installation Directory 0:')
                        disp(app.installpath)
                        disp('')

                        if not(exist(fullfile(app.installpath,'Dev','MineralCalcFunctions.csv'),'file'))
                            answer = uiconfirm(app.MinPlotXGUI,{'Application directory not found - Select MinPlotX directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');
                        end
                    elseif isunix
                    try
                        app.installpath=pwd;
                    catch
                       answer = uiconfirm(app.MinPlotXGUI,{'Application directory not found - Select MinPlotX directory'},'Select directory','Options',"OK",'Icon','warning');
                            app.installpath=uigetdir(pwd,'select application directory');
                      
                    end
                    end

                    tmp_path='';

                    if exist(fullfile(app.installpath,'Dev','MinPlotXWorkingDir'),'file')
                        tmp_path= fileread(fullfile(app.installpath,'Dev','MinPlotXWorkingDir'));
                        disp('tmp_path 0:')
                        disp(tmp_path)
                        if exist(tmp_path,'dir')
                            try
                                diary(fullfile(tmp_path,'MinPlotX_log.txt'))
                                user_path=tmp_path;
                            catch
                                disp('could not use user_path to create logfile')
                                tmp_path='There was an error logfile could not be created';
                            end
                        end
                    end

                    if isempty(tmp_path) || not(exist(tmp_path,'dir'))

                        user_path=userpath;
                        disp('Userpath -2:')
                        disp(user_path)

                        user_path=replace(user_path,'/MATLAB','');
                        user_path=replace(user_path,'\MATLAB','');
                        disp('Userpath -1:')
                        disp(user_path)
                        user_path=fullfile(user_path,'MinPlotX');

                        disp('Userpath 0:')
                        disp(user_path)


                        if ~exist(user_path,'dir')
                            try
                                disp('Userpath does not exist')
                                [status, msg, ~] = mkdir(user_path);
                                if status==0
                                    answer = uiconfirm(app.MinPlotXGUI,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');

                                    disp('Userpath folder could not be created:')
                                    disp(msg)
                                    f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                    delete(f)
                                    %   disp('Userpath folder could not be created')
                                else
                                    disp('Userpath folder created:')
                                    disp(user_path)
                                end

                            catch ME
                                DisplayErrorNG(app,ME,'Startup Error mkdir')

                                if ~exist(user_path,'dir')
                                    answer = uiconfirm(app.MinPlotXGUI,{'Working directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                                    % uialert(app.MinPlotXGUI,'To avoide this message create a folder called MinPlotX in your Documents folder',' Error');
                                    user_path=uigetdir(pwd,'select working directory with read/write permission');
                                end
                            end
                        end
                    end

                    diary(fullfile(user_path,'MinPlotX_log.txt'));

                    disp('Userpath 1:')
                    disp(user_path)

                    disp('Installation Directory:')
                    disp(app.installpath)
                    disp('')

                    disp('tmp_path 1:')
                    disp(tmp_path)
                    disp('')


                    app.WorkingDirectoryEditField.Value=user_path;
                    app.WorkingDirectoryEditField.Tooltip=user_path;

                    cd(user_path);
                    if not(exist(fullfile(app.installpath,'Dev','MinPlotXWorkingDir'),'file')) || isempty(tmp_path)
                        try
                            dir2exp=fullfile(app.installpath,'Dev','MinPlotXWorkingDir');
                            disp('dir2exp:')
                            disp(dir2exp)

                            [fileID,errmsg] = fopen(dir2exp,'w');
                            if fileID==-1
                                dir2exp2=fullfile(user_path,'MinPlotXWorkingDir');
                                [fileID,errmsg] = fopen(dir2exp2,'w');
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                                destination=fullfile(app.installpath,'Dev');
                                [status,msg,~]= movefile(dir2exp2, destination,'f');
                                if status==false
                                    disp('Error msg:')
                                    disp(msg)
                                end
                            else
                                fprintf(fileID,'%s',user_path);
                                fclose(fileID);
                            end
                        catch ME
                            disp('errmsg:')
                            disp(errmsg)

                            DisplayErrorNG(app,ME,'Startup Error MinPlotXWorkingDir file')

                            ans1= uiconfirm(app.MinPlotXGUI, ...
                                {'MinPlotXWorkingDir file - Permission Error!',...
                                'To fix this Error you have to options:', '-'...
                                '1) Execute the following command in the terminal:',...
                                [ 'chmod -R 755 ' app.installpath ],'-',...
                                '2) Manually add a path to your default working directory folder with read/write permission to the file:'...
                                , fullfile(app.installpath,'Dev','MinPlotXWorkingDir') },"Permission Error", ...
                                "Icon","warning");
                            disp(ans1)

                        end
                    end
                catch ME
                    DisplayErrorNG(app,ME,'Startup Error 1')
                end

            else
                user_path=fileparts(which('MinPlotX.mlapp'));

                app.WorkingDirectoryEditField.Value=pwd;
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            end


            %% check for new version
            check_for_update_fun(app)
            %%


            try
                read_MineralCalcFunctions(app)
            catch ME
                DisplayErrorNG(app,ME,'Error reading MineralCalcFunctions.csv')
                message={'Error reading MineralCalcFunctions.csv'};
                uialert(app.MinPlotXGUI,message,' Error');
            end

            try
                ReadColorMaps(app)
            catch  ME
                DisplayErrorNG(app,ME,'Error reading Colormaps')
                message={'Error reading Colormaps'};
                uialert(app.MinPlotXGUI,message,' Error');

            end

            app.MineralDropDown.Items=unique([{'unknown'};{'MultiMineralData'}; app.MineralNames ],'stable');
            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);

            %%
            autoimport_state=false;
            if autoimport_state==true
                import_data_fun(app,[],fullfile(user_path,'Autoimport'))
                update_GUI(app)
                app.edit_state=true;
            end


        end

        % Menu selected function: OpenDocumentationMenu
        function OpenDocumentationMenuSelected(app, event)
            if isdeployed
                web(fullfile(app.installpath,'Dev','MinPlotXv2_Documentation.pdf'),'-browser')%#exclude
            else
                open(which(fullfile('Dev','MinPlotXv2_Documentation.pdf')));%#exclude
            end
        end

        % Menu selected function: InputOutputDocumentationMenu
        function InputOutputDocumentationMenuSelected(app, event)
            if isdeployed
                web(fullfile(app.installpath,'Dev','MinPlotX_Input_output.htm'),'-browser')%#exclude
            else
                open(which(fullfile('Dev','MinPlotX_Input_output.htm')));%#exclude
            end
        end

        % Button pushed function: GeoCPlotButton_2
        function GeoCPlotButton_2Pushed(app, event)
            GeoCPlot
        end

        % Menu selected function: NewMinPlotXProjectMenu
        function NewMinPlotXProjectMenuSelected(app, event)
            MinPlotX;
        end

        % Callback function: LoadMinPlotXProjectMenu, LoadProject_Button
        function LoadMinPlotXProjectMenuSelected(app, event)
            if app.edit_state==true
                if isfield(app.MinPlotXData,'projectpath') && not(isempty(app.MinPlotXData.projectpath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.MinPlotXData.projectpath);
                    projectname=[' ' baseFileNameNoExt];
                else
                    projectname='';
                end
                answer = uiconfirm(app.MinPlotXGUI,{['Do you want to save your project' projectname '?']},...
                    'Save Project?','Options',{'Yes','No','Cancel'},'Icon','warning');
                switch answer
                    case 'Yes'
                        SaveMinPlotXProjectMenuSelected(app)
                    case 'Cancel'
                        return
                end

            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.mat'},"MultiSelect","off", 'Open Project');
            delete(f);
            figure(app.MinPlotXGUI);

            if not(isequal(file,0))
                thx=load(fullfile(path,file));
                app.MinPlotXData=thx.data2save;
            else
                return
            end

            app.MinPlotXData.projectpath=fullfile(path,file);
            app.MinPlotXGUI.Name = app.MinPlotXData.projectpath;

            try
                update_GUI(app,true)
            catch ME
                DisplayErrorNG(app,ME,'Update UI after load project failed')
            end
        end

        % Callback function: SaveDBProjectAs_Button, SaveMinPlotXProjectMenu
        function SaveMinPlotXProjectMenuSelected(app, event)
            if isfield(app.MinPlotXData,'projectpath') && not(isempty(app.MinPlotXData.projectpath))
                app.MinPlotXGUI.Name = [app.MinPlotXData.projectpath ' [... Saving Project]'] ;
                data2save=app.MinPlotXData;
                save(app.MinPlotXData.projectpath, 'data2save', '-v7.3');
                app.MinPlotXGUI.Name = app.MinPlotXData.projectpath;
            else
                SaveMinPlotXProjectAsMenuSelected(app)
            end
            if isfield(app.MinPlotXData,'projectpath')
                app.MinPlotXGUI.Name = app.MinPlotXData.projectpath;
                app.edit_state=false;
            end
        end

        % Menu selected function: SaveMinPlotXProjectAsMenu
        function SaveMinPlotXProjectAsMenuSelected(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.mat', 'Save the project file:');
            delete(f);
            figure(app.MinPlotXGUI);

            if not(isequal(filename,0))
                FileName = fullfile(filepath, filename);
                app.MinPlotXData.projectpath=FileName;
                app.MinPlotXGUI.Name = [app.MinPlotXData.projectpath ' [... Saving Project]'] ;

                data2save=app.MinPlotXData;
                save(FileName, 'data2save', '-v7.3');
                app.MinPlotXGUI.Name = app.MinPlotXData.projectpath;
            end
            app.edit_state=false;
        end

        % Close request function: MinPlotXGUI
        function MinPlotXGUICloseRequest(app, event)
            if not(isnumeric(app.d)) && isvalid(app.d)
                delete(app.d)
                return
            end

            if app.edit_state==true
                if isfield(app.MinPlotXData,'projectpath') && not(isempty(app.MinPlotXData.projectpath))
                    [~, baseFileNameNoExt, ~] = fileparts(app.MinPlotXData.projectpath);
                    projectname=[' ' baseFileNameNoExt];

                else
                    projectname='';
                end
                answer = uiconfirm(app.MinPlotXGUI,{['Do you want to save your project' projectname '?']},...
                    'Save Project?','Options',{'Yes','No','Cancel'},'Icon','warning');

                switch answer
                    case 'Yes'

                        SaveMinPlotXProjectMenuSelected(app)
                        CloseMinPlot_cleanup_fun(app)
                        %           delete(app)
                    case 'No'
                        CloseMinPlot_cleanup_fun(app)
                        delete(app)
                    case 'Cancel'
                        return
                end
            else

                CloseMinPlot_cleanup_fun(app)
                delete(app)
            end

        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select export directory with read/write permission');
            delete(f)
            figure(app.MinPlotXGUI);

            if path==0
                app.WorkingDirectoryEditField.Value=pwd;
            else
                app.WorkingDirectoryEditField.Value=path;
            end
            app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;

            WorkingDirectoryEditFieldValueChanged(app)
        end

        % Value changed function: WorkingDirectoryEditField
        function WorkingDirectoryEditFieldValueChanged(app, event)
            value = app.WorkingDirectoryEditField.Value;
            if not(exist(value,'dir'))
                uiconfirm(app.MinPlotXGUI,{'Directory not found - Select working directory'},'Select directory','Options',"OK",'Icon','warning');
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                path=uigetdir(pwd,'select working directory with read/write permission');
                delete(f)
                figure(app.MinPlotXGUI);

                if ~isequal(path,0)
                    cd(path)
                    app.WorkingDirectoryEditField.Value=path;
                else
                    app.WorkingDirectoryEditField.Value=pwd;
                end
                app.WorkingDirectoryEditField.Tooltip=app.WorkingDirectoryEditField.Value;
                diary('MinPlotX_log.txt');
            else
                diary('MinPlotX_log.txt');
            end
        end

        % Callback function: ImportFromFiles_Button, ImportfilesMenu
        function ImportfilesButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            [file,path]=uigetfile({'*.csv;*.xls;*.txt;*.dpt;*.xlsx;*.CSV'},"MultiSelect","on",'Select File');
            delete(f);
            figure(app.MinPlotXGUI)
            if isequal(file,0)
                return
            end

            if not(iscell(file))
                file={file};
            end
            path_cell=cell(size(file));
            path_cell(:)={path};

            import_data_fun(app,file,path_cell)
            update_GUI(app)
            app.edit_state=true;
        end

        % Callback function: ImportFromFolder_Button, ImportfolderMenu
        function ImportfolderButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);

            path=uigetdir;
            delete(f);
            figure(app.MinPlotXGUI)
            if isequal(path,0)
                return
            end
            import_data_fun(app,[],path)


            update_GUI(app)
            app.edit_state=true;

        end

        % Value changed function: MineralDropDown
        function MineralDropDownValueChanged(app, event)
            if isfield(app.MinPlotXData,'SampleNames') && not(isempty(app.MinPlotXData.SampleNames)) && not(isempty(app.active_entry)) && ismember(app.active_entry,app.SamplesListBox.ItemsData)
                app.MinPlotXData.Sample(app.active_entry).Input.MineralName=app.MineralDropDown.Value;
                app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral(:)={app.MineralDropDown.Value};
                app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction=[];


                update_MineralSpecificOptionsTable(app)

                if strcmp(app.MineralDropDown.Value, 'MultiMineralData')
                    app.SwitchtoMultiCalcButton.Visible=true;
                else
                    app.SwitchtoMultiCalcButton.Visible=false;
                end


            end
            app.edit_state=true;
            update_GUI(app,false)

        end

        % Value changed function: SamplesListBox
        function SamplesListBoxValueChanged(app, event)
            app.active_entry=app.SamplesListBox.Value;

            update_GUI(app,false)

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            update_output_table(app)
        end

        % Button pushed function: CalculateallButton
        function CalculateallButtonPushed(app, event)
            app.PlotResultsCheckBox.Value=false;
            app.d = uiprogressdlg(app.MinPlotXGUI,'Title','Calculation in Progress...',...
                'Message',' Calculation Started','Cancelable','on');
            t=0;
            %app.InputTable.Visible=false;
            %   app.OutputTable.Visible=false;

            for n=1:numel(app.MinPlotXData.SampleNames)
                tic
                try
                    if  not(isvalid(app.d))
                        app.d = uiprogressdlg(app.MinPlotXGUI,'Title','Calculation in Progress...',...
                            'Message',{['Calculating : ' num2str(n) ' of ' num2str(numel(app.MinPlotXData.SampleNames)) ': '],[  app.MinPlotXData.SampleNames{n}],[' Time for last calculation: ' num2str(round(t,1)) ' seconds']},'Cancelable','on');
                    else
                        app.d.Message = {['Calculating : ' num2str(n) ' of ' num2str(numel(app.MinPlotXData.SampleNames)) ': '],[  app.MinPlotXData.SampleNames{n}],[' Time for last calculation: ' num2str(round(t,1)) ' seconds']};
                        app.d.Value = n/numel(app.MinPlotXData.SampleNames);
                        drawnow

                    end
                    if app.d.CancelRequested
                        update_GUI(app,false)
                        %      app.InputTable.Visible=true;
                        %      app.OutputTable.Visible=true;
                        if not(isempty(app.d))
                            delete(app.d)
                        end
                        app.edit_state=true;

                        return
                    end
                catch ME
                    DisplayErrorNG(app,ME,'Calculate All Error')

                end

                app.active_entry=n;
                app.SamplesListBox.Value=n;
                update_GUI(app,false)
                if strcmp('MultiMineralData',app.MineralDropDown.Value)
                    MultiMineralCalcButtonPushed(app)
                else
                    calculate_fun(app);
                end
                t= toc;

            end


            update_GUI(app,false)
            %     app.InputTable.Visible=true;
            %     app.OutputTable.Visible=true;
            if not(isempty(app.d))
                delete(app.d)
            end
            app.edit_state=true;


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if  not(isfield(app.MinPlotXData,'SampleNames')) ||isempty(app.MinPlotXData.SampleNames) || isempty(app.active_entry) || not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                update_GUI(app)
                return %UIalert
            end
            try

                calculate_fun(app);
                update_output_table(app)

                update_GUI(app,false)
                app.edit_state=true;
            catch ME
                DisplayErrorNG(app,ME,'CalculateButtonPushed')

            end
        end

        % Button pushed function: SendtoGeoCPlotButton
        function SendtoGeoCPlotButtonPushed(app, event)

            T_cell={};
            for n=1:numel(app.MinPlotXData.SampleNames)
                T=  get_compiled_table(app,n);
                T_cell{end+1}=T; %#ok
            end


            T =tablevertcat_NG(app,T_cell);
            if isempty(T)
                return
            end
            GeoCPlot(T)


        end

        % Menu selected function: MergeTablesMenu
        function MergeTablesMenuSelected(app, event)
            MatchTableModule(app)
        end

        % Button pushed function: MultiMineralCalcButton
        function MultiMineralCalcButtonPushed(app, event)
            % Table update


            app.d = uiprogressdlg(app.MinPlotXGUI,'Title','Calculating...',...
                'Message','MultiCalc in Progress','Cancelable','on');
            app.Tree.CheckedNodes=[];
            update_MultiCalcTable_table(app)
            update_GUI(app,false)
            app.PlotResultsCheckBox.Value=false;


            %             app.CalcSettingsTable.Data = [{'to do';'group 1';'group 2'},{'later';'Amph_2';'Amph_3'}, {1;1;2}]; % have 3 rows of gender information.
            %             app.CalcSettingsTable.ColumnFormat = { {'to do' 'group 2'} {'later','Amph_2','Amph_3'} []};% {{{'Amph','px','Amph_1','Amph_2','Amph_3'}} {{'Amph','px','Amph_1','Amph_2','Amph_3'}}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            %             app.CalcSettingsTable.ColumnEditable = true;

            %             app.CalcSettingsTable.Data = [{'to do';'group 1';'group 2'},{'later';'Amph_2';'Amph_3'}, {1;1;2}]; % have 3 rows of gender information.
            %             app.CalcSettingsTable.ColumnFormat = { {'to do' 'group 2'} {'later','Amph_2','Amph_3'} []};% {{{'Amph','px','Amph_1','Amph_2','Amph_3'}} {{'Amph','px','Amph_1','Amph_2','Amph_3'}}}; % have option cell array within ColumnFormat property to indicate the 1st column is using dropdown menus.
            %             app.CalcSettingsTable.ColumnEditable = true;


            %             if not(isempty(checkedNodes))
            %
            %                 NodeText={checkedNodes.Text}';
            %                 NodeData=cell2mat({checkedNodes.NodeData}');
            %                 MineralCalcFunction=NodeText(NodeData(:,2)>0|contains(NodeText,'unknown'));
            %                 filter=contains(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction,MineralCalcFunction);
            %
            %             else
            %                 filter=true(app.MinPlotXData.Sample(app.active_entry).Input.data,1);
            %             end


            Output=struct();
            if app.d.CancelRequested
                return
            end

            for n=1:size(app.MinPlotXData.Sample(app.active_entry).Input.data,1) % full table needs to be processed to optain same number of rows as input
                app.d.Value = n/size(app.MinPlotXData.Sample(app.active_entry).Input.data,1);
                app.d.Message = char(['Calculating : ' num2str(n) ' of ' num2str(size(app.MinPlotXData.Sample(app.active_entry).Input.data,1))]);
                if app.d.CancelRequested
                    break
                end

                save_in_struct=false;
                options_input=[];
                MinCalc_fun=app.MultiMinCalcTable.Data(n,ismember(app.MultiMinCalcTable.ColumnName,'MineralCalcFunction'));
                if isempty(MinCalc_fun)
                    MinCalc_fun='unknown';
                end
                if not(endsWith(MinCalc_fun,'_XMapT')) && not(strcmp('unknown',MinCalc_fun))
                    try
                        [~, ~,options] = feval(char(MinCalc_fun),[],[],[]);

                        fns=fieldnames(options);


                        if not(isempty(fns))
                            for k=1:numel(fns)
                                fn=fns(k);


                                if isfield(app.MinPlotXData.Sample(app.active_entry),'MultiCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings))&&...
                                        isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings,char(MinCalc_fun)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun))))&&...
                                        isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)),char(fn)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn))))&&...
                                        isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)),'Edited') && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Edited))&&...
                                        app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Edited(n)==true
                                    options_input.(char(fn)).Value=app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Value{n};
                                else
                                    options_input.(char(fn)).Value=options.(char(fn)).Value;
                                end
                            end
                        end
                    catch ME
                        DisplayErrorNG(app,ME,'Error in [~, ~,options] = feval(char(MinCalc_fun),[],[],[]);')

                        options_input=[];
                    end
                else
                    options_input=[];
                end
                SampleName=app.MinPlotXData.SampleNames(app.active_entry);
                MineralName={app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction(n)};
                headers=app.MinPlotXData.Sample(app.active_entry).Input.headers;
                data=app.MinPlotXData.Sample(app.active_entry).Input.data(n,:);

                if any(strcmp(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames,'Fe3_rat'))
                    headers{end+1}='Fe3_ratio'; %#ok
                    data(1,end+1)=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Fe3_rat(n);%#ok
                elseif any(strcmp(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames,'Fe3_ratio'))
                    headers{end+1}='Fe3_ratio'; %#ok
                    data(1,end+1)=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Fe3_ratio(n);%#ok
                end


                if any(strcmp(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames,'Mn3_rat'))
                    headers{end+1}='Mn3_ratio';%#ok
                    data(1,end+1)=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mn3_rat(n);%#ok
                elseif any(strcmp(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Properties.VariableNames,'Mn3_ratio'))
                    headers{end+1}='Mn3_ratio';%#ok
                    data(1,end+1)=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mn3_ratio(n);%#ok
                end

                [StrctFrm,apfu]=calculate_fun(app,data,headers,MineralName,SampleName,options_input,save_in_struct);

                Output(n).StrctFrm=StrctFrm;
                Output(n).apfu=apfu;
                Output(n).options=options_input;
                app.MinPlotXData.Sample(app.active_entry).Output(n).MultiCalcOutput=Output(n);

            end


            struct_thx=table2cell(struct2table(Output,'AsArray',true));


            for n=1:size(app.MinPlotXData.Sample(app.active_entry).Input.data,1) % full table needs to be processed to optain same number of rows as input
                if isempty(Output(n).options)
                    struct_thx(n,3)={table()};
                else

                    fns=fieldnames(Output(n).options);
                    T=table();
                    for k=1:numel(fns)
                        T.(char(fns(k)))=Output(n).options.(char(fns(k))).Value;
                    end
                    struct_thx(n,3)={T};
                end

            end


            StrctFrm=tablevertcat_NG(app,struct_thx(:,1)');

            apfu=tablevertcat_NG(app,struct_thx(:,2)');
            options=tablevertcat_NG(app,struct_thx(:,3)');

            app.MinPlotXData.Sample(app.active_entry).Output(1).StrctFrm=StrctFrm;
            app.MinPlotXData.Sample(app.active_entry).Output(1).apfu=apfu;
            app.MinPlotXData.Sample(app.active_entry).Output(1).options=options;
            update_output_table(app)


            update_GUI(app,false)
            app.edit_state=true;
            delete(app.d)

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            update_MultiCalcTable_table(app)
        end

        % Button pushed function: ResetColFilt
        function ResetColFiltButtonPushed(app, event)
            app.MinPlotXData.ColumnFilter=[];
            if  not(isempty(app.active_entry)) && numel(app.active_entry)==1 && app.active_entry>0 
                        app.MinPlotXData.Sample(app.active_entry).ColumnFilter=[];
            end
            update_MultiCalcTable_table(app)
        end

        % Button pushed function: AddColFilt
        function AddColFiltButtonPushed(app, event)
            if  not(isempty(app.active_entry)) && numel(app.active_entry)==1 && app.active_entry>0 
            

            thx_table_Meta=app.MultiMinCalcTable.Data;
            if iscell(thx_table_Meta)
            list =  app.MultiMinCalcTable.ColumnName';
            elseif istable(thx_table_Meta)
            list = thx_table_Meta.Properties.VariableNames; %ATH
            else
                return
            end
            not2remove={'SpotId','Active','Mineral','MineralCalcFunction'}; %settings columns
            list2=list(not(ismember(list,not2remove)));
            [indx,~] = listdlg('ListString',list2,'SelectionMode','multi');
            figure(app.MinPlotXGUI);
            app.MinPlotXData.ColumnFilter=list((ismember(list, [not2remove list2(indx)])));
            app.MinPlotXData.Sample(app.active_entry).ColumnFilter=list((ismember(list, [not2remove list2(indx)])));
            update_MultiCalcTable_table(app)
            else
                            app.MinPlotXData.ColumnFilter=[];
            end
        end

        % Value changed function: ShowfulltableCheckBox
        function ShowfulltableCheckBoxValueChanged(app, event)
            update_MultiCalcTable_table(app)
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.Tree.CheckedNodes=app.Tree.Children;
            update_MultiCalcTable_table(app)
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            app.Tree.CheckedNodes=[];
            update_MultiCalcTable_table(app)
        end

        % Cell edit callback: MultiMinCalcTable
        function MultiMinCalcTableCellEdit(app, event)
            indices = event.Indices;
            % newData = event.NewData;

            SpotId=app.MultiMinCalcTable.Data{indices(1),strcmp(app.MultiMinCalcTable.ColumnName,'SpotId')};
            % if testallowedname           %ATH add panelty if allowed string MineralName and
            % MineralCalcFunction

            %             if iscell(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.(char(app.MultiMinCalcTable.ColumnName(indices(2))))(SpotId))
            %                 app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.(char(app.MultiMinCalcTable.ColumnName(indices(2))))(SpotId)={event.NewData};
            %             else
            %                 app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.(char(app.MultiMinCalcTable.ColumnName(indices(2))))(SpotId)=event.NewData;
            %             end

            MinCalc_fun=app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.MineralCalcFunction(SpotId);
            fn=char(app.MultiMinCalcTable.ColumnName(indices(2)));
            if isfield(app.MinPlotXData.Sample(app.active_entry),'MultiCalcSettings') && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings))&&...
                    isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings,char(MinCalc_fun)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun))))&&...
                    isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)),char(fn)) && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn))))&&...
                    isfield(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)),'Edited') && not(isempty(app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Edited))

                app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Edited(SpotId)=true;
                app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Value(SpotId)={event.NewData};
            else
                app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Edited=false(size(app.MultiMinCalcTable.Data,1),1);
                app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Value=cell(size(app.MultiMinCalcTable.Data,1),1);
                app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Edited(SpotId)=true;
                app.MinPlotXData.Sample(app.active_entry).MultiCalcSettings.(char(MinCalc_fun)).(char(fn)).Value(SpotId)={event.NewData};
            end
            %         else
            % uialert
            %         end
            if strcmp(char(app.MultiMinCalcTable.ColumnName(indices(2))),'Mineral')
                % CheckMinName and adjust to match mineral and allowed
                % MineralCalcFunctions
            end

            UpdateTree(app,app.Tree)
            update_MultiCalcTable_table(app)
        end

        % Button pushed function: FindMineralButton
        function FindMineralButtonPushed(app, event)
            FindMineralsInMetaModule(app)
        end

        % Button pushed function: SpotVisMinPlotButton
        function SpotVisMinPlotXButtonPushed(app, event)
            SpotVisMinPlotX(app,[])
        end

        % Menu selected function: CalculateTotalfromDataMenu
        function CalculateTotalfromDataMenuSelected(app, event)
            if any(strcmp('Total',app.MinPlotXData.Sample(app.active_entry).Input.headers))
                %ask user if recalculate totals
            end

            app.MinPlotXData.Sample(app.active_entry).Input.headers(end+1)={'Total'};
            app.MinPlotXData.Sample(app.active_entry).Input.data(:,end+1)=sum(app.MinPlotXData.Sample(app.active_entry).Input.data,2,'omitnan');

            update_GUI(app,false);
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if not(isfield(app.MinPlotXData,'SampleNames')) ||isempty(app.MinPlotXData.SampleNames) || isempty(app.active_entry) || not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                update_GUI(app,true);
                return
            end
            app.MinPlotXData.Sample(app.active_entry)=[];
            app.MinPlotXData.SampleNames(app.active_entry)=[];
            if app.active_entry>=2
                app.active_entry=app.active_entry-1;
            elseif numel(app.MinPlotXData.SampleNames)>1
                app.active_entry=1;
            elseif numel(app.MinPlotXData.SampleNames)==1
                ClearMinPlotXProjectMenuSelected(app)
                return
            end
            update_GUI(app,true);
        end

        % Menu selected function: ClearMinPlotXProjectMenu
        function ClearMinPlotXProjectMenuSelected(app, event)
            if isfield(app.MinPlotXData,'Sample')
                app.MinPlotXData.Sample(:)=[];
            end

            if isfield(app.MinPlotXData,'SampleNames')
                app.MinPlotXData.SampleNames(:)=[];
            end

            app.active_entry=[];
            update_GUI(app,true);
        end

        % Callback function
        function GenerateMineralFormulaMenuSelected(app, event)
            % make string CaMgSi_2O_6 [Mg(_{0.33}) Fe(_{0.67}) ]
            T=get_compiled_table(app,app.active_entry);


            if isempty(T)
                return
            end

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [filename, filepath] = uiputfile({'*.csv';'*.xls';'*.xlsx';'*.txt';'*.dat'}, 'Save the project file: ',app.WorkingDirectoryEditField.Value);
            delete(f)
            figure(app.MinPlotXGUI);

            if not(isequal(filename,0))
                writetable(T,fullfile(filepath,filename));
            end

        end

        % Button pushed function: SwitchtoMultiCalcButton
        function SwitchtoMultiCalcButtonPushed(app, event)
            app.TabGroup3.SelectedTab=app.TabGroup3.Children(strcmp('MultiMineralCalc',{app.TabGroup3.Children.Title}));
        end

        % Callback function: DataCalculatorModuleButton, 
        % DataCalculatorModuleMenu
        function DataCalculatorModuleMenuSelected(app, event)
            T=get_compiled_table(app,app.active_entry);
            DataCalculatorModule(app, app.SamplesListBox.Value, {'MinPlotX'},T)
        end

        % Callback function: AdvancedPlotModuleButton, AdvancedPlotModuleMenu
        function ResultsModuleButtonPushed(app, event)
            AdvancedPlotModule(app)
        end

        % Callback function: CustomExportMenu, ExportModuleButton, 
        % ExportModuleButton_2
        function CustomExportMenuSelected(app, event)
            MinPlotXExportModule(app)
        end

        % Menu selected function: AutoimportMenu
        function AutoimportMenuSelected(app, event)
            if isdeployed %standalone mode.

                path=app.installpath;
            else
                path=fileparts(which('MinPlotX.mlapp'));
            end

            import_data_fun(app,[],fullfile(path,'Autoimport'))
            update_GUI(app)
            app.edit_state=true;
        end

        % Menu selected function: ExportapfuResultsTableMenu
        function ExportapfuResultsTableMenuSelected(app, event)
            if   isfield(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id),'apfu') && ...
                    not(isempty(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu))&& ...
                    istable(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu)
                app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu;

                T=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu;
                SampleNames=app.MinPlotXData.SampleNames{app.active_entry};


                path=uigetdir(app.WorkingDirectoryEditField.Value,'select export directory');
                if path==0
                    return
                end
                writetable(T,fullfile(path,[SampleNames '.csv']))
            end
        end

        % Menu selected function: ExportStrucFMResultsTableMenu
        function ExportStrucFMResultsTableMenuSelected(app, event)
            if   isfield(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id),'StrctFrm') && ...
                    not(isempty(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm))&& ...
                    istable(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm)

                app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm;
                T=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm;
                path=uigetdir(app.WorkingDirectoryEditField.Value,'select export directory');
                if path==0
                    return
                end
                writetable(T,fullfile(path,[SampleNames '.csv']))
            end
        end

        % Menu selected function: ExportTableMenu
        function ExportTableMenuSelected(app, event)
            if  strcmp(app.Switch.Value,'apfu') && ...
                    isfield(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id),'apfu') && ...
                    not(isempty(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu))&& ...
                    istable(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu)
                app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu;

                T=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).apfu;

            elseif  strcmp(app.Switch.Value,'Structural Formula') &&...
                    isfield(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id),'StrctFrm') && ...
                    not(isempty(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm))&& ...
                    istable(app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm)

                app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm;
                T=app.MinPlotXData.Sample(app.active_entry).Output(app.calculation_id).StrctFrm;
            else
                %uialert calculate first
                return
            end
            SampleNames=app.MinPlotXData.SampleNames{app.active_entry};

            % T= table(Data,)
            % path=app.WorkingDirectoryEditField.Value;
            path=uigetdir(app.WorkingDirectoryEditField.Value,'select export directory');
            if path==0
                return
            end
            writetable(T,fullfile(path,[SampleNames '.csv']))

        end

        % Value changed function: EstimateFe3fromstoichiometryCheckBox
        function EstimateFe3fromstoichiometryCheckBoxValueChanged(app, event)
            if app.EstimateFe3fromstoichiometryCheckBox.Value==true
                app.CalculatewithFe3CheckBox.Value=true;
                app.UseFe3FeTotalRatioFromTableCheckBox.Value=false;
                app.UseGlobalFe3FeTotalRatioCheckBox.Value=false;
            elseif app.CalculatewithFe3CheckBox.Value==true
                app.UseFe3FeTotalRatioFromTableCheckBox.Value=true;
            end
            UseGlobalFe3FeTotalRatioCheckBoxValueChanged(app)
            update_MineralSpecificOptions(app)
        end

        % Value changed function: UseFe3FeTotalRatioFromTableCheckBox
        function UseFe3FeTotalRatioFromTableCheckBoxValueChanged(app, event)
            if app.UseFe3FeTotalRatioFromTableCheckBox.Value==true
                app.CalculatewithFe3CheckBox.Value=true;
                app.EstimateFe3fromstoichiometryCheckBox.Value=false;
                app.UseGlobalFe3FeTotalRatioCheckBox.Value=false;
            elseif app.EstimateFe3fromstoichiometryCheckBox.Visible==true &&app.EstimateFe3fromstoichiometryCheckBox.Value==false && app.UseGlobalFe3FeTotalRatioCheckBox.Value==false &&  app.CalculatewithFe3CheckBox.Value==true
                app.EstimateFe3fromstoichiometryCheckBox.Value=true ;
                app.Fe3FeTotalEditField.Visible=false;
                app.Fe3FeTotalEditFieldLabel.Visible=false;
            else
                app.CalculatewithFe3CheckBox.Value=false;


            end
            UseGlobalFe3FeTotalRatioCheckBoxValueChanged(app)

            update_MineralSpecificOptions(app)
        end

        % Value changed function: UseGlobalFe3FeTotalRatioCheckBox
        function UseGlobalFe3FeTotalRatioCheckBoxValueChanged(app, event)
            if app.UseGlobalFe3FeTotalRatioCheckBox.Value==true
                app.EstimateFe3fromstoichiometryCheckBox.Value=false ;
                app.Fe3FeTotalEditField.Visible=true;
                app.Fe3FeTotalEditFieldLabel.Visible=true;
                app.CalculatewithFe3CheckBox.Value=true;
                app.UseFe3FeTotalRatioFromTableCheckBox.Value=false;
            elseif app.EstimateFe3fromstoichiometryCheckBox.Visible==true && app.EstimateFe3fromstoichiometryCheckBox.Value==false && app.UseFe3FeTotalRatioFromTableCheckBox.Value==false && app.CalculatewithFe3CheckBox.Value==true
                app.EstimateFe3fromstoichiometryCheckBox.Value=true ;
                app.Fe3FeTotalEditField.Visible=false;
                app.Fe3FeTotalEditFieldLabel.Visible=false;
            elseif app.UseGlobalFe3FeTotalRatioCheckBox.Value==false
                app.Fe3FeTotalEditField.Visible=false;
                app.Fe3FeTotalEditFieldLabel.Visible=false;
                if app.EstimateFe3fromstoichiometryCheckBox.Visible==false&& app.CalculatewithFe3CheckBox.Value==true
                    app.UseFe3FeTotalRatioFromTableCheckBox.Value=true;
                end
            end
            update_MineralSpecificOptions(app)
        end

        % Value changed function: UseKnownFe3tetraFromTableCheckBox
        function UseKnownFe3tetraFromTableCheckBoxValueChanged(app, event)
            if app.UseKnownFe3tetraFromTableCheckBox.Value==true
                app.CalculatewithFe3CheckBox.Value=true;
                app.UseGlobalFe3tetraCheckBox.Value=false;
                UseGlobalFe3tetraCheckBoxValueChanged(app)
            end
            update_MineralSpecificOptions(app)
        end

        % Value changed function: UseGlobalFe3tetraCheckBox
        function UseGlobalFe3tetraCheckBoxValueChanged(app, event)
            if app.UseGlobalFe3tetraCheckBox.Value==true
                app.CalculatewithFe3CheckBox.Value=true;
                app.Fe3TetraEditField.Visible=true;
                app.Fe3TetraEditFieldLabel.Visible=true;
                app.UseKnownFe3tetraFromTableCheckBox.Value=false;
            else
                app.Fe3TetraEditField.Visible=false;
                app.Fe3TetraEditFieldLabel.Visible=false;
            end
            update_MineralSpecificOptions(app)
        end

        % Button pushed function: EasyPlotButton
        function EasyPlotButtonPushed(app, event)
            if not(isfield(app.MinPlotXData,'SampleNames')) ||isempty(app.MinPlotXData.SampleNames) || isempty(app.active_entry) || not(ismember(app.active_entry,app.SamplesListBox.ItemsData))
                return
            end


            if strcmp('MultiMineralData',app.MineralDropDown.Value)
                Min2Plot=unique(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral);
                if numel(Min2Plot)>3
                    answer = uiconfirm(app.MinPlotXGUI, ...
                        ['Are you sure you want to generate plots for ' num2str(numel(Min2Plot)) ' minerals?'],"Generate Plots", ...
                        "Icon","warning");

                    if strcmp(answer,'Cancel')
                        return
                    end
                end

            else
                Min2Plot={app.MineralDropDown.Value};
            end
            T_full=  get_compiled_table(app,app.active_entry);

            % Symb_Ini=app.SymbolDropDown.Value;
            % Col_Ini=app.ColorDropDown.Value;

            for M2P=1:numel(Min2Plot)

                %             app.MinPlotXFigures.hFig_type=app.MinPlotXFigures.hFig_type(cellfun(@isgraphics,{app.MinPlotXFigures.hFig.ax2plot}));
                %             app.MinPlotXFigures.hFig=app.MinPlotXFigures.hFig(cellfun(@isgraphics,{app.MinPlotXFigures.hFig.ax2plot}));


                if not(isempty(app.active_entry))   && not( strcmp('unknown',Min2Plot{M2P}))
                    if not(isfield(app.MinPlotXData.Sample(app.active_entry),'Output')) || isempty(app.MinPlotXData.Sample(app.active_entry).Output)
                        CalculateButtonPushed(app)
                        T_full=  get_compiled_table(app,app.active_entry);
                    end

                    if true
                        T= T_full(strcmp(T_full.Mineral,Min2Plot{M2P}),:);
                    end

                    if app.EasyPlotSubdivideMenu.Checked && not(ismember(app.DivColDropDown.Value,T.Properties.VariableNames)) && not(strcmp(app.DivColDropDown.Value,'none'))
                        app.DivColDropDown.Value='none';
                    end

                    if app.EasyPlotColormapMenu.Checked && not(ismember(app.CbarVarDropDown.Value,T.Properties.VariableNames)) && not(strcmp(app.CbarVarDropDown.Value,'1:n'))
                        app.CbarVarDropDown.Value='1:n';
                        app.ColorbarLabelEditField.Value='1:n';
                    end


                    try
                        if app.EasyPlotSubdivideMenu.Checked && not(strcmp(app.DivColDropDown.Value,'none')) && ismember(app.DivColDropDown.Value,T.Properties.VariableNames)
                            subdiv_unique=unique(T.(char(app.DivColDropDown.Value)));
                            subdiv_unique_n=numel(subdiv_unique);
                        else
                            subdiv_unique_n=1;
                        end


                        for subdiv_n=1:subdiv_unique_n

                            if true
                                T= T_full(strcmp(T_full.Mineral,Min2Plot{M2P}),:);
                            end


                            if subdiv_unique_n>1 && not(strcmp(app.DivColDropDown.Value,'none'))
                                col_tmp= T.(char(app.DivColDropDown.Value));
                                %   T=T(ismember(col_tmp,subdiv_unique(subdiv_n)),ismember(T.Properties.VariableNames,app.DivColDropDown.Value));
                                T=T(ismember(col_tmp,subdiv_unique(subdiv_n)),:);
                            end

                            %% xy
                            [ax2plot,options_definition]=plot_XY_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));

                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end


                            if sum(which2plot)>0
                                for n=1:numel(which2plot)
                                    try

                                        %% test figure handle track
                                        if isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type) &&  isgraphics(app.MinPlotXFigures.hFig(find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}))).ax2plot)
                                            hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            ax2plot=app.MinPlotXFigures.hFig(hFig_id).ax2plot;
                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                        else
                                            if not(isfield(app.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotXFigures) || isempty(app.MinPlotXFigures.hFig_type) % or handle not valid
                                                hFig_id=1;
                                            elseif isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type)
                                                hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            else
                                                hFig_id=numel(app.MinPlotXFigures.hFig_type)+1;
                                            end

                                            hfig=figure;

                                            hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app,src, event);

                                            hfig.Name=options_definition.type.description{which2plot(n)};
                                            hfig.Position(3:4)=hfig.Position(3:4).*1.5;
                                            CenterFig_fun(hfig)

                                            ax2plot=nexttile;

                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                            [ax2plot,options_definition_]=plot_XY_fun(ax2plot,options);%#ok

                                            app.MinPlotXFigures.hFig(hFig_id).options_definition=[];
                                            app.MinPlotXFigures.hFig(hFig_id).options=[];
                                            hfig.Visible=true;

                                        end


                                        [ax2plot,options_definition,~]=plot_XY_data_fun(T,ax2plot,options);

                                        app.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;
                                        if numel(ax2plot.Children)>0
                                            if subdiv_unique_n>1
                                                ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                            else
                                                ax2plot.Children(1).DisplayName=app.MinPlotXData.SampleNames{app.active_entry};
                                            end
                                            scatter_obj=flipud(findobj('Parent',app.MinPlotXFigures.hFig(hFig_id).ax2plot,'Type','Scatter'));

                                            if not(isempty(scatter_obj))
                                                legend(ax2plot,scatter_obj.DisplayName,'Interpreter','none','Location','bestoutside')

                                            end
                                        end

                                        app.MinPlotXFigures.hFig(hFig_id).options_definition=options_definition;
                                        app.MinPlotXFigures.hFig(hFig_id).options=options;
                                        app.MinPlotXFigures.hFig_type{hFig_id}=options_definition.type.options{which2plot(n)};
                                        app.MinPlotXFigures.hFig_description{hFig_id}=options_definition.type.description{which2plot(n)};

                                        % end test figure handle track


                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror XY')


                                    end
                                end
                            end


                            %% xyz
                            [ax2plot,options_definition]=plot_XYZ_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));

                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end

                            if sum(which2plot)>0
                                for n=1:numel(which2plot)
                                    try

                                        %% test figure handle track
                                        if isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type) &&  isgraphics(app.MinPlotXFigures.hFig(find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}))).ax2plot)
                                            hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            ax2plot=app.MinPlotXFigures.hFig(hFig_id).ax2plot;
                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                        else
                                            if not(isfield(app.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotXFigures) || isempty(app.MinPlotXFigures.hFig_type) % or handle not valid
                                                hFig_id=1;
                                            elseif isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type)
                                                hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            else
                                                hFig_id=numel(app.MinPlotXFigures.hFig_type)+1;
                                            end

                                            hfig=figure;
                                            hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app,src, event);

                                            hfig.Name=options_definition.type.description{which2plot(n)};
                                            hfig.Position(3:4)=hfig.Position(3:4).*1.5;
                                            CenterFig_fun(hfig)
                                            ax2plot=nexttile;

                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                            [ax2plot,options_definition_]=plot_XYZ_fun(ax2plot,options);%#ok

                                            app.MinPlotXFigures.hFig(hFig_id).options_definition=[];
                                            app.MinPlotXFigures.hFig(hFig_id).options=[];
                                            hfig.Visible=true;

                                        end


                                        [ax2plot,options_definition,~]=plot_XYZ_data_fun(T,ax2plot,options);

                                        app.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;
                                        if numel(ax2plot.Children)>0

                                            if subdiv_unique_n>1
                                                ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                            else
                                                ax2plot.Children(1).DisplayName=app.MinPlotXData.SampleNames{app.active_entry};
                                            end
                                            scatter_obj=flipud(findobj('Parent',app.MinPlotXFigures.hFig(hFig_id).ax2plot,'Type','Scatter'));

                                            if not(isempty(scatter_obj))
                                                legend(ax2plot,scatter_obj.DisplayName,'Interpreter','none','Location','bestoutside')

                                            end
                                        end
                                        app.MinPlotXFigures.hFig(hFig_id).options_definition=options_definition;
                                        app.MinPlotXFigures.hFig(hFig_id).options=options;
                                        app.MinPlotXFigures.hFig_type{hFig_id}=options_definition.type.options{which2plot(n)};
                                        app.MinPlotXFigures.hFig_description{hFig_id}=options_definition.type.description{which2plot(n)};

                                        % end test figure handle track


                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror XYZ')


                                    end
                                end
                            end

                            %% tern
                            [ax2plot,options_definition]=plot_tern_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));

                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end


                            if sum(which2plot)>0

                                for n=1:numel(which2plot)
                                    try

                                        %% test figure handle track
                                        if isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type) &&  isgraphics(app.MinPlotXFigures.hFig(find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}))).ax2plot)
                                            hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            ax2plot=app.MinPlotXFigures.hFig(hFig_id).ax2plot;
                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                        else
                                            if not(isfield(app.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotXFigures) || isempty(app.MinPlotXFigures.hFig_type) % or handle not valid
                                                hFig_id=1;
                                            elseif isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type)
                                                hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            else
                                                hFig_id=numel(app.MinPlotXFigures.hFig_type)+1;
                                            end

                                            hfig=figure;
                                            hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app,src, event);
                                            hfig.Name=options_definition.type.description{which2plot(n)};
                                            hfig.Position(3:4)=hfig.Position(3:4).*1.5;
                                            CenterFig_fun(hfig)
                                            ax2plot=nexttile;

                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                            [ax2plot,options_definition_]=plot_tern_fun(ax2plot,options);%#ok
                                            app.MinPlotXFigures.hFig(hFig_id).options_definition=[];
                                            app.MinPlotXFigures.hFig(hFig_id).options=[];
                                            hfig.Visible=true;

                                        end


                                        [ax2plot,options_definition,~]=plot_tern_data_fun(T,ax2plot,options);


                                        app.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;
                                        if numel(ax2plot.Children)>0

                                            if subdiv_unique_n>1
                                                ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                            else
                                                ax2plot.Children(1).DisplayName=app.MinPlotXData.SampleNames{app.active_entry};
                                            end

                                            scatter_obj=flipud(findobj('Parent',app.MinPlotXFigures.hFig(hFig_id).ax2plot,'Type','Scatter'));

                                            if not(isempty(scatter_obj))
                                                legend(ax2plot,scatter_obj.DisplayName,'Interpreter','none','Location','bestoutside')
                                            end
                                        end

                                        app.MinPlotXFigures.hFig(hFig_id).options_definition=options_definition;
                                        app.MinPlotXFigures.hFig(hFig_id).options=options;
                                        app.MinPlotXFigures.hFig_type{hFig_id}=options_definition.type.options{which2plot(n)};
                                        app.MinPlotXFigures.hFig_description{hFig_id}=options_definition.type.description{which2plot(n)};
                                        % end test figure handle track


                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror tern')

                                    end

                                end
                            end
                            %% binary

                            [ax2plot,options_definition]=plot_binary_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));

                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);

                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end


                            if sum(which2plot)>0

                                for n=1:numel(which2plot)
                                    try
                                        %% test figure handle track
                                        if isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type) &&  isgraphics(app.MinPlotXFigures.hFig(find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}))).ax2plot)
                                            hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            ax2plot=app.MinPlotXFigures.hFig(hFig_id).ax2plot;
                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                        else
                                            if not(isfield(app.MinPlotXFigures,'hFig_type')) || isempty(app.MinPlotXFigures) || isempty(app.MinPlotXFigures.hFig_type) % or handle not valid
                                                hFig_id=1;
                                            elseif isfield(app.MinPlotXFigures,'hFig_type') && not(isempty(app.MinPlotXFigures.hFig_type)) && ismember(options_definition.type.options{which2plot(n)},app.MinPlotXFigures.hFig_type)
                                                hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));
                                            else
                                                hFig_id=numel(app.MinPlotXFigures.hFig_type)+1;
                                            end


                                            hfig=figure;
                                            hfig.CloseRequestFcn = @(src, event) EasyPlotCloseRequestFcn(app,src, event);
                                            hfig.Name=options_definition.type.description{which2plot(n)};
                                            hfig.Position(3:4)=hfig.Position(3:4).*1.5;
                                            CenterFig_fun(hfig)

                                            ax2plot=nexttile;

                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                            [ax2plot,options_definition_]=plot_binary_fun(ax2plot,options);%#ok
                                            app.MinPlotXFigures.hFig(hFig_id).options_definition=[];
                                            app.MinPlotXFigures.hFig(hFig_id).options=[];

                                        end


                                        [ax2plot,options_definition_,~]=plot_binary_data_fun(T,ax2plot,options);%#ok

                                        app.MinPlotXFigures.hFig(hFig_id).ax2plot=ax2plot;
                                        if numel(ax2plot.Children)>0

                                            if subdiv_unique_n>1
                                                ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                            else
                                                ax2plot.Children(1).DisplayName=app.MinPlotXData.SampleNames{app.active_entry};
                                            end

                                            scatter_obj=flipud(findobj('Parent',app.MinPlotXFigures.hFig(hFig_id).ax2plot,'Type','Scatter'));

                                            if not(isempty(scatter_obj))
                                                legend(ax2plot,scatter_obj.DisplayName,'Interpreter','none','Location','bestoutside')
                                            end
                                        end
                                        app.MinPlotXFigures.hFig(hFig_id).options_definition=options_definition;
                                        app.MinPlotXFigures.hFig(hFig_id).options=options;
                                        app.MinPlotXFigures.hFig_type{hFig_id}=options_definition.type.options{which2plot(n)};
                                        app.MinPlotXFigures.hFig_description{hFig_id}=options_definition.type.description{which2plot(n)};

                                        % end test figure handle track


                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror binary')
                                    end
                                end
                            end
                            %                     %% stacked_binary
                            %                     [ax2plot,options_definition]=plot_stacked_binary_fun([],[]);
                            %
                            %
                            %                     options=options_definition;
                            %                 options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            %                 options.plot_settings.symbol=app.SymbolDropDown.Value;
                            %                 options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            %                 options.plot_state=app.PlotResultsCheckBox.Value;
                            %     which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));
                            %
                            %                     if sum(which2plot)>0
                            %
                            %                         for n=1:numel(which2plot)
                            %                             try
                            %                         hfig=figure;hfig.Position=get(groot,'ScreenSize');
                            %
                            %                                 options.type.Value=options_definition.type.options{which2plot(n)};
                            %                                 [ax2plot,options_definition_]=plot_stacked_binary_fun([],options);
                            %
                            %                                 [ax2plot,options_definition,legend_str]=plot_stacked_binary_data_fun(T,ax2plot,options);
                            %                             catch ME
                            %                                 DisplayErrorNG(app,ME,'ploterror stacked_binary')
                            %                             end
                            %                         end
                            %                     end
                            if subdiv_unique_n>1
                                if strcmp(app.SymbolDropDown.Value,'o')
                                    app.SymbolDropDown.Value='d';
                                elseif strcmp(app.SymbolDropDown.Value,'d')
                                    app.SymbolDropDown.Value='s';
                                elseif strcmp(app.SymbolDropDown.Value,'s')
                                    app.SymbolDropDown.Value='v';
                                elseif strcmp(app.SymbolDropDown.Value,'v')
                                    app.SymbolDropDown.Value='>';
                                elseif strcmp(app.SymbolDropDown.Value,'>')
                                    app.SymbolDropDown.Value='<';
                                elseif strcmp(app.SymbolDropDown.Value,'<')
                                    app.SymbolDropDown.Value='^';
                                else
                                    app.SymbolDropDown.Value='o';
                                end

                                if app.EasyPlotColormapMenu.Checked==false
                                    if find(ismember(app.ColorDropDown.ItemsData,app.ColorDropDown.Value))==8
                                        app.ColorDropDown.Value=app.ColorDropDown.ItemsData(1);
                                    else
                                        app.ColorDropDown.Value=app.ColorDropDown.ItemsData(1+find(ismember(app.ColorDropDown.ItemsData,app.ColorDropDown.Value)));
                                    end
                                end
                            end
                        end
                    catch ME
                        DisplayErrorNG(app,ME,'EasyPlot Error')

                    end

                end
            end
            EPM=EasyPlotModule(app);
            EPM.MinPlotXEasyPlot_public_update;
            %MinPlotXEasyPlot_public_update(EasyPlotModule)

            if strcmp(app.SymbolDropDown.Value,'o')
                app.SymbolDropDown.Value='d';
            elseif strcmp(app.SymbolDropDown.Value,'d')
                app.SymbolDropDown.Value='s';
            elseif strcmp(app.SymbolDropDown.Value,'s')
                app.SymbolDropDown.Value='v';
            elseif strcmp(app.SymbolDropDown.Value,'v')
                app.SymbolDropDown.Value='>';
            elseif strcmp(app.SymbolDropDown.Value,'>')
                app.SymbolDropDown.Value='<';
            elseif strcmp(app.SymbolDropDown.Value,'<')
                app.SymbolDropDown.Value='^';
            else
                app.SymbolDropDown.Value='o';
            end


            if find(ismember(app.ColorDropDown.ItemsData,app.ColorDropDown.Value))==8
                app.ColorDropDown.Value=app.ColorDropDown.ItemsData(1);
            else
                app.ColorDropDown.Value=app.ColorDropDown.ItemsData(1+find(ismember(app.ColorDropDown.ItemsData,app.ColorDropDown.Value)));
            end
            %        app.SymbolDropDown.Value=Symb_Ini;
            %             app.ColorDropDown.Value=Col_Ini;
        end

        % Button pushed function: QuickplotButton
        function QuickplotButtonPushed(app, event)
            if strcmp('MultiMineralData',app.MineralDropDown.Value)
                Min2Plot=unique(app.MinPlotXData.Sample(app.active_entry).Input.MetaTable.Mineral);
                if numel(Min2Plot)>3
                    answer = uiconfirm(app.MinPlotXGUI, ...
                        ['Are you sure you want to generate plots for ' num2str(numel(Min2Plot)) ' minerals?'],"Generate Plots", ...
                        "Icon","warning");

                    if strcmp(answer,'Cancel')
                        return
                    end
                end

            else
                Min2Plot={app.MineralDropDown.Value};
            end

            Symb_Ini=app.SymbolDropDown.Value;
            Col_Ini=app.ColorDropDown.Value;

            T_full=  get_compiled_table(app,app.active_entry);

            for M2P=1:numel(Min2Plot)

                plot_multi_XY=false;
                if not(isempty(app.active_entry))  && not( strcmp('unknown',Min2Plot{M2P}))
                    if not(isfield(app.MinPlotXData.Sample(app.active_entry),'Output')) || isempty(app.MinPlotXData.Sample(app.active_entry).Output)
                        CalculateButtonPushed(app)
                        T_full=  get_compiled_table(app,app.active_entry);
                    end

                    if true
                        T= T_full(strcmp(T_full.Mineral,Min2Plot{M2P}),:);
                    end

                    if app.EasyPlotSubdivideMenu.Checked && not(ismember(app.DivColDropDown.Value,T.Properties.VariableNames)) && not(strcmp(app.DivColDropDown.Value,'none'))
                        app.DivColDropDown.Value='none';
                    end

                    if app.EasyPlotColormapMenu.Checked && not(ismember(app.CbarVarDropDown.Value,T.Properties.VariableNames)) && not(strcmp(app.CbarVarDropDown.Value,'1:n'))
                        app.CbarVarDropDown.Value='1:n';
                        app.ColorbarLabelEditField.Value='1:n';
                    end


                    if app.EasyPlotSubdivideMenu.Checked && not(strcmp(app.DivColDropDown.Value,'none')) && ismember(app.DivColDropDown.Value,T.Properties.VariableNames)
                        subdiv_unique=unique(T.(char(app.DivColDropDown.Value)));
                        subdiv_unique_n=numel(subdiv_unique);
                    else
                        subdiv_unique_n=1;
                    end


                    for subdiv_n=1:subdiv_unique_n
                        try
                            if true
                                T= T_full(strcmp(T_full.Mineral,Min2Plot{M2P}),:);
                            end

                            if subdiv_unique_n>1 && not(strcmp(app.DivColDropDown.Value,'none'))
                                col_tmp= T.(char(app.DivColDropDown.Value));
                                %   T=T(ismember(col_tmp,subdiv_unique(subdiv_n)),ismember(T.Properties.VariableNames,app.DivColDropDown.Value));
                                T=T(ismember(col_tmp,subdiv_unique(subdiv_n)),:);
                            end

                            %% xy
                            if subdiv_n==1
                                hfig=figure;
                                hfig.Position=get(groot,'ScreenSize');
                                hfig.Name=Min2Plot{M2P};

                                t=tiledlayout('flow');
                                t.TileSpacing='compact';
                            end

                            % t.Units='points';
                            % t.OuterPosition=[0,0,670,250]*0.7;

                            [ax2plot,options_definition]=plot_XY_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));
                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end
                            if sum(which2plot)>0
                                %  hfig=figure;hfig.Position=get(groot,'ScreenSize');

                                for n=1:numel(which2plot)
                                    try

                                        % hFig_id=find(strcmp(app.MinPlotXFigures.hFig_type,options_definition.type.options{which2plot(n)}));

                                        %  options_definition.type.options{which2plot(n)};

                                        ax2check=hfig.Children(1).Children(strcmp(get(hfig.Children(1).Children(:), 'type'), 'axes'));

                                        if numel([hfig.Children(1).Children])<1 ||isempty(ax2check) || ~any(strcmp({ax2check.Tag},options_definition.type.options{which2plot(n)}))
                                            ax2plot=nexttile;
                                        else
                                            ax2plot=ax2check(strcmp({ax2check.Tag},options_definition.type.options{which2plot(n)}));
                                            options.plot_tern.Value=false;
                                        end

                                        options.type.Value=options_definition.type.options{which2plot(n)};
                                        if subdiv_n==1
                                            [ax2plot,options_definition_]=plot_XY_fun(ax2plot,options);%#ok
                                        end
                                        [ax2plot,options_definition,legend_str]=plot_XY_data_fun(T,ax2plot,options);%#ok
                                        ax2plot.Tag=options_definition.type.options{which2plot(n)};
                                        if numel(ax2plot.Children)>0&&subdiv_unique_n>1
                                            ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                        end

                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror XY')


                                    end
                                end
                            end
                            %% tern
                            [ax2plot,options_definition]=plot_tern_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));
                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end
                            if sum(which2plot)>0
                                %                         hfig=figure;
                                %                         hfig.Position=get(groot,'ScreenSize');

                                for n=1:numel(which2plot)
                                    try
                                        %x
                                        ax2check=hfig.Children(1).Children(strcmp(get(hfig.Children(1).Children(:), 'type'), 'axes'));

                                        if numel([hfig.Children(1).Children])<1 ||isempty(ax2check) || ~any(strcmp({ax2check.Tag},options_definition.type.options{which2plot(n)}))
                                            ax2plot=nexttile;
                                        else
                                            ax2plot=ax2check(strcmp({ax2check.Tag},options_definition.type.options{which2plot(n)}));
                                            options.plot_tern.Value=false;
                                        end

                                        options.type.Value=options_definition.type.options{which2plot(n)};
                                        if subdiv_n==1
                                            [ax2plot,options_definition_]=plot_tern_fun(ax2plot,options);%#ok
                                        end

                                        [ax2plot,options_definition,legend_str]=plot_tern_data_fun(T,ax2plot,options);%#ok
                                        ax2plot.Tag=options_definition.type.options{which2plot(n)};
                                        if numel(ax2plot.Children)>0&&subdiv_unique_n>1
                                            ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                        end


                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror tern')

                                    end
                                end
                            end

                            %% binary
                            [ax2plot,options_definition]=plot_binary_fun([],[]);%#ok

                            options=options_definition;
                            options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                            options.plot_settings.symbol=app.SymbolDropDown.Value;
                            options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                            options.plot_state=app.PlotResultsCheckBox.Value;
                            which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));
                            if app.EasyPlotColormapMenu.Checked==true
                                options.ColorData.Value=true;
                                options.ColorData.colormap=PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                                if strcmp(app.CbarVarDropDown.Value,'1:n')
                                    options.C1='1:n';
                                    options.ColorData.cbar_label="1:n";
                                else
                                    options.C1=app.CbarVarDropDown.Value;
                                    options.ColorData.cbar_label=app.ColorbarLabelEditField.Value;

                                end
                            end
                            if sum(which2plot)>0
                                % hfig=figure;hfig.Position=get(groot,'ScreenSize');

                                for n=1:numel(which2plot)
                                    try
                                        ax2check=hfig.Children(1).Children(strcmp(get(hfig.Children(1).Children(:), 'type'), 'axes'));

                                        if numel([hfig.Children(1).Children])<1 ||isempty(ax2check) || ~any(strcmp({ax2check.Tag},options_definition.type.options{which2plot(n)}))
                                            ax2plot=nexttile;
                                        else
                                            ax2plot=ax2check(strcmp({ax2check.Tag},options_definition.type.options{which2plot(n)}));
                                            options.plot_tern.Value=false;
                                        end

                                        options.type.Value=options_definition.type.options{which2plot(n)};
                                        if subdiv_n==1
                                            [ax2plot,options_definition_]=plot_binary_fun(ax2plot,options);%#ok
                                        end
                                        [ax2plot,options_definition,legend_str]=plot_binary_data_fun(T,ax2plot,options);%#ok
                                        ax2plot.Tag=options_definition.type.options{which2plot(n)};
                                        if numel(ax2plot.Children)>0&&subdiv_unique_n>1
                                            ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                        end

                                    catch ME
                                        DisplayErrorNG(app,ME,'ploterror binary')
                                    end
                                end
                            end

                            if plot_multi_XY==true
                                %% multi xy
                                [ax2plot,options_definition]=plot_multi_XY_fun([],[]);

                                options=options_definition;
                                options.plot_settings.MarkerFaceColor=str2num(app.ColorDropDown.Value); %#ok
                                options.plot_settings.symbol=app.SymbolDropDown.Value;
                                options.plot_settings.symbol_size=app.MarkerSizeEditField.Value;
                                options.plot_state=app.PlotResultsCheckBox.Value;
                                which2plot=find(startsWith(options_definition.type.description,Min2Plot{M2P}));

                                if sum(which2plot)>0

                                    for n=1:numel(which2plot)
                                        try

                                            options.type.Value=options_definition.type.options{which2plot(n)};
                                            if subdiv_n==1
                                                [ax2plot,options_definition_]=plot_multi_XY_fun([],options);%#ok
                                            end
                                            [ax2plot,options_definition,~]=plot_multi_XY_data_fun(T,ax2plot,options);
                                            ax2plot.Tag=options_definition.type.options{which2plot(n)};
                                            %  ax2plot(1).Parent(1).Parent(1).Position=get(groot,'ScreenSize');
                                            if numel(ax2plot.Children)>0&&subdiv_unique_n>1
                                                ax2plot.Children(1).DisplayName=[app.MinPlotXData.SampleNames{app.active_entry} '_' subdiv_unique{subdiv_n}];
                                            end

                                        catch ME
                                            DisplayErrorNG(app,ME,'ploterror multi xy')
                                        end
                                    end
                                end

                            end

                            if subdiv_unique_n>1
                                if strcmp(app.SymbolDropDown.Value,'o')
                                    app.SymbolDropDown.Value='d';
                                elseif strcmp(app.SymbolDropDown.Value,'d')
                                    app.SymbolDropDown.Value='s';
                                elseif strcmp(app.SymbolDropDown.Value,'s')
                                    app.SymbolDropDown.Value='v';
                                elseif strcmp(app.SymbolDropDown.Value,'v')
                                    app.SymbolDropDown.Value='>';
                                elseif strcmp(app.SymbolDropDown.Value,'>')
                                    app.SymbolDropDown.Value='<';
                                elseif strcmp(app.SymbolDropDown.Value,'<')
                                    app.SymbolDropDown.Value='^';
                                else
                                    app.SymbolDropDown.Value='o';
                                end

                                if app.EasyPlotColormapMenu.Checked==false
                                    if find(ismember(app.ColorDropDown.ItemsData,app.ColorDropDown.Value))==8
                                        app.ColorDropDown.Value=app.ColorDropDown.ItemsData(1);
                                    else
                                        app.ColorDropDown.Value=app.ColorDropDown.ItemsData(1+find(ismember(app.ColorDropDown.ItemsData,app.ColorDropDown.Value)));
                                    end
                                end
                            end

                        catch ME
                            DisplayErrorNG(app,ME,'ploterror ')

                        end
                    end


                end
            end

            if exist('subdiv_unique_n','var') && subdiv_unique_n>1

                legend_ax2plot=[];
                legend_scatter_obj=[];

                for n=1:numel([hfig.Children(1).Children])
                    scatter_obj=flipud(findobj('Parent',[hfig.Children(1).Children(n)],'Type','Scatter'));%#ok
                    if not(isempty(scatter_obj))

                        legend_ax2plot{end+1}=hfig.Children(1).Children(n);%#ok
                        legend_scatter_obj{end+1}=scatter_obj;%#ok
                    end

                end
                for k=1:numel(legend_ax2plot)

                    legend(legend_ax2plot{k},legend_scatter_obj{k},'Interpreter','none','Location','bestoutside')

                end
            end

            hfig.Visible=true;

            hFigs = get(0, 'Children');
            for n=1:numel(hFigs)
                try
                    if isempty(hFigs(n).Children.Children)
                        delete(hFigs(n));
                    end
                catch
                end
                try
                    if isempty(hFigs(n).Children)
                        delete(hFigs(n));
                    end
                catch
                end

            end

            app.SymbolDropDown.Value=Symb_Ini;
            app.ColorDropDown.Value=Col_Ini;
        end

        % Value changed function: CalculatewithFe3CheckBox
        function CalculatewithFe3CheckBoxValueChanged(app, event)
            value = app.CalculatewithFe3CheckBox.Value;
            if value==false
                app.EstimateFe3fromstoichiometryCheckBox.Value=false;
                app.UseFe3FeTotalRatioFromTableCheckBox.Value=false;
                app.UseGlobalFe3FeTotalRatioCheckBox.Value=false;
                app.UseKnownFe3tetraFromTableCheckBox.Value=false;
                app.UseGlobalFe3tetraCheckBox.Value=false;
                UseGlobalFe3FeTotalRatioCheckBoxValueChanged(app)
                UseGlobalFe3tetraCheckBoxValueChanged(app)

            elseif  app.EstimateFe3fromstoichiometryCheckBox.Visible==true && app.EstimateFe3fromstoichiometryCheckBox.Value==false && app.UseFe3FeTotalRatioFromTableCheckBox.Value==false&& app.UseGlobalFe3FeTotalRatioCheckBox.Value==false
                app.EstimateFe3fromstoichiometryCheckBox.Value=true;
            elseif  app.EstimateFe3fromstoichiometryCheckBox.Visible==false && app.EstimateFe3fromstoichiometryCheckBox.Value==false && app.UseFe3FeTotalRatioFromTableCheckBox.Value==false&& app.UseGlobalFe3FeTotalRatioCheckBox.Value==false

                app.UseFe3FeTotalRatioFromTableCheckBox.Value=true;
                %  app.UseGlobalFe3FeTotalRatioCheckBox.Value=true;
                % app.Fe3FeTotalEditField.Value=0
            end
            update_MineralSpecificOptions(app)
        end

        % Value changed function: UseGlobalMn3MnTotalRatioCheckBox
        function UseGlobalMn3MnTotalRatioCheckBoxValueChanged(app, event)
            if app.UseGlobalMn3MnTotalRatioCheckBox.Value==true

                app.Mn3MnTotalEditField.Visible=true;
                app.Mn3MnTotalEditFieldLabel.Visible=true;
                app.CalculatewithMn3CheckBox.Value=true;
                app.UseMn3MnTotalRatioFromTableCheckBox.Value=false;
            elseif  app.UseMn3MnTotalRatioFromTableCheckBox.Value==false && app.CalculatewithMn3CheckBox.Value==true
                app.UseMn3MnTotalRatioFromTableCheckBox.Value=true;
                app.Mn3MnTotalEditField.Visible=false;
                app.Mn3MnTotalEditFieldLabel.Visible=false;
            else
                app.Mn3MnTotalEditField.Visible=false;
                app.Mn3MnTotalEditFieldLabel.Visible=false;
            end
            update_MineralSpecificOptions(app)
        end

        % Cell edit callback: MineralSpecificOptionsTable
        function MineralSpecificOptionsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            MineralName=app.MineralDropDown.Value;
            %ATH MultiMineralData

            [~,~,options]=MinPlotX_fun([],[], app.MineralCalcFunctions.(char(MineralName))(1),[],[]);

            if isfield(options,app.MineralSpecificOptionsTable.Data((event.Indices(1)),1)) && isfield(options.(char(app.MineralSpecificOptionsTable.Data((event.Indices(1)),1))),'limits')
                if  newData<min(options.(char(app.MineralSpecificOptionsTable.Data((event.Indices(1)),1))).limits) || newData>max(options.(char(app.MineralSpecificOptionsTable.Data((event.Indices(1)),1))).limits) || not(isnumeric(newData)) || not(isfinite(newData))
                    message=['Value outside of allowed Values [' num2str(min(options.(char(app.MineralSpecificOptionsTable.Data((event.Indices(1)),1))).limits)) ' - ' num2str(max(options.(char(app.MineralSpecificOptionsTable.Data((event.Indices(1)),1))).limits)) ']'];
                    uialert(app.MinPlotXGUI,message,' Error');
                    update_MineralSpecificOptionsTable(app)
                    return
                end
            end
            app.MinPlotXData.Sample(app.active_entry).SingleCalcSettings.(char(MineralName)).(char(app.MineralSpecificOptionsTable.Data{indices(1),1})).Value=app.MineralSpecificOptionsTable.Data{indices(1),indices(2)};

        end

        % Value changed function: Fe3FeTotalEditField, Fe3TetraEditField, 
        % Mn3MnTotalEditField
        function Fe3FeTotalEditFieldValueChanged(app, event)
            update_MineralSpecificOptions(app)

        end

        % Value changed function: CalculatewithMn3CheckBox
        function CalculatewithMn3CheckBoxValueChanged(app, event)
            value = app.CalculatewithMn3CheckBox.Value;
            if value==false

                app.UseMn3MnTotalRatioFromTableCheckBox.Value=false;
                app.UseGlobalMn3MnTotalRatioCheckBox.Value=false;
                UseGlobalMn3MnTotalRatioCheckBoxValueChanged(app)
            elseif  app.UseMn3MnTotalRatioFromTableCheckBox.Value==false&& app.UseGlobalMn3MnTotalRatioCheckBox.Value==false
                app.UseMn3MnTotalRatioFromTableCheckBox.Value=true;
            end
            update_MineralSpecificOptions(app)
        end

        % Value changed function: UseMn3MnTotalRatioFromTableCheckBox
        function UseMn3MnTotalRatioFromTableCheckBoxValueChanged(app, event)
            if app.UseMn3MnTotalRatioFromTableCheckBox.Value==true
                app.CalculatewithMn3CheckBox.Value=true;
                app.UseGlobalMn3MnTotalRatioCheckBox.Value=false;
            elseif app.UseGlobalMn3MnTotalRatioCheckBox.Value==false
                app.Mn3MnTotalEditField.Visible=false;
                app.Mn3MnTotalEditFieldLabel.Visible=false;
                app.CalculatewithMn3CheckBox.Value=false;
            elseif app.UseGlobalMn3MnTotalRatioCheckBox==true
                app.UseMn3MnTotalRatioFromTableCheckBox.Value=false;
                app.CalculatewithMn3CheckBox.Value=true;
            end
            UseGlobalMn3MnTotalRatioCheckBoxValueChanged(app)

            update_MineralSpecificOptions(app)
        end

        % Button pushed function: VisModuleButton
        function VisModuleButtonPushed(app, event)
            VisModule(app)
        end

        % Button pushed function: EasyPlotModuleButton
        function EasyPlotModuleButtonPushed(app, event)
            callEasyPlotModule(app)
        end

        % Value changed function: Button_3
        function FilterButtonValueChanged(app, event)
            value = app.Button_3.Value;

            if value==true
                app.SamplesListBox.Layout.Row=[7 8];
                app.FilterStringEditField.Value='';
                app.Button_3.Icon='symbols_1_Filter_on.png';
            else
                app.SamplesListBox.Layout.Row=[6 8];
                app.Button_3.Icon='symbols_1_Filter.png';
            end

            app.FilterStringEditField.Visible=value;
            app.FilterStringEditFieldLabel.Visible=value;

            update_GUI(app)
        end

        % Value changed function: FilterStringEditField
        function FilterStringEditFieldValueChanged(app, event)
            update_GUI(app)
        end

        % Menu selected function: EasyPlotColormapMenu
        function EasyPlotColormapMenuSelected(app, event)
            value=app.EasyPlotColormapMenu.Checked;
            app.GridLayout23.Visible=not(value);
            app.Panel.Visible=not(value);
            app.EasyPlotColormapMenu.Checked=not(value);

        end

        % Drop down opening function: CbarVarDropDown
        function CbarVarDropDownOpening(app, event)

            T=get_compiled_table(app,app.active_entry);
            if isempty(T)
                return
            end

            app.CbarVarDropDown.Items=[{'1:n'}; T.Properties.VariableNames((any(cellfun(@isnumeric,table2cell(T)))|any(cellfun(@islogical,table2cell(T)))))'];

        end

        % Value changed function: CbarVarDropDown
        function CbarVarDropDownValueChanged(app, event)
            app.ColorbarLabelEditField.Value=replace(app.CbarVarDropDown.Value,'_',' ');
        end

        % Drop down opening function: DivColDropDown
        function DivColDropDownOpening(app, event)
            T=get_compiled_table(app,app.active_entry);
            if isempty(T)
                return
            end

            app.DivColDropDown.Items=[{'none'}; T.Properties.VariableNames((any(cellfun(@ischar,table2cell(T)))|any(cellfun(@islogical,table2cell(T)))))'];

        end

        % Menu selected function: EasyPlotSubdivideMenu
        function EasyPlotSubdivideMenuSelected(app, event)
            value=app.EasyPlotSubdivideMenu.Checked;

            app.DivColDropDownLabel.Visible=not(value);
            app.DivColDropDown.Visible=not(value);
            app.EasyPlotSubdivideMenu.Checked=not(value);
            app.DivColDropDown.Value='none';
        end

        % Value changed function: Options_ColormapDropDown, 
        % Options_ColormapResEditField, Options_InvertColormap, 
        % Options_LowerColor, Options_UpperCheckBox, Options_UpperColor
        function Options_ColormapDropDownValueChanged(app, event)
            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
        end

        % Menu selected function: CheckVersionMenu
        function CheckVersionMenuSelected(app, event)
            check_for_update_fun(app,1)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MinPlotXGUI = uifigure('Visible', 'off');
            app.MinPlotXGUI.Name = 'MinPlotX';
            app.MinPlotXGUI.Icon = 'MinPlotX_logo.png';
            app.MinPlotXGUI.CloseRequestFcn = createCallbackFcn(app, @MinPlotXGUICloseRequest, true);
            app.MinPlotXMenu = uimenu(app.MinPlotXGUI);
            app.MinPlotXMenu.Text = 'MinPlotX';
            app.NewMinPlotXProjectMenu = uimenu(app.MinPlotXMenu);
            app.NewMinPlotXProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMinPlotXProjectMenuSelected, true);
            app.NewMinPlotXProjectMenu.Text = 'New MinPlotX Project';
            app.LoadMinPlotXProjectMenu = uimenu(app.MinPlotXMenu);
            app.LoadMinPlotXProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMinPlotXProjectMenuSelected, true);
            app.LoadMinPlotXProjectMenu.Text = 'Load MinPlotX Project';
            app.SaveMinPlotXProjectMenu = uimenu(app.MinPlotXMenu);
            app.SaveMinPlotXProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMinPlotXProjectMenuSelected, true);
            app.SaveMinPlotXProjectMenu.Text = 'Save MinPlotX Project';
            app.SaveMinPlotXProjectAsMenu = uimenu(app.MinPlotXMenu);
            app.SaveMinPlotXProjectAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMinPlotXProjectAsMenuSelected, true);
            app.SaveMinPlotXProjectAsMenu.Text = 'Save MinPlotX Project As';
            app.CheckVersionMenu = uimenu(app.MinPlotXMenu);
            app.CheckVersionMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckVersionMenuSelected, true);
            app.CheckVersionMenu.Text = 'Check Version';
            app.ExportResultsMenu = uimenu(app.MinPlotXMenu);
            app.ExportResultsMenu.Text = 'Export Results';
            app.CustomExportMenu = uimenu(app.ExportResultsMenu);
            app.CustomExportMenu.MenuSelectedFcn = createCallbackFcn(app, @CustomExportMenuSelected, true);
            app.CustomExportMenu.Text = 'Custom Export';
            app.ExportapfuResultsTableMenu = uimenu(app.ExportResultsMenu);
            app.ExportapfuResultsTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportapfuResultsTableMenuSelected, true);
            app.ExportapfuResultsTableMenu.Text = 'Export apfu Results Table';
            app.ExportStrucFMResultsTableMenu = uimenu(app.ExportResultsMenu);
            app.ExportStrucFMResultsTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportStrucFMResultsTableMenuSelected, true);
            app.ExportStrucFMResultsTableMenu.Text = 'Export StrucFM Results Table';
            app.ClearMinPlotXProjectMenu = uimenu(app.MinPlotXMenu);
            app.ClearMinPlotXProjectMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMinPlotXProjectMenuSelected, true);
            app.ClearMinPlotXProjectMenu.Text = 'Clear MinPlotX Project';
            app.ImportMenu = uimenu(app.MinPlotXGUI);
            app.ImportMenu.Text = 'Import';
            app.ImportfilesMenu = uimenu(app.ImportMenu);
            app.ImportfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfilesButtonPushed, true);
            app.ImportfilesMenu.Text = 'Import (files)';
            app.ImportfolderMenu = uimenu(app.ImportMenu);
            app.ImportfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportfolderButtonPushed, true);
            app.ImportfolderMenu.Text = 'Import (folder)';
            app.AutoimportMenu = uimenu(app.ImportMenu);
            app.AutoimportMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoimportMenuSelected, true);
            app.AutoimportMenu.Text = 'Autoimport';
            app.AdvancedMenu = uimenu(app.MinPlotXGUI);
            app.AdvancedMenu.Text = 'Advanced';
            app.MergeTablesMenu = uimenu(app.AdvancedMenu);
            app.MergeTablesMenu.MenuSelectedFcn = createCallbackFcn(app, @MergeTablesMenuSelected, true);
            app.MergeTablesMenu.Text = 'Merge Tables';
            app.CalculateTotalfromDataMenu = uimenu(app.AdvancedMenu);
            app.CalculateTotalfromDataMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateTotalfromDataMenuSelected, true);
            app.CalculateTotalfromDataMenu.Text = 'Calculate Total from Data';
            app.DataCalculatorModuleMenu = uimenu(app.AdvancedMenu);
            app.DataCalculatorModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @DataCalculatorModuleMenuSelected, true);
            app.DataCalculatorModuleMenu.Text = 'DataCalculatorModule';
            app.AdvancedPlotModuleMenu = uimenu(app.AdvancedMenu);
            app.AdvancedPlotModuleMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsModuleButtonPushed, true);
            app.AdvancedPlotModuleMenu.Text = 'AdvancedPlotModule';
            app.EasyPlotColormapMenu = uimenu(app.AdvancedMenu);
            app.EasyPlotColormapMenu.MenuSelectedFcn = createCallbackFcn(app, @EasyPlotColormapMenuSelected, true);
            app.EasyPlotColormapMenu.Text = 'Easy Plot Colormap';
            app.EasyPlotSubdivideMenu = uimenu(app.AdvancedMenu);
            app.EasyPlotSubdivideMenu.MenuSelectedFcn = createCallbackFcn(app, @EasyPlotSubdivideMenuSelected, true);
            app.EasyPlotSubdivideMenu.Text = 'Easy Plot Subdivide';
            app.DocumentationMenu = uimenu(app.MinPlotXGUI);
            app.DocumentationMenu.Text = 'Documentation';
            app.OpenDocumentationMenu = uimenu(app.DocumentationMenu);
            app.OpenDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDocumentationMenuSelected, true);
            app.OpenDocumentationMenu.Text = 'Open Documentation';
            app.InputOutputDocumentationMenu = uimenu(app.DocumentationMenu);
            app.InputOutputDocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @InputOutputDocumentationMenuSelected, true);
            app.InputOutputDocumentationMenu.Text = 'Input/Output Documentation';
            app.GridLayout10 = uigridlayout(app.MinPlotXGUI);
            app.TabGroup3 = uitabgroup(app.GridLayout10);
            app.MainTab = uitab(app.TabGroup3);
            app.MainTab.Title = 'Main';
            app.GridLayout = uigridlayout(app.MainTab);
            app.OutputTable = uitable(app.GridLayout);
            app.OutputTable.ColumnName = '';
            app.OutputTable.RowName = {};
            app.CalculateButton = uibutton(app.GridLayout, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.SelectDirButton = uibutton(app.GridLayout, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'apfu', 'Structural Formula'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'apfu';
            app.CalculateallButton = uibutton(app.GridLayout, 'push');
            app.CalculateallButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateallButtonPushed, true);
            app.CalculateallButton.Text = 'Calculate all';
            app.WorkingDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.WorkingDirectoryEditFieldLabel.Text = 'Working Directory';
            app.WorkingDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.WorkingDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryEditFieldValueChanged, true);
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.ChemistryTab = uitab(app.TabGroup2);
            app.ChemistryTab.Title = 'Chemistry';
            app.GridLayout5 = uigridlayout(app.ChemistryTab);
            app.InputTable = uitable(app.GridLayout5);
            app.InputTable.ColumnName = '';
            app.InputTable.RowName = {};
            app.MetaTableTab = uitab(app.TabGroup2);
            app.MetaTableTab.Title = 'MetaTable';
            app.GridLayout6 = uigridlayout(app.MetaTableTab);
            app.MetaTable = uitable(app.GridLayout6);
            app.MetaTable.ColumnName = '';
            app.MetaTable.RowName = {};
            app.EasyPlotButton = uibutton(app.GridLayout, 'push');
            app.EasyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @EasyPlotButtonPushed, true);
            app.EasyPlotButton.Text = 'Easy Plot';
            app.QuickplotButton = uibutton(app.GridLayout, 'push');
            app.QuickplotButton.ButtonPushedFcn = createCallbackFcn(app, @QuickplotButtonPushed, true);
            app.QuickplotButton.Text = 'Quickplot';
            app.ExportModuleButton = uibutton(app.GridLayout, 'push');
            app.ExportModuleButton.ButtonPushedFcn = createCallbackFcn(app, @CustomExportMenuSelected, true);
            app.ExportModuleButton.Text = 'ExportModule';
            app.FeOptionsPanel = uipanel(app.GridLayout);
            app.FeOptionsPanel.Title = 'Fe Options';
            app.FeOptionsPanel.Visible = 'off';
            app.GridLayout18 = uigridlayout(app.FeOptionsPanel);
            app.Fe3TetraEditField = uieditfield(app.GridLayout18, 'numeric');
            app.Fe3TetraEditField.Limits = [0 1];
            app.Fe3TetraEditField.ValueChangedFcn = createCallbackFcn(app, @Fe3FeTotalEditFieldValueChanged, true);
            app.Fe3TetraEditField.Visible = 'off';
            app.Fe3TetraEditFieldLabel = uilabel(app.GridLayout18);
            app.Fe3TetraEditFieldLabel.Visible = 'off';
            app.Fe3TetraEditFieldLabel.Text = 'Fe3+ Tetra';
            app.UseKnownFe3tetraFromTableCheckBox = uicheckbox(app.GridLayout18);
            app.UseKnownFe3tetraFromTableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseKnownFe3tetraFromTableCheckBoxValueChanged, true);
            app.UseKnownFe3tetraFromTableCheckBox.Visible = 'off';
            app.UseKnownFe3tetraFromTableCheckBox.Text = 'Use Known Fe3 tetra (From Table)';
            app.UseFe3FeTotalRatioFromTableCheckBox = uicheckbox(app.GridLayout18);
            app.UseFe3FeTotalRatioFromTableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFe3FeTotalRatioFromTableCheckBoxValueChanged, true);
            app.UseFe3FeTotalRatioFromTableCheckBox.Visible = 'off';
            app.UseFe3FeTotalRatioFromTableCheckBox.Text = 'Use Fe3/FeTotal Ratio (From Table)';
            app.UseGlobalFe3tetraCheckBox = uicheckbox(app.GridLayout18);
            app.UseGlobalFe3tetraCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGlobalFe3tetraCheckBoxValueChanged, true);
            app.UseGlobalFe3tetraCheckBox.Visible = 'off';
            app.UseGlobalFe3tetraCheckBox.Text = 'Use Global Fe3 tetra';
            app.UseGlobalFe3FeTotalRatioCheckBox = uicheckbox(app.GridLayout18);
            app.UseGlobalFe3FeTotalRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGlobalFe3FeTotalRatioCheckBoxValueChanged, true);
            app.UseGlobalFe3FeTotalRatioCheckBox.Visible = 'off';
            app.UseGlobalFe3FeTotalRatioCheckBox.Text = 'Use Global Fe3/FeTotal Ratio';
            app.EstimateFe3fromstoichiometryCheckBox = uicheckbox(app.GridLayout18);
            app.EstimateFe3fromstoichiometryCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateFe3fromstoichiometryCheckBoxValueChanged, true);
            app.EstimateFe3fromstoichiometryCheckBox.Visible = 'off';
            app.EstimateFe3fromstoichiometryCheckBox.Text = 'Estimate Fe3+ from stoichiometry?';
            app.CalculatewithFe3CheckBox = uicheckbox(app.GridLayout18);
            app.CalculatewithFe3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatewithFe3CheckBoxValueChanged, true);
            app.CalculatewithFe3CheckBox.Text = 'Calculate with Fe3+';
            app.Fe3FeTotalEditFieldLabel = uilabel(app.GridLayout18);
            app.Fe3FeTotalEditFieldLabel.Visible = 'off';
            app.Fe3FeTotalEditFieldLabel.Text = 'Fe3/FeTotal';
            app.Fe3FeTotalEditField = uieditfield(app.GridLayout18, 'numeric');
            app.Fe3FeTotalEditField.Limits = [0 1];
            app.Fe3FeTotalEditField.ValueChangedFcn = createCallbackFcn(app, @Fe3FeTotalEditFieldValueChanged, true);
            app.Fe3FeTotalEditField.Visible = 'off';
            app.MnOptionsPanel = uipanel(app.GridLayout);
            app.MnOptionsPanel.Title = 'Mn Options';
            app.MnOptionsPanel.Visible = 'off';
            app.GridLayout19 = uigridlayout(app.MnOptionsPanel);
            app.UseGlobalMn3MnTotalRatioCheckBox = uicheckbox(app.GridLayout19);
            app.UseGlobalMn3MnTotalRatioCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseGlobalMn3MnTotalRatioCheckBoxValueChanged, true);
            app.UseGlobalMn3MnTotalRatioCheckBox.Visible = 'off';
            app.UseGlobalMn3MnTotalRatioCheckBox.Text = 'Use Global Mn3/MnTotal Ratio';
            app.UseMn3MnTotalRatioFromTableCheckBox = uicheckbox(app.GridLayout19);
            app.UseMn3MnTotalRatioFromTableCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseMn3MnTotalRatioFromTableCheckBoxValueChanged, true);
            app.UseMn3MnTotalRatioFromTableCheckBox.Visible = 'off';
            app.UseMn3MnTotalRatioFromTableCheckBox.Text = 'Use Mn3/MnTotal Ratio  (From Table)';
            app.CalculatewithMn3CheckBox = uicheckbox(app.GridLayout19);
            app.CalculatewithMn3CheckBox.ValueChangedFcn = createCallbackFcn(app, @CalculatewithMn3CheckBoxValueChanged, true);
            app.CalculatewithMn3CheckBox.Text = 'Calculate with Mn3+';
            app.Mn3MnTotalEditFieldLabel = uilabel(app.GridLayout19);
            app.Mn3MnTotalEditFieldLabel.Visible = 'off';
            app.Mn3MnTotalEditFieldLabel.Text = 'Mn3/MnTotal';
            app.Mn3MnTotalEditField = uieditfield(app.GridLayout19, 'numeric');
            app.Mn3MnTotalEditField.Limits = [0 1];
            app.Mn3MnTotalEditField.ValueChangedFcn = createCallbackFcn(app, @Fe3FeTotalEditFieldValueChanged, true);
            app.Mn3MnTotalEditField.Visible = 'off';
            app.MineralSpecificOptionsPanel = uipanel(app.GridLayout);
            app.MineralSpecificOptionsPanel.Title = 'MineralSpecificOptions';
            app.MineralSpecificOptionsPanel.Visible = 'off';
            app.GridLayout20 = uigridlayout(app.MineralSpecificOptionsPanel);
            app.MineralSpecificOptionsTable = uitable(app.GridLayout20);
            app.MineralSpecificOptionsTable.ColumnName = {'Option'; 'Value'};
            app.MineralSpecificOptionsTable.RowName = {};
            app.MineralSpecificOptionsTable.ColumnEditable = [false true];
            app.MineralSpecificOptionsTable.CellEditCallback = createCallbackFcn(app, @MineralSpecificOptionsTableCellEdit, true);
            app.SaveAdvancedResultsCheckBox = uicheckbox(app.GridLayout);
            app.SaveAdvancedResultsCheckBox.Tooltip = {'The output files will be saved in the default working directory'};
            app.SaveAdvancedResultsCheckBox.Text = 'Save Advanced Results';
            app.SwitchtoMultiCalcButton = uibutton(app.GridLayout, 'push');
            app.SwitchtoMultiCalcButton.ButtonPushedFcn = createCallbackFcn(app, @SwitchtoMultiCalcButtonPushed, true);
            app.SwitchtoMultiCalcButton.Visible = 'off';
            app.SwitchtoMultiCalcButton.Text = 'Switch to MultiCalc';
            app.GridLayout22 = uigridlayout(app.GridLayout);
            app.ColorDropDownLabel = uilabel(app.GridLayout22);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.GridLayout22);
            app.ColorDropDown.Items = {'blue', 'orange', 'yellow', 'purple', 'green', 'cyan', 'red', 'black'};
            app.ColorDropDown.ItemsData = {'[0 0.4470 0.7410]', '[0.8500 0.3250 0.0980]', '[0.9290 0.6940 0.1250]', '[0.4940 0.1840 0.5560]', '[0.4660 0.6740 0.1880]', '[0.3010 0.7450 0.9330]', '[0.6350 0.0780 0.1840]', '[0 0 0]'};
            app.ColorDropDown.Value = '[0 0.4470 0.7410]';
            app.SymbolDropDownLabel = uilabel(app.GridLayout22);
            app.SymbolDropDownLabel.Text = 'Symbol';
            app.SymbolDropDown = uidropdown(app.GridLayout22);
            app.SymbolDropDown.Items = {'Circle', 'Plus sign', 'Asterisk', 'Point', 'Cross', 'Horizontal line', 'Vertical line', 'Square', 'Diamond', 'Upward-pointing triangle', 'Downward-pointing triangle', 'Right-pointing triangle', 'Left-pointing triangle', 'Pentagram', 'Hexagram'};
            app.SymbolDropDown.ItemsData = {'o', '+', '*', '.', 'x', '_', '|', 's', 'd', '^', 'v', '>', '<', 'p', 'h', 'none'};
            app.SymbolDropDown.Value = 'o';
            app.MarkerSizeEditField = uieditfield(app.GridLayout22, 'numeric');
            app.MarkerSizeEditField.Value = 200;
            app.DivColDropDownLabel = uilabel(app.GridLayout22);
            app.DivColDropDownLabel.Visible = 'off';
            app.DivColDropDownLabel.Text = 'DivCol';
            app.DivColDropDown = uidropdown(app.GridLayout22);
            app.DivColDropDown.Items = {'none'};
            app.DivColDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DivColDropDownOpening, true);
            app.DivColDropDown.Visible = 'off';
            app.DivColDropDown.Value = 'none';
            app.GridLayout23 = uigridlayout(app.GridLayout);
            app.GridLayout23.Visible = 'off';
            app.CbarVarDropDownLabel = uilabel(app.GridLayout23);
            app.CbarVarDropDownLabel.Text = 'Cbar Var';
            app.CbarVarDropDown = uidropdown(app.GridLayout23);
            app.CbarVarDropDown.Items = {'1:n'};
            app.CbarVarDropDown.DropDownOpeningFcn = createCallbackFcn(app, @CbarVarDropDownOpening, true);
            app.CbarVarDropDown.ValueChangedFcn = createCallbackFcn(app, @CbarVarDropDownValueChanged, true);
            app.CbarVarDropDown.Value = '1:n';
            app.ColorbarLabelEditField = uieditfield(app.GridLayout23, 'text');
            app.ColorbarLabelEditFieldLabel = uilabel(app.GridLayout23);
            app.ColorbarLabelEditFieldLabel.Text = 'Cbar Label';
            app.MultiMineralCalcTab = uitab(app.TabGroup3);
            app.MultiMineralCalcTab.Title = 'MultiMineralCalc';
            app.GridLayout14 = uigridlayout(app.MultiMineralCalcTab);
            app.MultiMinCalcTable = uitable(app.GridLayout14);
            app.MultiMinCalcTable.ColumnName = '';
            app.MultiMinCalcTable.RowName = {};
            app.MultiMinCalcTable.CellEditCallback = createCallbackFcn(app, @MultiMinCalcTableCellEdit, true);
            app.MultiMineralCalcButton = uibutton(app.GridLayout14, 'push');
            app.MultiMineralCalcButton.ButtonPushedFcn = createCallbackFcn(app, @MultiMineralCalcButtonPushed, true);
            app.MultiMineralCalcButton.Text = 'MultiMineralCalc';
            app.Tree = uitree(app.GridLayout14, 'checkbox');
            app.Tree.Editable = 'on';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.ResetColFilt = uibutton(app.GridLayout14, 'push');
            app.ResetColFilt.ButtonPushedFcn = createCallbackFcn(app, @ResetColFiltButtonPushed, true);
            app.ResetColFilt.Icon = 'symbols_1_Reset Col. Filter.png';
            app.ResetColFilt.Tooltip = {'Reset Column Filter'};
            app.ResetColFilt.Text = '';
            app.AddColFilt = uibutton(app.GridLayout14, 'push');
            app.AddColFilt.ButtonPushedFcn = createCallbackFcn(app, @AddColFiltButtonPushed, true);
            app.AddColFilt.Icon = 'symbols_1_Col Filter.png';
            app.AddColFilt.Tooltip = {'Add Column Filter'};
            app.AddColFilt.Text = '';
            app.ShowfulltableCheckBox = uicheckbox(app.GridLayout14);
            app.ShowfulltableCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowfulltableCheckBoxValueChanged, true);
            app.ShowfulltableCheckBox.Text = 'Show full table';
            app.SelectAllButton = uibutton(app.GridLayout14, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.GridLayout14, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.FindMineralButton = uibutton(app.GridLayout14, 'push');
            app.FindMineralButton.ButtonPushedFcn = createCallbackFcn(app, @FindMineralButtonPushed, true);
            app.FindMineralButton.Text = 'Find Mineral';
            app.ResultsTab = uitab(app.TabGroup3);
            app.ResultsTab.Title = 'Results';
            app.GridLayout21 = uigridlayout(app.ResultsTab);
            app.AdvancedPlotModuleButton = uibutton(app.GridLayout21, 'push');
            app.AdvancedPlotModuleButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsModuleButtonPushed, true);
            app.AdvancedPlotModuleButton.Text = 'AdvancedPlotModule';
            app.SpotVisMinPlotButton = uibutton(app.GridLayout21, 'push');
            app.SpotVisMinPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SpotVisMinPlotXButtonPushed, true);
            app.SpotVisMinPlotButton.Text = 'SpotVisMinPlot';
            app.SendtoGeoCPlotButton = uibutton(app.GridLayout21, 'push');
            app.SendtoGeoCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SendtoGeoCPlotButtonPushed, true);
            app.SendtoGeoCPlotButton.Tooltip = {'Send Project data to GeoCPlot'};
            app.SendtoGeoCPlotButton.Text = 'Send to GeoCPlot';
            app.VisModuleButton = uibutton(app.GridLayout21, 'push');
            app.VisModuleButton.ButtonPushedFcn = createCallbackFcn(app, @VisModuleButtonPushed, true);
            app.VisModuleButton.Text = 'VisModule';
            app.ExportModuleButton_2 = uibutton(app.GridLayout21, 'push');
            app.ExportModuleButton_2.ButtonPushedFcn = createCallbackFcn(app, @CustomExportMenuSelected, true);
            app.ExportModuleButton_2.Text = 'ExportModule';
            app.EasyPlotModuleButton = uibutton(app.GridLayout21, 'push');
            app.EasyPlotModuleButton.ButtonPushedFcn = createCallbackFcn(app, @EasyPlotModuleButtonPushed, true);
            app.EasyPlotModuleButton.Text = 'EasyPlotModule';
            app.Panel = uipanel(app.GridLayout21);
            app.Panel.Visible = 'off';
            app.GridLayout_2 = uigridlayout(app.Panel);
            app.ColorMapPreview = uiaxes(app.GridLayout_2);
            app.ColorMapPreview.XTick = [];
            app.ColorMapPreview.YTick = [];
            app.Options_LowerCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_LowerCheckBox.Tooltip = {'Add a lower color layer'};
            app.Options_LowerCheckBox.Text = 'Lower';
            app.Options_UpperCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_UpperCheckBox.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_UpperCheckBox.Tooltip = {'Add an upper color layer'};
            app.Options_UpperCheckBox.Text = 'Upper';
            app.Options_UpperColor = uidropdown(app.GridLayout_2);
            app.Options_UpperColor.Items = {'Black', 'White'};
            app.Options_UpperColor.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_UpperColor.Tooltip = {'Select the color for the upper layer'};
            app.Options_UpperColor.Value = 'Black';
            app.Options_ColormapResEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.Options_ColormapResEditField.Limits = [3 9999];
            app.Options_ColormapResEditField.RoundFractionalValues = 'on';
            app.Options_ColormapResEditField.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapResEditField.Tooltip = {'Colormap resolution (def. 256)'};
            app.Options_ColormapResEditField.Value = 256;
            app.Options_LogColormap = uicheckbox(app.GridLayout_2);
            app.Options_LogColormap.Tooltip = {'Use a log colormap'};
            app.Options_LogColormap.Text = 'Log';
            app.ColormapDropDownLabel = uilabel(app.GridLayout_2);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout_2);
            app.Options_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_ColormapDropDown.Tooltip = {'Select the active colormap'};
            app.Options_InvertColormap = uicheckbox(app.GridLayout_2);
            app.Options_InvertColormap.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_InvertColormap.Text = 'Invert';
            app.Options_LowerColor = uidropdown(app.GridLayout_2);
            app.Options_LowerColor.Items = {'Black', 'White'};
            app.Options_LowerColor.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged, true);
            app.Options_LowerColor.Tooltip = {'Select the color for the lower layer'};
            app.Options_LowerColor.Value = 'Black';
            app.ImportFromFiles_Button = uibutton(app.GridLayout10, 'push');
            app.ImportFromFiles_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportfilesButtonPushed, true);
            app.ImportFromFiles_Button.Icon = 'Add_from_file.png';
            app.ImportFromFiles_Button.Tooltip = {'Import single file'};
            app.ImportFromFiles_Button.Text = '';
            app.SaveDBProject_Button = uibutton(app.GridLayout10, 'push');
            app.SaveDBProject_Button.Icon = 'symbols_1_Save.png';
            app.SaveDBProject_Button.Tooltip = {'Save Project'};
            app.SaveDBProject_Button.Text = '';
            app.SaveDBProjectAs_Button = uibutton(app.GridLayout10, 'push');
            app.SaveDBProjectAs_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveMinPlotXProjectMenuSelected, true);
            app.SaveDBProjectAs_Button.Icon = 'Save As.png';
            app.SaveDBProjectAs_Button.Tooltip = {'Save Project'};
            app.SaveDBProjectAs_Button.Text = '';
            app.GeoCPlotButton_2 = uibutton(app.GridLayout10, 'push');
            app.GeoCPlotButton_2.ButtonPushedFcn = createCallbackFcn(app, @GeoCPlotButton_2Pushed, true);
            app.GeoCPlotButton_2.Icon = 'GeoCPlot.png';
            app.GeoCPlotButton_2.Tooltip = {'Open GeoCPlot'};
            app.GeoCPlotButton_2.Text = '';
            app.LoadProject_Button = uibutton(app.GridLayout10, 'push');
            app.LoadProject_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadMinPlotXProjectMenuSelected, true);
            app.LoadProject_Button.Icon = 'Load_Project.png';
            app.LoadProject_Button.Tooltip = {'Load MinPlotX Project'};
            app.LoadProject_Button.Text = '';
            app.ImportFromFolder_Button = uibutton(app.GridLayout10, 'push');
            app.ImportFromFolder_Button.ButtonPushedFcn = createCallbackFcn(app, @ImportfolderButtonPushed, true);
            app.ImportFromFolder_Button.Icon = 'Add_from_folder.png';
            app.ImportFromFolder_Button.Tooltip = {'Import multiple files from folder'};
            app.ImportFromFolder_Button.Text = '';
            app.MineralDropDownLabel = uilabel(app.GridLayout10);
            app.MineralDropDownLabel.Text = 'Mineral';
            app.MineralDropDown = uidropdown(app.GridLayout10);
            app.MineralDropDown.Items = {'unknown', 'MultiMineralData', 'garnet', 'pyroxene', 'olivine', 'amphibole', 'feldspar', 'mica', 'staurolite', 'cordierite', 'chlorite', 'chloritoid', 'talc', 'epidote', 'titanite', 'oxyspinel', 'ilmenite', 'apatite', 'sulfide', 'scapolite', 'lawsonite'};
            app.MineralDropDown.ValueChangedFcn = createCallbackFcn(app, @MineralDropDownValueChanged, true);
            app.MineralDropDown.Value = 'unknown';
            app.SamplesListBox = uilistbox(app.GridLayout10);
            app.SamplesListBox.Items = {};
            app.SamplesListBox.ValueChangedFcn = createCallbackFcn(app, @SamplesListBoxValueChanged, true);
            app.SamplesListBox.Value = {};
            app.DeleteButton = uibutton(app.GridLayout10, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Icon = 'Delete.png';
            app.DeleteButton.Tooltip = {'Delete Selected'};
            app.DeleteButton.Text = 'Delete Entry';
            app.DataCalculatorModuleButton = uibutton(app.GridLayout10, 'push');
            app.DataCalculatorModuleButton.ButtonPushedFcn = createCallbackFcn(app, @DataCalculatorModuleMenuSelected, true);
            app.DataCalculatorModuleButton.Icon = 'Caclulator.png';
            app.DataCalculatorModuleButton.Tooltip = {'Calculator Module'};
            app.DataCalculatorModuleButton.Text = '';
            app.FilterStringEditFieldLabel = uilabel(app.GridLayout10);
            app.FilterStringEditFieldLabel.Visible = 'off';
            app.FilterStringEditFieldLabel.Text = 'Filter String';
            app.FilterStringEditField = uieditfield(app.GridLayout10, 'text');
            app.FilterStringEditField.ValueChangedFcn = createCallbackFcn(app, @FilterStringEditFieldValueChanged, true);
            app.FilterStringEditField.Visible = 'off';
            app.Button_3 = uibutton(app.GridLayout10, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @FilterButtonValueChanged, true);
            app.Button_3.Tooltip = {'File Filter'};
            app.Button_3.Icon = 'symbols_1_Filter.png';
            app.Button_3.Text = '';
            app.PlotResultsCheckBox = uicheckbox(app.MinPlotXGUI);
            app.PlotResultsCheckBox.Visible = 'off';
            app.PlotResultsCheckBox.Text = 'Plot Results';
            app.Panel_2 = uipanel(app.MinPlotXGUI);
            app.Panel_2.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.MinPlotXGUI);
            app.ExportTableMenu = uimenu(app.ContextMenu);
            app.ExportTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTableMenuSelected, true);
            app.ExportTableMenu.Text = 'Export Table';
            % Assign app.ContextMenu
            app.OutputTable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.MinPlotXGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MinPlotX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MinPlotXGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MinPlotXGUI)
        end
    end
end