classdef DataCalculatorModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataCalculatorModuleGUI     matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        UITable                     matlab.ui.control.Table
        ClearButton                 matlab.ui.control.Button
        HelpButton                  matlab.ui.control.Button
        NewEntryNameEditField       matlab.ui.control.EditField
        NewEntryNameEditFieldLabel  matlab.ui.control.Label
        SampleDropDown              matlab.ui.control.DropDown
        SampleDropDownLabel         matlab.ui.control.Label
        Calculate_sel               matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        OpenButton                  matlab.ui.control.Button
        DevisionButton              matlab.ui.control.Button
        MultiplicationButton        matlab.ui.control.Button
        MinusButton                 matlab.ui.control.Button
        PlusButton                  matlab.ui.control.Button
        DataTree                    matlab.ui.container.Tree
        AddButton                   matlab.ui.control.Button
        Calculate                   matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        DataPlot                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        mainapp % Description
        sampleinfo

        T 
    end

    methods (Access = public)

        function UpdateTree_Single(app,Tree,T)

            if not(exist('Tree','var'))|| isempty(Tree)
                Tree=app.DataTree;
            end

            a = Tree.Children;
            a.delete;

            nodes=         T.Properties.VariableNames;
            for n=1:numel(nodes)
                p1 = uitreenode(app.DataTree,'Text',char(nodes{n}),'NodeData',T.(char(nodes{n})));
            end
        end

    end


    methods (Access = private)

        function matrix=CalculateMatrix_Fun(app,active_Sample,plotstate)
            if startsWith(app.TextArea.Value{:},'CombineStrings') ||startsWith(app.TextArea.Value{:},'ReplaceString')
                %  uialert(app.DataCalculatorModuleGUI,message,'Error');
            else
                matrix=[];
                str_ini=app.TextArea.Value{:};
                spltstr = str_ini;%split(string(str_ini),"'");
                calc_idx=strfind(spltstr,'§!');
                calc_idx_end=strfind(spltstr,'!§')+1;
                if plotstate==true
                    figure
                end

                try
                    %% new code using str2func
                    str2calc_cell=str_ini;
                    x=cell(1,numel(calc_idx));
                    %calc_idx=calc_idx(not(cellfun(@isempty,calc_idx)));

                    for n=1:numel(calc_idx)
                        str2Replace= str_ini(calc_idx(n):calc_idx_end(n));
                        x{n}=app.T.(char(replace(str2Replace,{'§!','!§'},'')));
                        str2Replace_cell=char(['x{',num2str(n),'}']);
                        str2calc_cell=replace(str2calc_cell,str2Replace,str2Replace_cell);
                    end

                    f=str2func(['@(x)', str2calc_cell]);
                    matrix=f(x);

                    %%old code using eval
                    %                 matrix2=eval(str2calc{:});
                    %                 %matrix2=eval(str{:});
                    %                 message=num2str(isequal(matrix,matrix2));
                    %                 uialert(app.DataCalculatorModuleGUI,message,'Error');

                catch ME
                    disp('Error CalculateMatrix_Fun in DataCalculatorModule')
                    disp(ME)
                    message=ME.message;
                    uialert(app.DataCalculatorModuleGUI,message,'Error');
                    disp(message)
                end
                if plotstate==true
                    histogram(app.DataPlot,matrix); % used in CorelationModule
                    title(app.DataPlot,str_ini,'Interpreter','none')
                end
            end
        end

        function matrix=ReplaceString_Fun(app)
            matrix=[];
            str_ini=replace(app.TextArea.Value{:},'ReplaceString ','');

            calc_idx=strfind(str_ini,'§!');
            calc_idx_end=strfind(str_ini,'!§')+1;

            try
                %% new code using str2func
                str2calc_cell=str_ini;
                x=cell(1,numel(calc_idx));
                for n=1:numel(calc_idx)
                    str2Replace= str_ini(calc_idx(n):calc_idx_end(n));
                    x{n}=app.T.(char(replace(str2Replace,{'§!','!§'},'')));
                    str2Replace_cell=char(['x{',num2str(n),'}']);
                    str2calc_cell=replace(str2calc_cell,str2Replace,str2Replace_cell);
                end
                str2calc_cell=['replace(' str2calc_cell ')']
                f=str2func(['@(x)', str2calc_cell]);
                matrix=string(f(x));

                %%old code using eval
                %                 matrix2=eval(str2calc{:});
                %                 %matrix2=eval(str{:});
                %                 message=num2str(isequal(matrix,matrix2));
                %                 uialert(app.DataCalculatorModuleGUI,message,'Error');

            catch ME
                disp('Error ReplaceString_Fun in DataCalculatorModule')
                disp(ME)
                message=ME.message;
                uialert(app.DataCalculatorModuleGUI,message,'Error');
                disp(message)
            end
        end

        function results = CombineStringsFun(app)
            str_ini=replace(app.TextArea.Value{:},'CombineStrings ','');

            calc_idx=strfind(str_ini,'§!');
            calc_idx_end=strfind(str_ini,'!§')+1;
            x=cell(size(app.T,1),numel(calc_idx));
            for n=1:numel(calc_idx)
                str2Replace= str_ini(calc_idx(n):calc_idx_end(n));
                x(:,n)=app.T.(char(replace(str2Replace,{'§!','!§'},'')));
            end
            results=join(x);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, sampleinfo, SampleNames, T)
            movegui(app.DataCalculatorModuleGUI, 'center');
            app.mainapp=mainapp;
            if numel(SampleNames)>1
                app.SampleDropDown.Items=SampleNames;

                app.SampleDropDown.Value=SampleNames(sampleinfo(1));
            else
                app.SampleDropDown.Visible=false;
            end
            app.sampleinfo=sampleinfo;
            app.T=T;


            UpdateTree_Single(app,app.DataTree,T)


        end

        % Button pushed function: Calculate
        function CalculatePushed(app, event)
            active_Sample=app.sampleinfo(1);
            CalculateMatrix_Fun(app,active_Sample,true);

        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            try

                if iscell(app.TextArea.Value) && isempty( app.TextArea.Value{:})
                    app.TextArea.Value= ['§!' char(app.DataTree.SelectedNodes.Text) '!§'];
                elseif  iscell(app.TextArea.Value)
                    app.TextArea.Value=[app.TextArea.Value{:} ' ' ['§!' char(app.DataTree.SelectedNodes.Text) '!§']];
                end
            catch
                CalculatePushed(app)
            end
        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            app.sampleinfo(1)= find(ismember(app.SampleDropDown.Items,app.SampleDropDown.Value));
            UpdateTree_Single(app,app.DataTree,app.T)

        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            selectedNodes = app.DataTree.SelectedNodes;
            try
                if isnumeric(selectedNodes.NodeData)
                    app.DataPlot.Visible=true;
                    histogram(app.DataPlot,selectedNodes.NodeData)
                    title(app.DataPlot,selectedNodes.Text,'Interpreter','none')
                else
                    app.DataPlot.Visible=false;
                    cla(app.DataPlot)
                    title(app.DataPlot,'','Interpreter','none')
                end
                app.UITable.Data=selectedNodes.NodeData;
                app.UITable.ColumnName={selectedNodes.Text};
            catch
                app.DataPlot.Visible=false;
                app.UITable.Data=selectedNodes.NodeData;
                app.UITable.ColumnName={selectedNodes.Text};
                cla(app.DataPlot)
                title(app.DataPlot,'','Interpreter','none')

            end

        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' + '];
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ) '];
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' - '];
        end

        % Button pushed function: MultiplicationButton
        function MultiplicationButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' .* '];
        end

        % Button pushed function: DevisionButton
        function DevisionButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ./ '];
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.TextArea.Value=[app.TextArea.Value{:} ' ( '];
        end

        % Button pushed function: Calculate_sel
        function Calculate_selButtonPushed(app, event)

            if isempty(app.NewEntryNameEditField.Value)
                new_name=app.TextArea.Value;
            else
                new_name={app.NewEntryNameEditField.Value};
            end

            new_name = matlab.lang.makeValidName(new_name);
            if startsWith(app.TextArea.Value{:},'CombineStrings')
                matrix=CombineStringsFun(app);
                app.T.(char(new_name))=matrix;


            elseif startsWith(app.TextArea.Value{:},'ReplaceString')
                matrix=ReplaceString_Fun(app);
            else
                [matrix ]=CalculateMatrix_Fun(app,[],false);

            end
            try
                new_name= CalcTableReturn(app.mainapp,matrix,new_name,app.T.Id,app.sampleinfo,app.DataCalculatorModuleGUI);
            catch
                new_name=CalcTableReturn(app.mainapp,matrix,new_name,[],app.sampleinfo,app.DataCalculatorModuleGUI);
            end

            app.T.(char(new_name))=matrix;

            UpdateTree_Single(app,app.DataTree,app.T)

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            helptext = {         'Variables can be added by selecting the wanted entry from the listbox on the left and pressing Add>',...
                '', ...
                '1) To calculate, use: (§!Variable1!§ + §!Variable2!§) ./ §!Variable3!§', ...
                'Note: Any valid MATLAB mathematical operation and syntax is allowed', ...
                '', ...
                '2) To combine strings, use: CombineStrings §!Variable1!§ §!Variable2!§', ...
                '', ...
                ['3) To replace strings, use: ReplaceString §!Variable!§, ' '''original_str''' ','  '''new_str'''],...
                };
            app.TextArea.Value = helptext;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.DataPlot)
            app.TextArea.Value='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataCalculatorModuleGUI = uifigure('Visible', 'off');
            app.DataCalculatorModuleGUI.Name = 'DataCalculatorModule';
            app.DataCalculatorModuleGUI.Icon = 'Caclulator.png';
            app.GridLayout = uigridlayout(app.DataCalculatorModuleGUI);
            app.DataPlot = uiaxes(app.GridLayout);
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Tooltip = {'Press the Help button for more information'};
            app.Calculate = uibutton(app.GridLayout, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculatePushed, true);
            app.Calculate.Text = 'Check';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add >';
            app.DataTree = uitree(app.GridLayout);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.DataTree.Editable = 'on';
            app.DataTree.Tooltip = {''};
            app.PlusButton = uibutton(app.GridLayout, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.GridLayout, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.MultiplicationButton = uibutton(app.GridLayout, 'push');
            app.MultiplicationButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicationButtonPushed, true);
            app.MultiplicationButton.Text = 'x';
            app.DevisionButton = uibutton(app.GridLayout, 'push');
            app.DevisionButton.ButtonPushedFcn = createCallbackFcn(app, @DevisionButtonPushed, true);
            app.DevisionButton.Text = ':';
            app.OpenButton = uibutton(app.GridLayout, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = '(';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = ')';
            app.Calculate_sel = uibutton(app.GridLayout, 'push');
            app.Calculate_sel.ButtonPushedFcn = createCallbackFcn(app, @Calculate_selButtonPushed, true);
            app.Calculate_sel.Text = 'Add Result';
            app.SampleDropDownLabel = uilabel(app.GridLayout);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.GridLayout);
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.NewEntryNameEditFieldLabel = uilabel(app.GridLayout);
            app.NewEntryNameEditFieldLabel.Text = 'NewEntryName';
            app.NewEntryNameEditField = uieditfield(app.GridLayout, 'text');
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.DataCalculatorModuleGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataCalculatorModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataCalculatorModuleGUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataCalculatorModuleGUI)
        end
    end
end