classdef FindMineralsInMetaModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FindMineralsInMetaModuleUIFigure  matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        MineralDropDown       matlab.ui.control.DropDown
        MineralDropDownLabel  matlab.ui.control.Label
        StringEditField       matlab.ui.control.EditField
        StringEditFieldLabel  matlab.ui.control.Label
        MatchCaseCheckBox     matlab.ui.control.CheckBox
        ExtractButton         matlab.ui.control.Button
        ColumnDropDown        matlab.ui.control.DropDown
    end


    properties (Access = private)
        MinPlotX % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
                     movegui(app.FindMineralsInMetaModuleUIFigure, 'center');
   app.MinPlotX=Mainapp;
            app.MineralDropDown.Items=app.MinPlotX.MineralDropDown.Items;
            ColumnDropDownOpening(app)
        end

        % Drop down opening function: ColumnDropDown
        function ColumnDropDownOpening(app, event)
            app.ColumnDropDown.Items=app.MinPlotX.MultiMinCalcTable.ColumnName;

        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            data2test=app.MinPlotX.MultiMinCalcTable.Data(:,strcmp(app.ColumnDropDown.Value,app.MinPlotX.MultiMinCalcTable.ColumnName));

            if app.MatchCaseCheckBox.Value == true && sum(contains(data2test,app.StringEditField.Value))>1
                app.MinPlotX.MultiMinCalcTable.Data(contains(data2test,app.StringEditField.Value),strcmp('Mineral',app.MinPlotX.MultiMinCalcTable.ColumnName))={app.MineralDropDown.Value};

                app.MinPlotX.MinPlotXData.Sample(app.MinPlotX.active_entry).Input.MetaTable.Mineral(contains(data2test,app.StringEditField.Value))={app.MineralDropDown.Value};

                app.MinPlotX.MineralDropDown.Value='MultiMineralData';
                UpdateTree(app.MinPlotX)
            elseif app.MatchCaseCheckBox.Value == false && sum(contains(lower(data2test),lower(app.StringEditField.Value)))>1
                app.MinPlotX.MultiMinCalcTable.Data(contains(data2test,app.StringEditField.Value),strcmp('Mineral',app.MinPlotX.MultiMinCalcTable.ColumnName))={app.MineralDropDown.Value};
                app.MinPlotX.MinPlotXData.Sample(app.MinPlotX.active_entry).Input.MetaTable.Mineral(contains(lower(data2test),lower(app.StringEditField.Value)))={app.MineralDropDown.Value};
   app.MinPlotX.MineralDropDown.Value='MultiMineralData';
                UpdateTree(app.MinPlotX)
            end
            thx=cell(1,numel(app.MinPlotX.MinPlotXData.Sample(app.MinPlotX.active_entry).Input.MetaTable.Mineral));
            for n=1:numel(app.MinPlotX.MinPlotXData.Sample(app.MinPlotX.active_entry).Input.MetaTable.Mineral)
                str={CheckMinName(app.MinPlotX,app.MinPlotX.MinPlotXData.Sample(app.MinPlotX.active_entry).Input.MetaTable.Mineral(n))};
                if strcmp(str,'unknown')
                    thx(n)={'unknown'};
                else
                    thx(n)=app.MinPlotX.MineralCalcFunctions.(char(str))(1); %ath if ismember mineral,str -> MinPlotXfun find idx
                end
            end

            app.MinPlotX.MultiMinCalcTable.Data(:,strcmp('MineralCalcFunction',app.MinPlotX.MultiMinCalcTable.ColumnName))=thx;
            [app.MinPlotX.MinPlotXData.Sample(app.MinPlotX.active_entry).Input.MetaTable.MineralCalcFunction(:)]=thx;


 update_MultiCalcTable_table_public(app.MinPlotX)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FindMineralsInMetaModuleUIFigure = uifigure('Visible', 'off');
            app.FindMineralsInMetaModuleUIFigure.Name = 'FindMineralsInMetaModule';
            app.GridLayout = uigridlayout(app.FindMineralsInMetaModuleUIFigure);
            app.ColumnDropDown = uidropdown(app.GridLayout);
            app.ColumnDropDown.Items = {'Select Column', 'Option 2', 'Option 3', 'Option 4'};
            app.ColumnDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ColumnDropDownOpening, true);
            app.ColumnDropDown.Value = 'Select Column';
            app.ExtractButton = uibutton(app.GridLayout, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Text = 'Extract';
            app.MatchCaseCheckBox = uicheckbox(app.GridLayout);
            app.MatchCaseCheckBox.Text = 'MatchCase';
            app.StringEditFieldLabel = uilabel(app.GridLayout);
            app.StringEditFieldLabel.Text = 'String';
            app.StringEditField = uieditfield(app.GridLayout, 'text');
            app.MineralDropDownLabel = uilabel(app.GridLayout);
            app.MineralDropDownLabel.Text = 'Mineral';
            app.MineralDropDown = uidropdown(app.GridLayout);
            app.MineralDropDown.Items = {''};
            app.MineralDropDown.Value = '';
            % Show the figure after all components are created
            app.FindMineralsInMetaModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FindMineralsInMetaModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.FindMineralsInMetaModuleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.FindMineralsInMetaModuleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FindMineralsInMetaModuleUIFigure)
        end
    end
end