classdef MatchTableModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatchTableModuleUIFigure  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LoadadditionalFileButton  matlab.ui.control.Button
        ExportResultsButton       matlab.ui.control.Button
        PrefixEditField           matlab.ui.control.EditField
        PrefixEditFieldLabel      matlab.ui.control.Label
        ResetButton               matlab.ui.control.Button
        LoadTableButton           matlab.ui.control.Button
        DB_table_2                matlab.ui.control.Table
        DBDropDown                matlab.ui.control.DropDown
        DBDropDownLabel           matlab.ui.control.Label
        ImportDropDown            matlab.ui.control.DropDown
        ImportDropDownLabel       matlab.ui.control.Label
        MergeTablesButton         matlab.ui.control.Button
        InsertinMinPlotXButton    matlab.ui.control.Button
        LoadMetaFileButton        matlab.ui.control.Button
        DB_table                  matlab.ui.control.Table
        ResultsTable              matlab.ui.control.Table
    end

    
    properties (Access = private)
        mainapp
        Data % Description
        protected_fns={'Id',...
            'Al2O3','As2O3','Au2O','B2O3','BaO','BeO','CO2','CaO','CeO2','Ce2O3',...
                'CoO','Cr2O3','Cs2O','CuO','Dy2O3','Er2O3','EuO','Eu2O3','FeO','Fe2O3',...
                'Ga2O3','Gd2O3','GeO2','H2O','HfO2','Ho2O3','K2O','La2O3','Li2O','Lu2O3',...
                'MgO','MnO','MnO2','Mn3O4','MoO3','Na2O','Nb2O5','Nd2O3','NiO','P2O5','PbO',...
                'Pr2O3','Rb2O','SO3','Sb2O3','Sc2O3','SiO2','Sm2O3','SnO2','SrO','Ta2O5',...
                'Tb2O3','ThO2','TiO2','Ti2O3','Tm2O3','UO2','U3O8','V2O3','V2O5','WO3',...
                'Y2O3','Yb2O3','ZnO','ZrO2',...
                'H','He','Li','Be','B','C','N','O','F','Ne','Na','Mg','Al','Si','P','S','Cl',...
                'Ar','K','Ca','Sc','Ti','V','Cr','Mn','Fe','Co','Ni','Cu','Zn','Ga','Ge','As',...
                'Se','Br','Kr','Rb','Sr','Y','Zr','Nb','Mo','Tc','Ru','Rh','Pd','Ag','Cd','In',...
                'Sn','Sb','Te','I','Xe','Cs','Ba','La','Ce','Pr','Nd','Pm','Sm','Eu','Gd','Tb',...
                'Dy','Ho','Er','Tm','Yb','Lu','Hf','Ta','W','Re','Os','Ir','Pt','Au','Hg','Tl',...
                'Pb','Bi','Po','At','Rn','Fr','Ra','Ac','Th','Pa','U','Np','Pu','Am','Cm','Bk',...
                'Cf','Es','Fm','Md','No','Lr','Rf','Db','Sg','Bh','Hs','Mt','Uun','Uuu','Uub'}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)

            if exist('mainapp','var')
            app.mainapp=mainapp;
            else
            app.mainapp=[];
            end
            if exist('meta_data_in','var')&& istable(meta_data_in) && not(isempty(meta_data_in))
                app.Data.meta_data_in=readtable(fullfile(path,file));
                app.DB_table_2.Data=app.Data.meta_data_in;
                app.DB_table_2.ColumnName=app.Data.meta_data_in.Properties.VariableNames;
                app.ImportDropDown.Items=app.Data.meta_data_in.Properties.VariableNames;
            else
                app.ImportDropDown.Editable=false;
            end

            if exist('DataTable','var')&& istable(DataTable) && not(isempty(DataTable))
                app.Data.DataTable=readtable(fullfile(path,file));
                app.DB_table.Data=app.Data.meta_data_in;
                app.DB_table.ColumnName=app.Data.meta_data_in.Properties.VariableNames;
                %table(unique({app.Data.meta_data_in.Sample}'),'VariableNames',{'Samples'});
                app.DBDropDown.Items=app.Data.DataTable.Properties.VariableNames;
            else
                app.DBDropDown.Editable=false;
            end
        end

        % Button pushed function: LoadTableButton
        function LoadTableButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},"MultiSelect","off", 'Import Meta File');
            delete(f);
            figure(app.MatchTableModuleUIFigure)
            if not(isequal(file,0))
                try 

                    opts = detectImportOptions(fullfile(path,file));

                    % ismember Sample / SampleName / comment whichevervariable
                   % opts = setvartype(opts, whichevervariable, 'string');  %or 'char' if you prefer
                   % data = readtable(yourfile, opts)

                    app.Data.table_data_in=readtable(fullfile(path,file),opts);
                    %
                    app.DB_table.Data=app.Data.table_data_in;
                    app.Data.table_data_in_original=app.Data.table_data_in;

                    app.DB_table.ColumnName=app.Data.table_data_in.Properties.VariableNames;

                    app.DBDropDown.Items=app.Data.table_data_in.Properties.VariableNames;
                    app.DBDropDown.Enable=true;

                catch ME
                    ME
                end
            else
                return
            end
        end

        % Button pushed function: LoadMetaFileButton
        function LoadMetaFileButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [file,path]=uigetfile({'*.xlsx;*.xls;*.csv;*.txt'},"MultiSelect","off", 'Import Meta File');
            delete(f);
            figure(app.MatchTableModuleUIFigure)
            if not(isequal(file,0))
                try
                    app.Data.meta_data_in=readtable(fullfile(path,file));
                    %
                    app.DB_table_2.Data=app.Data.meta_data_in;
                    app.DB_table_2.ColumnName=app.Data.meta_data_in.Properties.VariableNames;
                    %table(unique({app.Data.meta_data_in.Sample}'),'VariableNames',{'Samples'});

                    app.DBDropDown.Items=app.DB_table.ColumnName;
                    app.ImportDropDown.Items=app.Data.meta_data_in.Properties.VariableNames;
                    app.ImportDropDown.Enable=true;
                catch ME
                    ME
                end
            else
                return
            end
        end

        % Button pushed function: MergeTablesButton
        function MergeTablesButtonPushed(app, event)
            if not(isempty(app.Data))&& isfield(app.Data,'table_data_in') && not(isempty(app.Data.table_data_in))&&isfield(app.Data,'meta_data_in') && not(isempty(app.Data.meta_data_in))
                Samples_in_DB=unique(app.Data.table_data_in.(char(app.DBDropDown.Value)));
                if isempty(Samples_in_DB)
                    return
                end
                    if  isnumeric(Samples_in_DB)
                        return
                    end

                    d = uiprogressdlg(app.MatchTableModuleUIFigure,'Title','Meta Matching',...
                        'Message','Automatic Meta Matching Started','Cancelable','on');

                results_table=app.Data.table_data_in;

                for n=1:numel(Samples_in_DB)
                    if d.CancelRequested
                        break
                    end

                    d.Value = n/numel(Samples_in_DB);
                    d.Message = sprintf(['Matching Entry ' num2str(n) '/' num2str(numel(Samples_in_DB)) ': ' Samples_in_DB{n}]);
                    pos_in_meta_import=find(strcmpi(string(app.Data.meta_data_in.(char(app.ImportDropDown.Value))),(Samples_in_DB(n))));

                    if not(isempty(pos_in_meta_import))
                        meta2import=app.Data.meta_data_in(pos_in_meta_import,:);
                        fns=meta2import.Properties.VariableNames; %% ath here remove forbidden fieldnames

                        eIds=find(contains(results_table.(char(app.DBDropDown.Value)),Samples_in_DB{n}));
                        if isempty(eIds)
                            return
                        end
                        for m=1:numel(fns)
                            if d.CancelRequested
                                break
                            end
                       %     if any(strcmp(fns{m},app.protected_fns))||
                            if  isempty(eIds)|| (isnumeric((meta2import.(char(fns{m})))) && sum(isnan(meta2import.(char(fns{m}))))==size(meta2import.(char(fns{m})),1))
                                continue
                            else
                                try
                                    if isnumeric((meta2import.(char(fns{m})))) && not(ismember(char([app.PrefixEditField.Value fns{m}]),results_table.Properties.VariableNames))
                                        results_table.(char([app.PrefixEditField.Value fns{m}]))(:)=nan;
                                    end

                                    if numel(unique(meta2import.(char(fns{m}))(:)))==1
                                        results_table.(char([app.PrefixEditField.Value fns{m}]))(eIds)=meta2import.(char(fns{m}))(1);
                                    else
                                        %    results_table.(char([app.PrefixEditField.Value fns{m}]))(:)=meta2import.(char(fns{m}))(1);
                                        if isnumeric((meta2import.(char(fns{m}))))
                                            results_table.(char([app.PrefixEditField.Value fns{m}]))(eIds)=mean(meta2import.(char(fns{m})),'omitnan');%#ok
                                            results_table.(char([app.PrefixEditField.Value fns{m}]))(eIds)=mean(meta2import.(char(fns{m})),'omitnan');%#ok
                                            %% delete after sims import done

%                                             if strcmp(meta2import.Pos(1),'core')
%                                            %     [isda da]=max(meta2import.ProfileDistance);
%                                                 [isda, da]=max(meta2import.distanceFromRimSpot);
%                                                 results_table.(char([app.PrefixEditField.Value fns{m}]))(eIds)=meta2import.(char(fns{m}))(da);
%                                             elseif strcmp(meta2import.Pos(1),'rim')
%                                                 [isda, da]=min(meta2import.H2O);
%                                                 results_table.(char([app.PrefixEditField.Value fns{m}]))(eIds)=meta2import.(char(fns{m}))(da);
% 
%                                             end
%                                             %
                                            
                                            disp('multiple entries with different content mean value imported')
                                                                                                    d.Message = sprintf(['Matching Entry ' num2str(n) '/' num2str(numel(Samples_in_DB)) ': ' Samples_in_DB{n} '\n' 'multiple entries with different content mean value imported']);
                                        else
                                            results_table.(char([app.PrefixEditField.Value fns{m}]))(eIds)=meta2import.(char(fns{m}))(1);
                                            disp('multiple entries with different content first value imported')
                                                                d.Message = sprintf(['Matching Entry ' num2str(n) '/' num2str(numel(Samples_in_DB)) ': ' Samples_in_DB{n} '\n' 'multiple entries with different content first value imported']);
                                        end

                                        % disp('multiple entries with different content first value imported')
                                        %    disp([num2str(n) ' multiple entries with different content first value imported' ])
                                    end


                                catch ME
                                    ME
                                end
                            end
                        end


                    end
                end
                app.Data.results_table=results_table;
                app.ResultsTable.Data=results_table;
                app.ResultsTable.ColumnName=results_table.Properties.VariableNames;

                app.ResultsTable.Visible=true;
                app.ResetButton.Visible=true;
                app.ExportResultsButton.Visible=true;
                app.DB_table.Visible=false;
                app.DB_table_2.Visible=false;

                app.LoadadditionalFileButton.Visible=true;
                app.LoadMetaFileButton.Visible=false;


            end

        end

        % Button pushed function: InsertinMinPlotXButton
        function InsertinMinPlotXButtonPushed(app, event)
                            insert_data(app.mainapp,app.Data.results_table,'MultiMineralData','MinPlotX_FullTable',true)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Data.results_table=[];
            app.ResultsTable.Data=[];
            app.Data.table_data_in=app.Data.table_data_in_original;

            app.ResultsTable.Visible=false;
            app.LoadadditionalFileButton.Visible=false;
            app.LoadMetaFileButton.Visible=true;
            
            app.DB_table.Visible=true;
            app.DB_table_2.Visible=true;
            app.ResetButton.Visible=false;
                app.ExportResultsButton.Visible=false;

        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
           f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]);
            [filename, filepath] = uiputfile('*.txt', 'Save the project file:','MinPlotX_MergedTable.csv');
            delete(f);
            figure(app.MatchTableModuleUIFigure);

            if not(isequal(filename,0))
                if not(isempty(app.ResultsTable.Data))
                result=app.ResultsTable.Data;
                writetable(result,fullfile(filepath,filename),'Delimiter','\t');
                end
            end
        end

        % Button pushed function: LoadadditionalFileButton
        function LoadadditionalFileButtonPushed(app, event)

            app.Data.table_data_in=app.ResultsTable.Data;
            LoadMetaFileButtonPushed(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MatchTableModuleUIFigure = uifigure('Visible', 'off');
            app.MatchTableModuleUIFigure.Name = 'MatchTableModule';
            app.GridLayout = uigridlayout(app.MatchTableModuleUIFigure);
            app.ResultsTable = uitable(app.GridLayout);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.ResultsTable.ColumnSortable = true;
            app.ResultsTable.ColumnEditable = true;
            app.ResultsTable.Visible = 'off';
            app.DB_table = uitable(app.GridLayout);
            app.DB_table.ColumnName = '';
            app.DB_table.RowName = {};
            app.DB_table.ColumnSortable = true;
            app.DB_table.ColumnEditable = true;
            app.LoadMetaFileButton = uibutton(app.GridLayout, 'push');
            app.LoadMetaFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMetaFileButtonPushed, true);
            app.LoadMetaFileButton.Text = 'Load Meta File';
            app.InsertinMinPlotXButton = uibutton(app.GridLayout, 'push');
            app.InsertinMinPlotXButton.ButtonPushedFcn = createCallbackFcn(app, @InsertinMinPlotXButtonPushed, true);
            app.InsertinMinPlotXButton.Text = 'Insert in MinPlotX';
            app.MergeTablesButton = uibutton(app.GridLayout, 'push');
            app.MergeTablesButton.ButtonPushedFcn = createCallbackFcn(app, @MergeTablesButtonPushed, true);
            app.MergeTablesButton.Text = 'MergeTables';
            app.ImportDropDownLabel = uilabel(app.GridLayout);
            app.ImportDropDownLabel.Text = 'MetaDataTable';
            app.ImportDropDown = uidropdown(app.GridLayout);
            app.ImportDropDown.Items = {'No MetaTable'};
            app.ImportDropDown.Enable = 'off';
            app.ImportDropDown.Value = 'No MetaTable';
            app.DBDropDownLabel = uilabel(app.GridLayout);
            app.DBDropDownLabel.Text = 'DataTable';
            app.DBDropDown = uidropdown(app.GridLayout);
            app.DBDropDown.Items = {'No DataTable'};
            app.DBDropDown.Enable = 'off';
            app.DBDropDown.Value = 'No DataTable';
            app.DB_table_2 = uitable(app.GridLayout);
            app.DB_table_2.ColumnName = '';
            app.DB_table_2.RowName = {};
            app.DB_table_2.ColumnSortable = true;
            app.DB_table_2.ColumnEditable = true;
            app.LoadTableButton = uibutton(app.GridLayout, 'push');
            app.LoadTableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTableButtonPushed, true);
            app.LoadTableButton.Text = 'Load Table';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.PrefixEditFieldLabel = uilabel(app.GridLayout);
            app.PrefixEditFieldLabel.Text = 'Prefix';
            app.PrefixEditField = uieditfield(app.GridLayout, 'text');
            app.PrefixEditField.Value = 'Meta_';
            app.ExportResultsButton = uibutton(app.GridLayout, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Visible = 'off';
            app.ExportResultsButton.Text = 'Export Results';
            app.LoadadditionalFileButton = uibutton(app.GridLayout, 'push');
            app.LoadadditionalFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadadditionalFileButtonPushed, true);
            app.LoadadditionalFileButton.Visible = 'off';
            app.LoadadditionalFileButton.Text = 'Load additional File';
            % Show the figure after all components are created
            app.MatchTableModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatchTableModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatchTableModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatchTableModuleUIFigure)
        end
    end
end