classdef MinPlotXExportModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExportModuleUIFigure           matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DataTable                      matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        DropDown                       matlab.ui.control.DropDown
        UpdateButton                   matlab.ui.control.Button
        Tree_2                         matlab.ui.container.CheckBoxTree
        Tree                           matlab.ui.container.CheckBoxTree
        FileInfoNode                   matlab.ui.container.TreeNode
        ChemistryNode                  matlab.ui.container.TreeNode
        MetadataNode                   matlab.ui.container.TreeNode
        apfuNode                       matlab.ui.container.TreeNode
        StructuralFormularNode         matlab.ui.container.TreeNode
        CalcOutNode                    matlab.ui.container.TreeNode
        OptionsNode                    matlab.ui.container.TreeNode
        UITable                        matlab.ui.control.Table
        ExportTab                      matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        ExcelTabExportButton           matlab.ui.control.Button
        ExportButton_3                 matlab.ui.control.Button
        DelimiterDropDown              matlab.ui.control.DropDown
        DelimiterDropDownLabel         matlab.ui.control.Label
        FiletypeDropDown               matlab.ui.control.DropDown
        FiletypeDropDownLabel          matlab.ui.control.Label
        OverwritePreviousExportCheckBox  matlab.ui.control.CheckBox
        ExportDirectoryEditField_2     matlab.ui.control.EditField
        ExportDirectoryEditFieldLabel_2  matlab.ui.control.Label
        ExportDirectoryEditField       matlab.ui.control.EditField
        ExportDirectoryEditFieldLabel  matlab.ui.control.Label
        SelectDirButton                matlab.ui.control.Button
        DelimiterEditField             matlab.ui.control.EditField
        ContextMenu                    matlab.ui.container.ContextMenu
        CheckallMenu                   matlab.ui.container.Menu
        UncheckallMenu                 matlab.ui.container.Menu
    end


    properties (Access = private)
        MinPlotX % Description
    end

    methods (Access = private)

        function update_GUI(app)
            UpdateFileTree(app)
            update_table(app);

        end

        function ResetTree(app,Tree2Update)

            if not(exist('Tree2Update','var'))
                Tree2Update=app.Tree_2;
            end

            %             if ~isempty(Tree2Update.SelectedNodes)
            %                 app.SelectedNodes_MainTree = Tree2Update.SelectedNodes.NodeData;
            %             end

            a = Tree2Update.Children;
            a.delete;
        end

        function UpdateFileTree(app)

            Tree2Update=app.Tree_2;
            N=Tree2Update.CheckedNodes;

            if not(isempty(N))


                selectedNodes=cell2mat({N.NodeData}');

                T_C=cell2mat({Tree2Update.Children.NodeData}');
                T_C=Tree2Update.Children(T_C(ismember(T_C,unique(selectedNodes(:,1)))));
                N=[N;T_C];

                selectedNodes_T={N.Text}';
                selectedNodes=cell2mat({N.NodeData}');

                update_ts=true;
            else
                update_ts=false;
                selectedNodes=[];
            end

            %             ResetTree(app,app.Tree_2)
            %
            %             app.CheckallMenu = uimenu(app.ContextMenu);
            %             app.CheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckallMenuSelected, true);
            %             app.CheckallMenu.Text = 'Check all';
            %
            %             % Create UncheckallMenu
            %             app.UncheckallMenu = uimenu(app.ContextMenu);
            %             app.UncheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @UncheckallMenuSelected, true);
            %             app.UncheckallMenu.Text = 'Uncheck all';
            %
            %             % Assign app.ContextMenu


            if strcmp(app.DropDown.Value,'Sort By File')

                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    p0 = uitreenode(Tree2Update,'Text',string(app.MinPlotX.MinPlotXData.SampleNames{n}),'NodeData',[n,0]);
                    %   p.ContextMenu = ContextMenu_thx;

                    p0.ContextMenu =  app.ContextMenu;

                    Mineral=unique(app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral);
                    if numel(Mineral) > 0
                        for i = 1:numel(Mineral)
                            [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo]=get_compiled_table(app.MinPlotX,n); %#ok

                            which_row=strcmp(T_MetaData.Mineral,Mineral{i});

                            if not(isempty(T_FileInfo))
                                Data.FileInfo=T_FileInfo(which_row,:);
                            else
                                Data.FileInfo=T_FileInfo;
                            end


                            if not(isempty(T_InputData))
                                Data.InputData=T_InputData(which_row,:);
                            else
                                Data.InputData=T_InputData;
                            end

                            if not(isempty(T_MetaData))
                                Data.MetaData=T_MetaData(which_row,:);
                            else
                                Data.MetaData=T_MetaData;
                            end
                            if not(isempty(T_StrctFrm))
                                Data.StrctFrm=T_StrctFrm(which_row,:);
                            else
                                Data.StrctFrm=T_StrctFrm;
                            end

                            if not(isempty(T_apfu))
                                Data.apfu=T_apfu(which_row,:);
                            else
                                Data.apfu=T_apfu;
                            end

                            if not(isempty(T_CalcOut))
                                Data.CalcOut=T_CalcOut(which_row,:);
                            else
                                Data.CalcOut=T_CalcOut;
                            end

                            if not(isempty(T_options))
                                Data.options=T_options(which_row,:);
                            else
                                Data.options=T_options;
                            end


                            p = uitreenode(p0,'Text',string(Mineral{i}),'NodeData',Data);


                            p.ContextMenu =  app.ContextMenu;

                            %                         if not(strcmp('unknown',char(CheckMinName(app.MinPlotX,Mineral{i}))))
                            %                             u_MNs= app.MinPlotX.MineralCalcFunctions.(char(CheckMinName(app.MinPlotX,Mineral{i})));
                            %                             for j = 1:length(u_MNs)
                            %                                 if any(not(ismissing(u_MNs{j})))
                            %                                     p1 = uitreenode(p,'Text',string(u_MNs{j}),'NodeData',[i,j]);
                            %
                            %                                     %   p1.ContextMenu = ContextMenu_thx;
                            %                                 end
                            %                             end
                            %                         end
                        end
                    end
                end
            else
                Mineral={};
                for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                    Mineral=unique([Mineral; app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral]);
                end

                if numel(Mineral) > 0
                    for i = 1:numel(Mineral)
                        p = uitreenode(Tree2Update,'Text',string(Mineral{i}),'NodeData',[i,0]);
                        p.ContextMenu =  app.ContextMenu;
                        for n=1:numel(app.MinPlotX.MinPlotXData.SampleNames)
                            if any(ismember(app.MinPlotX.MinPlotXData.Sample(n).Input.MetaTable.Mineral,Mineral{i}))

                                % [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo]=get_compiled_table(app.MinPlotX,n); %#ok
                                [T,T_InputData,T_MetaData,T_StrctFrm,T_apfu,T_CalcOut,T_options,T_FileInfo]=get_compiled_table(app.MinPlotX,n); %#ok

                                which_row=strcmp(T_MetaData.Mineral,Mineral{i});

                                if not(isempty(T_FileInfo))
                                    Data.FileInfo=T_FileInfo(which_row,:);
                                else
                                    Data.FileInfo=T_FileInfo;
                                end

                                if not(isempty(T_InputData))
                                    Data.InputData=T_InputData(which_row,:);
                                else
                                    Data.InputData=T_InputData;
                                end

                                if not(isempty(T_MetaData))
                                    Data.MetaData=T_MetaData(which_row,:);
                                else
                                    Data.MetaData=T_MetaData;
                                end
                                if not(isempty(T_StrctFrm))
                                    Data.StrctFrm=T_StrctFrm(which_row,:);
                                else
                                    Data.StrctFrm=T_StrctFrm;
                                end

                                if not(isempty(T_apfu))
                                    Data.apfu=T_apfu(which_row,:);
                                else
                                    Data.apfu=T_apfu;
                                end

                                if not(isempty(T_CalcOut))
                                    Data.CalcOut=T_CalcOut(which_row,:);
                                else
                                    Data.CalcOut=T_CalcOut;
                                end

                                if not(isempty(T_options))
                                    Data.options=T_options(which_row,:);
                                else
                                    Data.options=T_options;
                                end


                                p0 = uitreenode(p,'Text',string(app.MinPlotX.MinPlotXData.SampleNames{n}),'NodeData',Data);
                                p0.ContextMenu =  app.ContextMenu;

                            end
                        end
                    end
                end


            end


            if update_ts==true

                try
                    TreeNodes_new={Tree2Update.Children.Text};
                    nodes2check_P=Tree2Update.Children(contains(TreeNodes_new,selectedNodes_T(selectedNodes(:,2)==0)));
                    P_old=unique(selectedNodes(selectedNodes(:,2)==0,1),'row');
                    nodes2check_final= [];
                    for n=1:numel(nodes2check_P)
                        nodes2check_P_C=(selectedNodes_T(selectedNodes(:,2)>0&selectedNodes(:,1)==P_old(n)));
                        nodes2check_final=[nodes2check_final; nodes2check_P(n).Children(contains({nodes2check_P(n).Children.Text},nodes2check_P_C))  ];%#ok
                    end
                    Tree2Update.CheckedNodes=nodes2check_final;
                    expand(Tree2Update)
                catch ME
                    DisplayErrorNG(app.MinPlotX,ME,'UpdateTree')


                end

            end
        end


        function T_out=update_table(app)
            checkedNodes= app.Tree_2.CheckedNodes;
            if isempty(checkedNodes) || isempty(app.Tree.CheckedNodes)
                app.UITable.Data=table();
                return
            end

            ND_cell={checkedNodes.NodeData};
            %             ND_names={checkedNodes.Text};
            %             ND_names=ND_names(cellfun(@isstruct,ND_cell));

            ND_cell=ND_cell(cellfun(@isstruct,ND_cell));
            ND_struct=[ND_cell{:}];

            for n=1:numel(ND_cell)

                T=table();


                if any(ismember({app.Tree.CheckedNodes.Text}, 'FileInfo'))
                    T=[T ND_struct(n).FileInfo];%#ok
                end

                if any(ismember({app.Tree.CheckedNodes.Text}, 'Chemistry'))
                    T=[T ND_struct(n).InputData];%#ok
                end

                if any(ismember({app.Tree.CheckedNodes.Text}, 'Metadata'))


                    try

                        var_names_unique = matlab.lang.makeUniqueStrings([T.Properties.VariableNames ND_struct(n).MetaData.Properties.VariableNames]);
                        ND_struct(n).MetaData.Properties.VariableNames = var_names_unique(numel(T.Properties.VariableNames)+1:end);

                        T=[T ND_struct(n).MetaData];%#ok
                    catch
                        0
                    end
                end

                if any(ismember({app.Tree.CheckedNodes.Text}, 'apfu'))
                    T=[T ND_struct(n).apfu];%#ok
                end

                if any(ismember({app.Tree.CheckedNodes.Text}, 'Structural Formular'))
                    T=[T ND_struct(n).StrctFrm];%#ok
                end

                if any(ismember({app.Tree.CheckedNodes.Text}, 'CalcOut'))
                    T=[T ND_struct(n).CalcOut];%#ok
                end


                if any(ismember({app.Tree.CheckedNodes.Text}, 'Options'))

                    T=[T ND_struct(n).options];%#ok

                end


                T_cell{n}=T;%#ok

            end

            T_out=tablevertcat_NG(app.MinPlotX,T_cell);

            %ATH sort_T_out_here


            app.UITable.Data=T_out;
            app.UITable.ColumnName=T_out.Properties.VariableNames;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            movegui(app.ExportModuleUIFigure, 'center');
            app.MinPlotX=mainapp;

            if isempty(app.ExportDirectoryEditField.Value)
                app.ExportDirectoryEditField.Value=app.MinPlotX.WorkingDirectoryEditField.Value;
            end
            update_GUI(app)
        end

        % Button pushed function: ExportButton_3
        function ExportButtonPushed(app, event)

            T=app.UITable.Data;


            if isempty(T) || not(istable(T))
                message={'No Table found'};
                uialert(app.ExportModuleUIFigure,message,'Error');
                return
            end

            FileName=app.ExportDirectoryEditField_2.Value;
            FilePath=app.ExportDirectoryEditField.Value;
            if isempty(FileName) || isempty(FilePath)
                dlg_state=true;
            else
                dlg_state=false;
                FileName= [FileName app.FiletypeDropDown.Value];
            end
            overwrite_state=app.OverwritePreviousExportCheckBox.Value;
            delimiter_setting=app.DelimiterDropDown.Value;
            if strcmp(app.DelimiterDropDown.Value,'custom')
                delimiter_setting=app.DelimiterEditField.Value;
            end

            [newpath, newname]= ExportSelectedTable(app.MinPlotX,T,FilePath,FileName,dlg_state,overwrite_state,delimiter_setting);
            if not(strcmp(newpath,''))
                app.ExportDirectoryEditField.Value=newpath;
            end

            if not(strcmp(newname,''))
                [~, filename, ext]=fileparts(newname);
                app.ExportDirectoryEditField_2.Value=filename;

                if not(strcmpi(ext,app.FiletypeDropDown.Value))
                    app.FiletypeDropDown.Value=ext;
                end
            end

            figure(app.ExportModuleUIFigure)

        end

        % Callback function: DropDown, UpdateButton
        function UpdateButtonPushed(app, event)
            ResetTree(app,app.Tree_2)
            update_GUI(app)

        end

        % Callback function: Tree, Tree_2
        function Tree_2CheckedNodesChanged(app, event)
            update_table(app);
        end

        % Button pushed function: SelectDirButton
        function SelectDirButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            path=uigetdir(pwd,'select export directory with read/write permission');
            delete(f)
            if ~isequal(path,0)
                app.ExportDirectoryEditField.Value=path;
            end

            figure(app.ExportModuleUIFigure)
        end

        % Value changed function: DelimiterDropDown
        function DelimiterDropDownValueChanged(app, event)

            if strcmp(app.DelimiterDropDown.Value,'custom')
                app.DelimiterEditField.Visible=true;
            else
                app.DelimiterEditField.Visible=false;
            end
        end

        % Menu selected function: CheckallMenu
        function CheckallMenuSelected(app, event)
            app.Tree_2.CheckedNodes= app.Tree_2.Children;
            update_table(app);

        end

        % Menu selected function: UncheckallMenu
        function UncheckallMenuSelected(app, event)
            app.Tree_2.CheckedNodes=[];
            update_table(app);
        end

        % Button pushed function: ExcelTabExportButton
        function ExcelTabExportButtonPushed(app, event)
            app.FiletypeDropDown.Value='.xlsx';


            FileName=app.ExportDirectoryEditField_2.Value;
            FilePath=app.ExportDirectoryEditField.Value;

            if isempty(FileName) || isempty(FilePath)
            else
                FileName= [FileName app.FiletypeDropDown.Value];
            end


            if app.OverwritePreviousExportCheckBox.Value==false && exist(fullfile(FilePath,FileName),'file')>0
                d=dir(FilePath);

                [~, n, ext]=fileparts(fullfile(path,FileName));

                [~, nms, cc]=fileparts({d.name});

                FileName=matlab.lang.makeUniqueStrings([nms(ismember(cc,ext)),{n}]);
                FileName=[FileName{end} ext] ;
            end


            for k=1:numel(app.Tree_2.Children)
                app.Tree_2.CheckedNodes=app.Tree_2.Children(k);
                T=update_table(app);
                if not(isempty(T))  && istable(T)
                    sheetname_to_save=matlab.lang.makeValidName(app.Tree_2.Children(k).Text);
                    if numel(sheetname_to_save)>31
                        sheetname_to_save=sheetname_to_save(1:29);

                        if exist(fullfile(FilePath,FileName),'file')>0
                            sheets=sheetnames(fullfile(FilePath,FileName));
                        else
                            sheets='';
                        end

                        if ismember(sheetname_to_save,sheets)
                            sheetnames_unique = matlab.lang.makeUniqueStrings(sheets,sheetname_to_save);
                            sheetname_to_save=sheetnames_unique(end);
                        end
                    end

                    writetable(T,fullfile(FilePath,FileName),'Sheet',sheetname_to_save,'WriteVariableNames',true);
                end
            end
        end

        % Value changed function: FiletypeDropDown
        function FiletypeDropDownValueChanged(app, event)
            %             value = app.FiletypeDropDown.Value;
            %             if strcmp(value,'.xlsx')
            %             app.ExcelTabExportButton.Visible=true;
            %             else
            %             app.ExcelTabExportButton.Visible=false;
            %             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExportModuleUIFigure = uifigure('Visible', 'off');
            app.ExportModuleUIFigure.Name = 'ExportModule';
            app.GridLayout2 = uigridlayout(app.ExportModuleUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.DataTable = uitab(app.TabGroup);
            app.DataTable.Title = 'DataTable';
            app.GridLayout3 = uigridlayout(app.DataTable);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Tree = uitree(app.GridLayout3, 'checkbox');
            app.FileInfoNode = uitreenode(app.Tree);
            app.FileInfoNode.Text = 'FileInfo';
            app.ChemistryNode = uitreenode(app.Tree);
            app.ChemistryNode.Text = 'Chemistry';
            app.MetadataNode = uitreenode(app.Tree);
            app.MetadataNode.Text = 'Metadata';
            app.apfuNode = uitreenode(app.Tree);
            app.apfuNode.Text = 'apfu';
            app.StructuralFormularNode = uitreenode(app.Tree);
            app.StructuralFormularNode.Text = 'Structural Formular';
            app.CalcOutNode = uitreenode(app.Tree);
            app.CalcOutNode.Text = 'CalcOut';
            app.OptionsNode = uitreenode(app.Tree);
            app.OptionsNode.Text = 'Options';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.FileInfoNode, app.ChemistryNode, app.MetadataNode, app.apfuNode];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.Tree_2 = uitree(app.GridLayout3, 'checkbox');
            % Assign Checked Nodes
            app.Tree_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.DropDown = uidropdown(app.GridLayout3);
            app.DropDown.Items = {'Sort By File', 'Sort By Mineral'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.DropDown.Value = 'Sort By File';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.GridLayout = uigridlayout(app.ExportTab);
            app.DelimiterEditField = uieditfield(app.GridLayout, 'text');
            app.DelimiterEditField.Visible = 'off';
            app.DelimiterEditField.Value = ',';
            app.SelectDirButton = uibutton(app.GridLayout, 'push');
            app.SelectDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirButtonPushed, true);
            app.SelectDirButton.Text = 'Select Dir';
            app.ExportDirectoryEditFieldLabel = uilabel(app.GridLayout);
            app.ExportDirectoryEditFieldLabel.Text = 'Export Directory';
            app.ExportDirectoryEditField = uieditfield(app.GridLayout, 'text');
            app.ExportDirectoryEditFieldLabel_2 = uilabel(app.GridLayout);
            app.ExportDirectoryEditFieldLabel_2.Text = 'FileName';
            app.ExportDirectoryEditField_2 = uieditfield(app.GridLayout, 'text');
            app.ExportDirectoryEditField_2.Value = 'MinPlot_Export';
            app.OverwritePreviousExportCheckBox = uicheckbox(app.GridLayout);
            app.OverwritePreviousExportCheckBox.Text = 'Overwrite Previous Export';
            app.FiletypeDropDownLabel = uilabel(app.GridLayout);
            app.FiletypeDropDownLabel.Text = 'Filetype';
            app.FiletypeDropDown = uidropdown(app.GridLayout);
            app.FiletypeDropDown.Items = {'.csv', '.xlsx', '.txt'};
            app.FiletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletypeDropDownValueChanged, true);
            app.FiletypeDropDown.Value = '.csv';
            app.DelimiterDropDownLabel = uilabel(app.GridLayout);
            app.DelimiterDropDownLabel.Text = 'Delimiter';
            app.DelimiterDropDown = uidropdown(app.GridLayout);
            app.DelimiterDropDown.Items = {',', ';', 'tab', 'space', 'Custom'};
            app.DelimiterDropDown.ItemsData = {',', ';', '\t', ' ', 'custom'};
            app.DelimiterDropDown.ValueChangedFcn = createCallbackFcn(app, @DelimiterDropDownValueChanged, true);
            app.DelimiterDropDown.Value = ',';
            app.ExportButton_3 = uibutton(app.GridLayout, 'push');
            app.ExportButton_3.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton_3.Text = 'Export';
            app.ExcelTabExportButton = uibutton(app.GridLayout, 'push');
            app.ExcelTabExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExcelTabExportButtonPushed, true);
            app.ExcelTabExportButton.Text = 'Excel Tab Export';
            app.ContextMenu = uicontextmenu(app.ExportModuleUIFigure);
            app.CheckallMenu = uimenu(app.ContextMenu);
            app.CheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @CheckallMenuSelected, true);
            app.CheckallMenu.Text = 'Check all';
            app.UncheckallMenu = uimenu(app.ContextMenu);
            app.UncheckallMenu.MenuSelectedFcn = createCallbackFcn(app, @UncheckallMenuSelected, true);
            app.UncheckallMenu.Text = 'Uncheck all';
            % Assign app.ContextMenu
            app.Tree_2.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.ExportModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MinPlotXExportModule(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExportModuleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExportModuleUIFigure)
        end
    end
end