classdef SpotVisMinPlotX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpotVisModuleUIFigure         matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        SpotTransparencySlider        matlab.ui.control.Slider
        nEditField                    matlab.ui.control.NumericEditField
        nEditFieldLabel               matlab.ui.control.Label
        ListBox_2                     matlab.ui.control.ListBox
        ListBox                       matlab.ui.control.ListBox
        AdvancedSettingsCheckBox      matlab.ui.control.CheckBox
        ListBox_Col2Plot              matlab.ui.control.ListBox
        MapGenDropDown                matlab.ui.control.DropDown
        AutoUpdateButton              matlab.ui.control.StateButton
        ResetButton_3                 matlab.ui.control.Button
        Button_2                      matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        EditField_climMax             matlab.ui.control.NumericEditField
        EditField_climMin             matlab.ui.control.NumericEditField
        SpotsizeSpinner               matlab.ui.control.Spinner
        SpotsizeSpinnerLabel          matlab.ui.control.Label
        IdEditField                   matlab.ui.control.NumericEditField
        IdEditFieldLabel              matlab.ui.control.Label
        Spinner                       matlab.ui.control.Spinner
        BEditField                    matlab.ui.control.NumericEditField
        BEditFieldLabel               matlab.ui.control.Label
        GEditField                    matlab.ui.control.NumericEditField
        GEditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        REditFieldLabel               matlab.ui.control.Label
        InterpolationButton           matlab.ui.control.StateButton
        SampleDropDown                matlab.ui.control.DropDown
        REditField_2                  matlab.ui.control.NumericEditField
        REditField_2Label             matlab.ui.control.Label
        GEditField_2                  matlab.ui.control.NumericEditField
        GEditField_2Label             matlab.ui.control.Label
        BEditField_2                  matlab.ui.control.NumericEditField
        BEditField_2Label             matlab.ui.control.Label
        LabelColorDropDown            matlab.ui.control.DropDown
        LabelColorDropDownLabel       matlab.ui.control.Label
        LabelsButton                  matlab.ui.control.StateButton
        SpotColorDropDown             matlab.ui.control.DropDown
        SpotColorDropDownLabel        matlab.ui.control.Label
        SpotsButton                   matlab.ui.control.StateButton
        ImageButton                   matlab.ui.control.StateButton
        ListBox_img                   matlab.ui.control.ListBox
        FigHistLive                   matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout_2                  matlab.ui.container.GridLayout
        Options_LowerColor            matlab.ui.control.DropDown
        Options_InvertColormap        matlab.ui.control.CheckBox
        Options_ColormapDropDown      matlab.ui.control.DropDown
        ColormapDropDownLabel         matlab.ui.control.Label
        Options_LogColormap           matlab.ui.control.CheckBox
        Options_ColormapResEditField  matlab.ui.control.NumericEditField
        Options_UpperColor            matlab.ui.control.DropDown
        Options_UpperCheckBox         matlab.ui.control.CheckBox
        Options_LowerCheckBox         matlab.ui.control.CheckBox
        ColorMapPreview               matlab.ui.control.UIAxes
        ResetMask                     matlab.ui.control.Image
        InvertMask                    matlab.ui.control.Image
        UnmaskSelection               matlab.ui.control.Image
        MaskSelection                 matlab.ui.control.Image
        UITable_side                  matlab.ui.control.Table
        UIAxes_big                    matlab.ui.control.UIAxes
        GroupsPanel                   matlab.ui.container.Panel
        GridLayout5                   matlab.ui.container.GridLayout
        DropDown                      matlab.ui.control.DropDown
        GroupMeanButton               matlab.ui.control.StateButton
        GroupProfilesButton           matlab.ui.control.StateButton
        SelectinTableButton           matlab.ui.control.Button
        ToggleAllButton               matlab.ui.control.Button
        GroupListBox                  matlab.ui.control.ListBox
        ntoplotEditField              matlab.ui.control.NumericEditField
        ntoplotEditFieldLabel         matlab.ui.control.Label
        NormalizeSelectionCheckBox    matlab.ui.control.CheckBox
        NormalizeValuesCheckBox       matlab.ui.control.CheckBox
        logCheckBox                   matlab.ui.control.CheckBox
        EditField_xlimMax             matlab.ui.control.NumericEditField
        ResetButton_2                 matlab.ui.control.Button
        MeanButton                    matlab.ui.control.StateButton
        EditField_xlimMin             matlab.ui.control.NumericEditField
        FigXlimLive                   matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        EditMenu                      matlab.ui.container.Menu
        ExportTableMenu               matlab.ui.container.Menu
        Classification2GroupsMenu     matlab.ui.container.Menu
        PlotProfileMenu               matlab.ui.container.Menu
        ExtractGroupsMenu             matlab.ui.container.Menu
        DevMenu                       matlab.ui.container.Menu
        Profile2Menu                  matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        AddImageMenu                  matlab.ui.container.Menu
        EditImageNameMenu             matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        mainapp % Description
        hVerticalLines
        hLineToDrag
        clickbuffer=0.05 % Description
        selected_sample
        selected_image=1
        selected_image_group
        n_spots
        climits
        DataTable
        CX_Lim_state
        exportpath='';
        calculation_id=1
        reset_value=false

        ColorMaps
        ColorMapValues
        ColorScale
        newnew

    end

    methods (Access = private)

        function update_lines(app)
            delete(findobj(app.FigXlimLive.Children,'Type','ConstantLine'));
            delete(findobj(app.FigHistLive.Children,'Type','ConstantLine'));

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                app.hVerticalLines(1) = xline(app.FigXlimLive,app.EditField_xlimMax.Value,'Color','red',"LineWidth",3);
                set(app.hVerticalLines(1), 'hittest', 'off')

                app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMax.Value,'Color','k',"LineWidth",3)...
                    xline(app.FigHistLive, app.EditField_climMin.Value,'Color','k',"LineWidth",3)];
                set(app.hVerticalLines(3:4), 'hittest', 'off')


            else
                app.hVerticalLines(1:2) = [xline(app.FigXlimLive,app.EditField_xlimMin.Value,'Color','red',"LineWidth",3)...
                    xline(app.FigXlimLive, app.EditField_xlimMax.Value,'Color','blue',"LineWidth",3)];
                set(app.hVerticalLines(1:2), 'hittest', 'off')

                app.hVerticalLines(3:4) = [xline(app.FigHistLive,app.EditField_climMax.Value,'Color','k',"LineWidth",3)...
                    xline(app.FigHistLive, app.EditField_climMin.Value,'Color','k',"LineWidth",3)];
                set(app.hVerticalLines(3:4), 'hittest', 'off')
            end
        end

        function update_UI(app)
            %% 1) update the list of Samples
            if   app.SampleDropDown.Visible==true
                SampleNames=app.mainapp.MinPlotXData.SampleNames;
                selections=1:numel(SampleNames);

                test=false(1,numel(SampleNames));
                for k=1:numel(SampleNames)
                    test(k)=any(strcmp('x_pos',app.mainapp.MinPlotXData.Sample(k).Input.MetaTable.Properties.VariableNames)) && any(strcmp('y_pos',app.mainapp.MinPlotXData.Sample(k).Input.MetaTable.Properties.VariableNames));
                end

                if sum(test)==0
                    message={'No spot position data found.' 'To use this module add columns x_pos and y_pos to the input data.'};
                    uialert(app.MinPlotXGUI,message,'No Positon Data Found','Icon','warning');
                    delete(app)
                end
                app.SampleDropDown.Items=SampleNames(test);
                app.SampleDropDown.ItemsData=selections(test);

                try

                    app.SampleDropDown.Value=app.selected_sample;
                catch
                    app.selected_sample=app.SampleDropDown.ItemsData(1);
                    app.SampleDropDown.Value=app.selected_sample;

                end
                app.IdEditField.Value=app.selected_sample;


                update_ListBox(app.mainapp,app.ListBox_Col2Plot,app.selected_sample)

                % T=get_compiled_table(app.mainapp,app.selected_sample);
                % ColNames=T.Properties.VariableNames(not(any(cellfun(@isnumeric,table2cell(T)))|any(cellfun(@islogical,table2cell(T)))));


            else

                ColNames=app.DataTable.Data.Properties.VariableNames((any(cellfun(@isnumeric,table2cell(app.DataTable.Data)))));
                app.ListBox_Col2Plot.Items=ColNames;
            end
            %% 2) check for meta table and n specs
            if   app.SampleDropDown.Visible==true
                if  isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample),'Input') && not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input))&&...
                        isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'MetaTable') && ...
                        not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable))&& ...
                        istable(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable)

                    meta_tbl=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable;

                else
                    meta_tbl=table();
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable=meta_tbl;
                end

            else
                meta_tbl=app.DataTable.Data;
            end


            if app.AdvancedSettingsCheckBox.Value==false && not(isempty(app.ListBox.Value)) && not(isempty(app.ListBox_2.Value))&& ismember(app.ListBox.Value,meta_tbl.Properties.VariableNames)
                [~,~,ic]=unique(meta_tbl.(char(app.ListBox.Value))(ismember(meta_tbl.(char(app.ListBox.Value)),app.ListBox_2.Value)),'stable');
                meta_tbl.Group(:)=ic;

                if   app.SampleDropDown.Visible==true
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable=meta_tbl;
                end
            end

            if not(any(strcmp('Group',meta_tbl.Properties.VariableNames)))
                meta_tbl.Group(:)=1;
            end

            if not(any(strcmp('Fileindex',meta_tbl.Properties.VariableNames)))
                meta_tbl.Fileindex(:)=1:size(meta_tbl(:,1),1);
                if   app.SampleDropDown.Visible==true
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable=meta_tbl;
                end
            end


            %% 3) check for groups and types
            %             if any(strcmp('Group',meta_tbl.Properties.VariableNames))
            %                 app.GroupListBox.Items=string((unique(meta_tbl.Group)));
            %                 app.GroupListBox.ItemsData=unique(meta_tbl.Group);
            %
            %                 if numel(unique(meta_tbl.Group))>1
            %                     vis_state=true;
            %                     app.GroupListBox.Value=app.GroupListBox.ItemsData;
            %
            %                 else
            %                     vis_state=false;
            %                 end
            %
            %             else
            %                 app.GroupListBox.Items={};
            %                 app.GroupListBox.ItemsData=[];
            %                 vis_state=false;
            %             end
            %             %this part needs to move in update plots function start
            %               app.GroupsPanel.Visible=vis_state;
            %             %this part needs to move in update plots function end


            %% 4) check if xy coordinates
            if any(strcmp('Group',meta_tbl.Properties.VariableNames)) &&  (any(strcmp('x_pos',meta_tbl.Properties.VariableNames))  && any(strcmp('y_pos',meta_tbl.Properties.VariableNames)))
                app.SpotsButton.Value=true;
                app.SpotsButton.Enable=true;

                app.InterpolationButton.Enable=true;
                %   app.LabelsButton.Value=true;
                app.LabelsButton.Enable=true;
            else
                app.SpotsButton.Value=false;
                app.SpotsButton.Enable=false;

                app.InterpolationButton.Enable=false;
                app.InterpolationButton.Value=false;

                app.LabelsButton.Value=false;
                app.LabelsButton.Enable=false;
            end

            %% 5) update the list of Images
            if app.SampleDropDown.Visible==true
                if isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'ImageGroup_Id')
                    app.selected_image_group=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).ImageGroup_Id;
                else
                    app.selected_image_group=[];
                end
                if not(isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample),'Images')) || not(isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Images,'Images')) || not(isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images,'ImageNames')) || isempty(app.selected_image_group) || not(isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group),'ImageNames')) || isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageNames)
                    app.ListBox_img.Items={};
                    app.ListBox_img.Visible=false;
                    app.ImageButton.Value=false;
                    app.ImageButton.Enable=false;
                    app.selected_image=[];
                else
                    Image_Ids=[app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(not(cellfun(@isempty,{app.mainapp.MinPlotXData.Sample(app.selected_sample).Input.ImageGroup_Id}))).ImageGroup_Id];
                    app.selected_image_group=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).ImageGroup_Id;

                    Image_Names=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageNames;


                    try
                        if isempty(Image_Names)
                            disp('no image detected')
                            app.ListBox_img.Visible=false;
                            app.ImageButton.Enable=false;
                        else
                            app.ListBox_img.Visible=true;
                            app.ImageButton.Enable=true;
                            app.ListBox_img.Items=Image_Names;
                            app.ListBox_img.ItemsData=1:numel(Image_Names);
                            app.ListBox_img.Value=Image_Ids(1);
                            app.selected_image=app.ListBox_img.Value;

                        end
                    catch ME
                        ME
                        Me.stack.line
                        Me.stack.file
                        disp('error in image list generation')
                    end

                end
            end
            %% 6) update the table
            update_table(app)

            %% 7) update visible status of axis the plots

            update_plots(app);
            ResetButton_2Pushed(app)

        end

        function update_table(app)
            if app.SampleDropDown.Visible==true
                meta_tbl=get_compiled_table(app.mainapp,app.selected_sample);
            else
                meta_tbl=app.DataTable.Data;
            end
            % meta_tbl=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable;

            %ATH SampleName? Spotname? AnalysisName?
            try
                AnalysisName=meta_tbl.AnalysisName;
            catch
                meta_tbl.AnalysisName(:)={'Unknown'};

            end

            if app.SampleDropDown.Visible==false
                mask=meta_tbl.Active;
            elseif app.SampleDropDown.Visible==true && isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'Mask') && not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask))
                mask=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask;

            else
                mask=true(size(meta_tbl,1),1);
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask=mask;
            end

            Value2Plot = get_val2plot(app);

            if not(ismember('Group',meta_tbl.Properties.VariableNames))
                meta_tbl.Group(:)=1;
            end

            app.UITable_side.Data=table(mask,meta_tbl.Fileindex,meta_tbl.AnalysisName,Value2Plot,meta_tbl.Group,VariableNames={'active','Id','AnalysisName','Value','Group'}); % Copy this

            if app.SampleDropDown.Visible==true && isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'MaskComment') && not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MaskComment))
                app.UITable_side.Data.Comment(:)=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MaskComment;

            else
                app.UITable_side.Data.Comment(:)={''};
                if app.SampleDropDown.Visible==true
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MaskComment(:)={''};
                end
            end


            if any(strcmp('Group',meta_tbl.Properties.VariableNames)) &&  any(strcmp('x',meta_tbl.Properties.VariableNames))  && any(strcmp('y',meta_tbl.Properties.VariableNames))
                app.UITable_side.Data.x_pos(:)=meta_tbl.x;
                app.UITable_side.Data.y_pos(:)=meta_tbl.y;
            elseif any(strcmp('Group',meta_tbl.Properties.VariableNames)) &&  any(strcmp('x_pos',meta_tbl.Properties.VariableNames))  && any(strcmp('y_pos',meta_tbl.Properties.VariableNames))
                app.UITable_side.Data.x_pos(:)=meta_tbl.x_pos;
                app.UITable_side.Data.y_pos(:)=meta_tbl.y_pos;
            else
                app.UITable_side.Data.x_pos(:)=0;
                app.UITable_side.Data.y_pos(:)=0;
            end

            app.UITable_side.ColumnName=app.UITable_side.Data.Properties.VariableNames;

            if app.SampleDropDown.Visible==true && isempty(meta_tbl)
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable=meta_tbl;
            end
            %             catch
            %                 message=('Error Table update failed - Different number of Spectra in Measurements');
            %                 uialert(app.SpotVisModuleUIFigure,message,'Error');
            %             end
        end

        function axis2plot_map=update_plots(app,no_update)
            %             axis2plot_map
            %             axis2plot_profile

            axis2plot_map=app.UIAxes_big;


            if exist("no_update","var")&&no_update==true
                return
            end


            cla(app.UIAxes_big)


            app.UIAxes_big.Visible=false;


            app.UIAxes_big.Toolbar.Visible=false;


            if not(isempty(app.UIAxes_big.Colorbar))
                app.UIAxes_big.Colorbar.Visible=false;
            end

            axis2plot_map.Visible=true;
            axis2plot_map.Toolbar.Visible=true;

            axis(app.UIAxes_big, 'normal')


            plot_image(app,axis2plot_map)
            plot_spots(app,axis2plot_map)
        end

        function plot_image(app,axis2plot)
            if isstruct(axis2plot)
                return
            end


            if app.ImageButton.Value==true && isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample),'Images') && isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Images,'Images') && not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group)))
                for k= 1:numel(app.selected_image)
                    img=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).Image{app.selected_image(k)};
                    if isempty(img)
                        message='Error No Image found';
                        % uialert(app.SpotVisModuleUIFigure,message,'Error');
                        disp(message);
                        return
                    end

                    ImageLimits=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageLimits{app.selected_image(k)};
                    if isempty(size(ImageLimits)) || not(isequal(size(ImageLimits),[2 2]))
                        message='Error No Image Info found';
                        uialert(app.SpotVisModuleUIFigure,message,'Error');
                        return
                    end

                    imagesc(axis2plot,flipud(img),'XData', ImageLimits(1,:), 'YData',ImageLimits(2,:));
                    axis(axis2plot, 'equal')
                end
            end
        end


        function plot_spots(app,axis2plot)
            if isstruct(axis2plot)
                return
            end


            if app.SpotsButton.Value==true || app.InterpolationButton.Value==true
                if   app.SampleDropDown.Visible==true


                    meta_tbl=get_compiled_table(app.mainapp,app.selected_sample);

                    if isempty(meta_tbl) || not(istable(meta_tbl)) || not(any(strcmp('Fileindex',meta_tbl.Properties.VariableNames)))
                        message='Error No Metadata found';
                        n_specs=size(meta_tbl(:,1),1);
                        meta_tbl=table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});
                        %app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable=meta_tbl;
                    else
                        meta_tbl=meta_tbl(meta_tbl.Fileindex>0,:);
                    end

                    if isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'Mask') && not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask))
                        mask=logical(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask);
                    else
                        mask=true(size(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).data(:,1),1),1);
                        app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask=mask;
                    end

                else
                    meta_tbl=app.DataTable.Data;
                    mask=app.DataTable.Data.Active;
                end

                if app.ListBox_2.Visible==true && not(isempty(app.ListBox_2.Value))
                    mask=mask==true & ismember(meta_tbl.(char(app.ListBox.Value)),app.ListBox_2.Value);
                end

                if app.AdvancedSettingsCheckBox.Value==false && (isempty(app.ListBox.Value)||isempty(app.ListBox_2.Value)) &&  any(strcmp('x_pos',meta_tbl.Properties.VariableNames))  && any(strcmp('y_pos',meta_tbl.Properties.VariableNames))
                    [~,~,ic]=unique(meta_tbl.(char(app.ListBox.Value))(ismember(meta_tbl.(char(app.ListBox.Value)),app.ListBox_2.Value)),'stable');

                    meta_g=ic;
                    meta_x=meta_tbl.x_pos;
                    meta_y=meta_tbl.y_pos;
                else

                    if  any(strcmp('x_pos',meta_tbl.Properties.VariableNames))  && any(strcmp('y_pos',meta_tbl.Properties.VariableNames))
                        meta_x=meta_tbl.x_pos;
                        meta_y=meta_tbl.y_pos;
                        if any(strcmp('Group',meta_tbl.Properties.VariableNames))
                            meta_g=meta_tbl.Group;
                        else

                            meta_g=ones(size(meta_y));
                        end
                    else
                        meta_x=[];
                        meta_g=[];
                        meta_y=[];
                    end
                end

                if app.InterpolationButton.Value==true


                    Value2Plot = get_val2plot(app);
                    x=linspace(min(meta_x(mask)),max(meta_x(mask)),ceil(sqrt(numel(Value2Plot(mask)))));
                    y=linspace(min(meta_y(mask)),max(meta_y(mask)),ceil(sqrt(numel(Value2Plot(mask)))));
                    [Xq,Yq] = meshgrid(x,y);
                    Value2Plot(Value2Plot==0)=nan;
                    F=scatteredInterpolant(meta_x(mask),meta_y(mask),Value2Plot(mask));
                    vq1 = F(Xq,Yq);


                    imagesc(axis2plot,vq1,'XData',[min(meta_x(mask)),max(meta_x(mask))],'YData',[min(meta_y(mask)),max(meta_y(mask))],AlphaData=app.SpotTransparencySlider.Value/100)
                    %   contourf(app.UIAxes_big,Value2Plot,'XData',meta_x,'YData',meta_y,AlphaData=app.SpotTransparencySlider.Value/100)
                    % contourf(app.UIAxes_big,meta_x,meta_y,Value2Plot,LineStyle="none")
                    xlim(axis2plot,'auto')
                    ylim(axis2plot,'auto')
                    axis(axis2plot, 'equal')
                    colorbar(axis2plot)

                end
                if app.SpotsButton.Value==true
                    switch app.SpotColorDropDown.Value
                        case {'Black','White','Red','Green','Blue','Custom'}
                            switch app.SpotColorDropDown.Value
                                case 'Black'
                                    c=[0 0 0];
                                case 'White'
                                    c=[1 1 1];
                                case 'Red'
                                    c=[1 0 0];
                                case 'Green'
                                    c=[0 1 0];
                                case 'Blue'
                                    c=[0 0 1];
                                case 'Custom'
                                    c=[app.REditField.Value app.GEditField.Value app.BEditField.Value];
                            end
                            plot(axis2plot,meta_x(mask),meta_y(mask),MarkerFaceColor=c,MarkerSize=15,LineStyle="none",Marker="o")
                        otherwise
                            switch app.SpotColorDropDown.Value
                                case 'Value'
                                    if not(exist('Value2Plot','var')) || not(numel(meta_x(mask))==numel(Value2Plot))
                                        Value2Plot = get_val2plot(app);
                                    end
                                    sz=Value2Plot;
                                    colorbar(axis2plot)
                                case 'Group'
                                    mask=mask&(ismember(app.UITable_side.Data.Group,app.GroupListBox.Value));
                                    sz=meta_g;

                            end

                            if app.GroupsPanel.Visible==true
                                if numel(app.GroupListBox.Value)>0
                                    mask=mask==true & ismember(app.UITable_side.Data.Group,app.GroupListBox.Value);
                                    caxis(axis2plot,[min(meta_g,'omitnan') max(meta_g,'omitnan')]);

                                end
                            end

                            if not(numel(meta_x(mask))==numel(sz(mask)))
                                message=('Error Table update failed - Different number of Spectra in Measurements');
                                uialert(app.SpotVisModuleUIFigure,message,'Error');

                            else

                                %                                         try
                                %                                             px_size=app.mainapp.MinPlotXData.Sample(app.selected_sample).Meta(app.selected_sample).Aperture;
                                %                                         catch
                                %                                             px_size=50;
                                %                                         end
                                %                                         Axis2Plot=update_plots(app,true);
                                %
                                %                                         originalUnits = Axis2Plot.Units;
                                %                                         Axis2Plot.Units = 'Points';
                                %                                         axSizePix = Axis2Plot.Position(3:4);
                                %                                         Axis2Plot.Units = originalUnits;
                                %
                                %                                         pixPerUnit = axSizePix ./ [range(xlim(Axis2Plot)),range(ylim(Axis2Plot))];  %pixels per unit
                                %                                         pxsz=ceil(max(pixPerUnit)*px_size)^2;
                                %
                                %                                         app.SpotsizeSpinner.Value=pxsz;


                                scatter(axis2plot,meta_x(mask)',meta_y(mask)',app.SpotsizeSpinner.Value,sz(mask)',"filled",'MarkerEdgeAlpha',1,'MarkerFaceAlpha',app.SpotTransparencySlider.Value/100,Marker='square')
                                xlim(axis2plot,'auto')
                                ylim(axis2plot,'auto')
                                axis(axis2plot, 'equal')

                            end

                    end
                    update_plot_cmap(app)
                end
            end

            if app.LabelsButton.Value==true

                if   app.SampleDropDown.Visible==true


                    meta_tbl=get_compiled_table(app.mainapp,app.selected_sample);

                    if isempty(meta_tbl) || not(istable(meta_tbl)) || not(any(strcmp('Fileindex',meta_tbl.Properties.VariableNames)))
                        message='Error No Metadata found';
                        n_specs=size(meta_tbl(:,1),1);
                        meta_tbl=table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});table((1:n_specs)',ones(n_specs,1),'VariableNames',{'Fileindex','Group'});
                        %app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable=meta_tbl;
                    else
                        meta_tbl=meta_tbl(meta_tbl.Fileindex>0,:);
                    end

                    if isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'Mask') && not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask))
                        mask=logical(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask);
                    else
                        mask=true(size(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).data(:,1),1),1);
                        app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask=mask;
                    end

                else
                    meta_tbl=app.DataTable.Data;
                    mask=app.DataTable.Data.Active;
                end

                if app.ListBox_2.Visible==true && not(isempty(app.ListBox_2.Value))
                    mask=mask==true & ismember(meta_tbl.(char(app.ListBox.Value)),app.ListBox_2.Value);
                end

                if app.AdvancedSettingsCheckBox.Value==false && (isempty(app.ListBox.Value)||isempty(app.ListBox_2.Value)) &&  any(strcmp('x_pos',meta_tbl.Properties.VariableNames))  && any(strcmp('y_pos',meta_tbl.Properties.VariableNames))
                    [~,~,ic]=unique(meta_tbl.(char(app.ListBox.Value))(ismember(meta_tbl.(char(app.ListBox.Value)),app.ListBox_2.Value)),'stable');

                    meta_g=ic;
                    meta_x=meta_tbl.x_pos;
                    meta_y=meta_tbl.y_pos;
                else

                    if  any(strcmp('x_pos',meta_tbl.Properties.VariableNames))  && any(strcmp('y_pos',meta_tbl.Properties.VariableNames))
                        meta_x=meta_tbl.x_pos;
                        meta_y=meta_tbl.y_pos;
                        if any(strcmp('Group',meta_tbl.Properties.VariableNames))
                            meta_g=meta_tbl.Group;
                        else

                            meta_g=ones(size(meta_y));
                        end
                    else
                        meta_x=[];
                        meta_g=[];
                        meta_y=[];
                    end
                end


                meta_id=app.UITable_side.Data.Id(mask);
                meta_id_s=string(sprintfc('%d',meta_id))';

                %                 datapoints=meta_id;
                %
                %                 if numel(meta_id)>=300
                %                     samplepoints=sort(randperm(numel(datapoints),300));
                %                     samplepoints=datapoints(samplepoints);
                %                 else
                %                     samplepoints=datapoints;
                %                 end
                switch app.LabelColorDropDown.Value
                    case 'Black'
                        c=[0 0 0];
                    case 'White'
                        c=[1 1 1];
                    case 'Red'
                        c=[1 0 0];
                    case 'Green'
                        c=[0 1 0];
                    case 'Blue'
                        c=[0 0 1];
                    case 'Custom'
                        c=[app.REditField_2.Value app.GEditField_2.Value app.BEditField_2.Value];
                end

                if not(isempty(meta_x(mask))) && not(isempty(meta_y(mask))) && not(isempty(meta_id_s))
                    text(axis2plot,meta_x((mask)),meta_y((mask)),meta_id_s,HorizontalAlignment="center",VerticalAlignment='middle',Color=c)
                end
            end
        end

        function [x,y,z,profile_distance]=get_profiles_to_plot(app,sample,mask_state,value)
            [x,y,z,profile_distance]=get_profiledata(app.mainapp,sample,value);

            if isfield(app.mainapp.MinPlotXData.Sample(sample).Input(1),'Mask') && not(isempty(app.mainapp.MinPlotXData.Sample(sample).Input(1).Mask)) && mask_state == true
                mask=app.mainapp.MinPlotXData.Sample(sample).Input(1).Mask';
                if app.GroupsPanel.Visible==true
                    if numel(app.GroupListBox.Value)>0
                        mask=mask==true & ismember(app.UITable_side.Data.Group,app.GroupListBox.Value)';
                    end
                end
                if not(any(mask))
                    x=nan;
                    y=nan;
                    z=nan;
                    profile_distance=nan;
                    disp('no mask')
                    return
                end
                z=z(mask,:);
            end
        end


        function  update_FigHistLive(app)
            cla(app.FigHistLive)

            if not(isempty(app.ListBox_Col2Plot.Value))

                Value2Plot = get_val2plot(app);

                num_bars=20;
                [N, EDGES] = histcounts(Value2Plot(:),num_bars); %// use two-output version of hist to get values
                EDGES=linspace(EDGES(1),EDGES(end),num_bars);
                a_h=area(app.FigHistLive,EDGES,N,'FaceColor',[0.8 0.8 0.8],'LineWidth',1);
                set(a_h, 'hittest', 'off');
                %EDGES=EDGES(2:end-1);
                xlimits_test = [min(EDGES) max(EDGES)];
                app.FigHistLive.XLim=xlimits_test;

                if app.reset_value==true ||app.EditField_climMax.Value > max(xlimits_test) || app.EditField_climMin.Value < min(xlimits_test)
                    app.climits=xlimits_test;
                    app.EditField_climMin.Value=app.climits(1);
                    app.EditField_climMax.Value=app.climits(2);
                    PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);
                    app.reset_value=false;
                else
                    app.climits(1)=min([app.EditField_climMin.Value app.EditField_climMax.Value]);
                    app.climits(2)=max([app.EditField_climMin.Value app.EditField_climMax.Value]);
                end

            end
            update_lines(app)

        end


        function reset_int_x(app)
            [~,~,~,x]=get_profiles_to_plot(app,app.selected_sample,true,app.ListBox_Col2Plot.Value);

            app.EditField_xlimMax.Value=max(x(not(isnan(x))));
            app.EditField_xlimMin.Value=min(x(not(isnan(x))));

            EditField_xlimMaxValueChanged(app)

        end


        function reset_zoom_pan(app)
            try
                zoom(app.UIAxes_big,'off')
                pan(app.UIAxes_big,'off')

            catch ME
                disp('Error - SpotVisModule reset zoom / pan')
                disp(ME)
            end

        end

        function results = get_value(app,value)
            if ~exist('value','var') || isempty(value)
                value = app.ListBox_img.Value;
            end
            T=get_compiled_table(app.mainapp,app.selected_sample);

            if isempty(value)
                value=1;
            end


            results=table2array(T(:,value));
        end

        function Value2Plot = get_val2plot(app)
            if   app.SampleDropDown.Visible==true
                Value2Plot=get_value(app,app.ListBox_Col2Plot.Value);
            elseif app.SampleDropDown.Visible==false && not(isempty(app.ListBox_Col2Plot.Value))

                Value2Plot=app.DataTable.Data.(char(app.ListBox_Col2Plot.Value));

            else
                Value2Plot=nan(size(app.DataTable.Data,1),1);
            end
        end

        function [ColorMap] = PlotMap_UpdateColormap(app,Resolution,~)
            if not(exist('Resolution','var')) || isempty(Resolution)
                Resolution=app.Options_ColormapResEditField.Value;
            end


            SelColorMap = find(ismember(app.Options_ColormapDropDown.Items,app.Options_ColormapDropDown.Value));

            if app.Options_InvertColormap.Value
                ColorData =app.mainapp.ColorMaps(SelColorMap).Code;
            else
                ColorData =flip(app.mainapp.ColorMaps(SelColorMap).Code);
            end
            if Resolution==1
                ColorMap = [0,0,0];
            else
                Step = (Resolution-1)/(size(ColorData,1)-1);
                X = 1:Step:Resolution;


                ColorMap = zeros(length(1:Resolution),size(ColorData,2));
                for i = 1:size(ColorData,2)
                    ColorMap(:,i) = interp1(X',ColorData(:,i),1:Resolution);
                end

                % upper & lower
                if app.Options_LowerCheckBox.Value
                    if isequal(app.Options_LowerColor.Value,'Black')
                        ColorMap(1,:) = [0,0,0];
                    else
                        ColorMap(1,:) = [1,1,1];
                    end
                end
                if app.Options_UpperCheckBox.Value
                    if isequal(app.Options_UpperColor.Value,'Black')
                        ColorMap(end,:) = [0,0,0];
                    else
                        ColorMap(end,:) = [1,1,1];
                    end
                end
            end
            %log
            %linlogchange start

            app.ColorScale = 'linear';

            if not(exist('no_update','var'))
                app.ColorMapValues = ColorMap;
                imagesc(app.ColorMapPreview,[(1:app.Options_ColormapResEditField.Value);(1:app.Options_ColormapResEditField.Value)])
                colormap(app.ColorMapPreview,app.ColorMapValues)
                app.ColorMapPreview.XLim=[0 app.Options_ColormapResEditField.Value];
                app.ColorMapPreview.YLim=[0.5 2.5];
            end
        end

        function  update_plot_cmap(app)
            app.UIAxes_big.Colormap=app.ColorMapValues;
        end
    end

    methods (Access = public)

        function ClassificationReturn(app,Classification,sampleinfo)
            %return fun
            % ask if groups should be overwritten

            % write class numbers and names in
            % app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Group
            % update everything
            n=1;
            m=1;
            c=Classification.Classes(n).ClassMap(m).classNumber;
            c(isnan(c))=max(c(not(isnan(c)))+1);
            app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Group(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Group>0)=c;

            update_UI(app)
            update_plots(app);
            %
            update_FigHistLive(app)
            disp('DONE')

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DataTable)
            movegui(app.SpotVisModuleUIFigure, 'center');
            app.mainapp=mainapp;

            if not(isempty(DataTable))
                app.DataTable=DataTable;
                app.SampleDropDown.Visible=false;
                app.selected_sample=[];
                app.mainapp=mainapp.MinPlotX;
            elseif not(isempty(app.mainapp.active_entry))
                app.selected_sample=app.mainapp.active_entry;
                app.SampleDropDown.Visible=true;
            else
                app.selected_sample=1;
                app.SampleDropDown.Visible=true;

                update_UI(app)
            end

            %ATH here change for standalone
            app.Options_ColormapDropDown.Items=app.mainapp.Options_ColormapDropDown.Items;
            app.Options_ColormapDropDown.Value=app.mainapp.Options_ColormapDropDown.Value;
            PlotMap_UpdateColormap(app,256);

            update_UI(app)
            %
            update_FigHistLive(app)

            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);

        end

        % Callback function
        function Options_ColormapDropDownValueChanged(app, event)
            axis2plot=update_plots(app,true);

            update_plot_cmap(app)
        end

        % Value changed function: BEditField, BEditField_2, DropDown, 
        % GEditField, GEditField_2, GroupListBox, GroupMeanButton, 
        % GroupProfilesButton, ImageButton, InterpolationButton, 
        % LabelsButton, MeanButton, Options_ColormapResEditField, 
        % Options_InvertColormap, Options_LogColormap, 
        % Options_LowerCheckBox, Options_LowerColor, 
        % Options_UpperCheckBox, Options_UpperColor, REditField, 
        % REditField_2, SpotTransparencySlider, SpotsButton, 
        % SpotsizeSpinner, ntoplotEditField
        function plot_update(app, event)
            update_plots(app);
            update_plot_cmap(app)
        end

        % Value changed function: EditField_xlimMax, EditField_xlimMin
        function EditField_xlimMaxValueChanged(app, event)

            app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
            if isempty(app.hLineToDrag)

            end

            int=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            xlimval=app.FigXlimLive.XLim;
            EditField_climMaxValueChanged(app)

            if max(int) > max(xlimval)|| min(int) < min(xlimval)
                if min(int) < min(xlimval)
                    app.EditField_xlimMin.Value= app.EditField_xlimMin.Value;
                end
                if max(int) > max(xlimval)
                    app.EditField_xlimMax.Value= app.EditField_xlimMax.Value;
                end

                try
                    set(app.hVerticalLines(1),'Value',app.EditField_xlimMin.Value)
                    set(app.hVerticalLines(2),'Value',app.EditField_xlimMax.Value)

                catch

                    disp('Error in int range update')
                end

            end
        end

        % Value changed function: EditField_climMax, EditField_climMin
        function EditField_climMaxValueChanged(app, event)

            ValueMin = min([ app.EditField_climMin.Value app.EditField_climMax.Value]);
            ValueMax = max([ app.EditField_climMin.Value app.EditField_climMax.Value]);

            if app.EditField_climMax.Value < app.EditField_climMin.Value
                app.EditField_climMin.Value=ValueMin;
                app.EditField_climMax.Value=ValueMax;
            end

            app.climits=[ValueMin ValueMax];

            %% update map
            axis2plot=update_plots(app,true);
            if isempty(axis2plot)
                return
            end
            if isempty(app.climits) || isequal(app.climits(1),app.climits(2))
                caxis(axis2plot,'auto');
            else
                caxis(axis2plot,sort(app.climits));
            end
        end

        % Button down function: FigXlimLive
        function FigXlimLiveButtonDown(app, event)
            reset_zoom_pan(app)
            click_buffer=(max(app.FigXlimLive.XLim)-min(app.FigXlimLive.XLim))*0.05; %ATH Settingspanel

            if strcmp(app.MapGenDropDown.Value,'Absolute')
                xVertLineCoord = get(app.hVerticalLines(1),'Value');
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(1);
                    app.CX_Lim_state='x';
                end

            else
                for k=1:2
                    xVertLineCoord = get(app.hVerticalLines(k),'Value');
                    xVertLineCoord(1)=xVertLineCoord(1);
                    if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                        app.hLineToDrag = app.hVerticalLines(k);
                        app.CX_Lim_state='x';
                        break;
                    end
                end
            end
        end

        % Button down function: FigHistLive
        function FigHistLiveButtonDown(app, event)
            reset_zoom_pan(app)
            for k=3:4
                xVertLineCoord = get(app.hVerticalLines(k),'Value');
                click_buffer=(max(app.FigHistLive.XLim)-min(app.FigHistLive.XLim))*0.05; %ATH Settingspanel
                if abs(event.IntersectionPoint(1) - xVertLineCoord(1)) < click_buffer
                    app.hLineToDrag = app.hVerticalLines(k);
                    app.CX_Lim_state='c';
                    break;
                end
            end


        end

        % Window button motion function: SpotVisModuleUIFigure
        function SpotVisModuleUIFigureWindowButtonMotion(app, event)
            if ~isempty(app.hLineToDrag)

                if strcmp(app.CX_Lim_state,'x')
                    currentPoint   = app.FigXlimLive.CurrentPoint;
                    x            = currentPoint(1,1);
                    %y            = currentPoint(1,3);
                    xlim = app.FigXlimLive.XLim;
                    %ylim = app.FigXlimLive.YLim;
                    inside_FigXlimLive = x >= xlim(1) & x <= xlim(2); %& y >= ylim(1) & y <= ylim(2) % x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2)

                    if inside_FigXlimLive

                        set(app.hLineToDrag, 'Value', x);
                        if strcmp(app.MapGenDropDown.Value,'Absolute')
                            app.EditField_xlimMax.Value = get(app.hVerticalLines(1),'Value');
                        else
                            xposlines=[get(app.hVerticalLines(1),'Value') get(app.hVerticalLines(2),'Value')];
                            app.EditField_xlimMin.Value = min(xposlines);
                            app.EditField_xlimMax.Value = max(xposlines);
                        end


                    else
                        SpotVisModuleUIFigureWindowButtonUp(app)
                    end

                else

                    currentPoint   = app.FigHistLive.CurrentPoint;
                    x            = currentPoint(1,1);
                    y            = currentPoint(1,3);
                    xlim = app.FigHistLive.XLim;
                    ylim = app.FigHistLive.YLim;
                    inside_FigHistLive = x >= xlim(1) & x <= xlim(2) & y >= ylim(1) & y <= ylim(2); %(val*app.clickbuffer)

                    if inside_FigHistLive

                        if not(isempty(ishandle(app.hLineToDrag)))

                            set(app.hLineToDrag, 'Value', x);


                        else
                            app.hLineToDrag = 0;
                            SpotVisModuleUIFigureWindowButtonUp(app)
                            'here'
                            return
                        end
                        xposlines=[get(app.hVerticalLines(3),'Value') get(app.hVerticalLines(4),'Value')];
                        app.EditField_climMin.Value = min(xposlines);
                        app.EditField_climMax.Value = max(xposlines);
                        %                         if app.UIAxes_big.Visible==true
                        %                             caxis(app.UIAxes_big,sort([min(xposlines) max(xposlines)]));
                        %                         end
                        %                         if app.UIAxes_small.Visible==true
                        %                             caxis(app.UIAxes_small,sort([min(xposlines) max(xposlines)]));
                        %                         end
                        %% update map
                        if app.AutoUpdateButton.Value==true
                            if isempty(xposlines) || isequal(xposlines(1),xposlines(2))
                                caxis(app.UIAxes_big,'auto');
                            else
                                caxis(app.UIAxes_big,sort(xposlines));
                            end
                        end
                    else
                        app.hLineToDrag = 0;
                        SpotVisModuleUIFigureWindowButtonUp(app)
                    end

                end

            end

        end

        % Window button up function: SpotVisModuleUIFigure
        function SpotVisModuleUIFigureWindowButtonUp(app, event)
            if ~isempty(app.hLineToDrag)
                app.hLineToDrag = [];

                if strcmp(app.CX_Lim_state,'x')


                    set(app.hVerticalLines(1),'Value',app.EditField_xlimMin.Value)
                    set(app.hVerticalLines(2),'Value',app.EditField_xlimMax.Value)

                    if app.EditField_xlimMin.Value<app.EditField_xlimMin.Value
                        app.EditField_xlimMin.Value=app.EditField_xlimMin.Value;
                        app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                    end

                    if app.EditField_xlimMax.Value>app.EditField_xlimMax.Value
                        app.EditField_xlimMax.Value=app.EditField_xlimMax.Value;
                        app.FigXlimLive.XLim=sort([app.EditField_xlimMin.Value app.EditField_xlimMax.Value]);
                    end


                    ResetButton_3Pushed(app)
                    % update_FigHistLive(app)
                else
                    set(app.hVerticalLines(3),'Value',app.EditField_climMax.Value)
                    set(app.hVerticalLines(4),'Value',app.EditField_climMin.Value)
                    app.climits=sort([app.EditField_climMax.Value app.EditField_climMin.Value]);
                    if app.AutoUpdateButton.Value==false
                        axis2plot=update_plots(app,true);
                        if not(isempty(axis2plot))
                            if isempty(app.climits) || isequal(app.climits(1),app.climits(2))
                                caxis(axis2plot,'auto');
                            else
                                caxis(axis2plot,sort(app.climits));
                            end
                        end
                    end
                end
                if   strcmp(app.SpotColorDropDown.Value,'Value') || app.InterpolationButton.Value==true
                    update_table(app)
                    %  update_plots(app);
                end
                %
            end

        end

        % Value changed function: SampleDropDown
        function SampleDropDownValueChanged(app, event)
            app.selected_image=1;
            app.selected_sample=app.SampleDropDown.Value;


            update_UI(app)
            %
            ResetButton_3Pushed(app)

            if app.n_spots>250
                app.LabelsButton.Value=false;
                app.InterpolationButton.Value=true;
                app.SpotsButton.Value=false;
            end

        end

        % Value changed function: ListBox_img
        function ListBox_imgValueChanged(app, event)
            value = app.ListBox_img.Value;
            app.selected_image=value;
            update_plots(app);
        end

        % Value changed function: LabelColorDropDown
        function LabelColorDropDownValueChanged(app, event)
            value = app.LabelColorDropDown.Value;
            if strcmp(value,'Custom')
                state=true;
            else
                state=false;
            end

            app.REditField_2.Visible=state;
            app.REditField_2Label.Visible=state;
            app.GEditField_2.Visible=state;
            app.GEditField_2Label.Visible=state;
            app.BEditField_2.Visible=state;
            app.BEditField_2Label.Visible=state;

            update_plots(app);
        end

        % Value changed function: SpotColorDropDown
        function SpotColorDropDownValueChanged(app, event)
            value = app.SpotColorDropDown.Value;
            if strcmp(value,'Custom')
                state1=true;
            else
                state1=false;
            end
            app.REditField.Visible=state1;
            app.REditFieldLabel.Visible=state1;
            app.GEditField.Visible=state1;
            app.GEditFieldLabel.Visible=state1;
            app.BEditField.Visible=state1;
            app.BEditFieldLabel.Visible=state1;


            update_plots(app);
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));
            oldval=            event.PreviousValue;
            %app.SampleDropDown.ItemsData(val)

            newval=event.Value;

            if oldval<newval
                val_=+1;
            else
                val_=-1;
            end

            if val+val_==0 || val+val_>numel(app.SampleDropDown.ItemsData)
                return
            end
            app.selected_sample=app.SampleDropDown.ItemsData(val_+val);
            app.Spinner.Value=0;
            app.SampleDropDown.Value= app.SampleDropDown.ItemsData(val_+val);
            SampleDropDownValueChanged(app)

        end

        % Cell edit callback: UITable_side
        function UITable_sideCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            if isempty(unique(app.UITable_side.Selection)) && not(indices(2)==1)
                message={'No spectra selected!','Please select at least one spectra'};
                uialert(app.SpotVisModuleUIFigure,message,'Error');
                return
            elseif  indices(2)==1
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask(indices(1))=newData;
                update_plots(app);
                %
                update_FigHistLive(app)

                return
            end

            if size(app.UITable_side.Selection(1,:))>1
                error('Single Column editable only')
            elseif strcmp(app.UITable_side.ColumnName(indices(2)),'Id')
                error('SpecIndex can not be edited')
            elseif strcmp(app.UITable_side.ColumnName(indices(2)),'Value')
                error('Integration can not be edited')
            else

                fn=app.UITable_side.ColumnName{indices(2)};

                % for n=1:numel(idx)

                %testvar =  app.Data.Meta(idx(n)).(char(fn));

                if any(strcmp(char(fn),{'AnalysisName'}))
                    %ath change
                    update_table(app)
                    update_plots(app);
                    %
                    update_FigHistLive(app)

                elseif any(strcmp(char(fn),{'Comment'}))
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MaskComment(indices(1))=newData;
                    update_table(app)
                elseif any(strcmp(char(fn),{'Group'}))
                    if isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'MetaTableRaw')&& not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTableRaw))
                        app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTableRaw=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable;
                    end
                    selections=find(ismember(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Fileindex,indices(1)));
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Group(indices(1))=newData;

                    update_UI(app)


                end
            end


        end

        % Menu selected function: EditMenu
        function EditMenuSelected(app, event)
            if isempty(unique(app.UITable_side.Selection))
                message={'No spectra selected!','Please select at least one spectra'};
                uialert(app.SpotVisModuleUIFigure,message,'Error');
                return
            end

            if size(app.UITable_side.Selection(1,:))>1
                error('Single Column editable only')
            elseif strcmp(app.UITable_side.ColumnName(app.UITable_side.Selection(1,2)),'Id')
                error('SpecIndex can not be edited')
            elseif strcmp(app.UITable_side.ColumnName(app.UITable_side.Selection(1,2)),'Value')
                error('Integration can not be edited')
            else

                fn=app.UITable_side.ColumnName{app.UITable_side.Selection(1,2)};

                newval= inputdlg(['Enter new Value for ' fn],...
                    'Enter Value', 1);
                if isempty(newval)
                    return
                elseif iscell(newval)
                    newval=newval{:};
                end

                selections = unique(app.UITable_side.Selection(:,1));

                % for n=1:numel(idx)

                %testvar =  app.Data.Meta(idx(n)).(char(fn));

                if any(strcmp(char(fn),{'AnalysisName'}))
                    %ATH CHANGE
                    update_table(app)
                    update_plots(app);
                elseif any(strcmp(char(fn),{'Comment'}))
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MaskComment{selections}=newval;
                    update_table(app)
                    update_plots(app);
                elseif any(strcmp(char(fn),{'Group'}))
                    if isfield(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id),'MetaTableRaw')&& not(isempty(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTableRaw))
                        app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTableRaw=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable;
                    end
                    selections=find(ismember(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Fileindex,selections));
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Group(selections)=str2double(newval);
                    update_UI(app)
                end


                %  end
            end


        end

        % Image clicked function: MaskSelection
        function MaskSelectionClicked(app, event)
            if  isempty(app.UITable_side.Selection(:,1))
                return
            end

            selections = unique(app.UITable_side.Selection(:,1));
            for n=1:numel(selections)
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask(selections(n))=true;
            end
            app.ListBox_2.Value={};


            update_table(app)
            update_plots(app);

            update_FigHistLive(app)


        end

        % Image clicked function: UnmaskSelection
        function UnmaskSelectionImageClicked(app, event)
            if  isempty(app.UITable_side.Selection) ||  isempty(app.UITable_side.Selection(:,1))
                return
            end

            selections = unique(app.UITable_side.Selection(:,1));
            for n=1:numel(selections)
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask(selections(n))=false;
            end

            app.ListBox_2.Value={};

            update_table(app)
            update_plots(app);

            update_FigHistLive(app)


        end

        % Image clicked function: InvertMask
        function InvertMaskImageClicked(app, event)
            if app.SampleDropDown.Visible==true

                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask=not(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask);
            else
                app.UITable_side.Data.active=not(app.UITable_side.Data.active);
            end
            app.ListBox_2.Value={};
            update_table(app)
            update_plots(app);
            update_FigHistLive(app)

        end

        % Callback function: Button_2, ResetMask
        function ResetMaskImageClicked(app, event)
            if app.SampleDropDown.Visible==true

                mask=true(size(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).data(:,1),1),1);

                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask=mask;
            else
                app.UITable_side.Data.active(:)=true;
            end


            app.ListBox_2.Value={};

            update_table(app)
            update_plots(app);

            update_FigHistLive(app)

        end

        % Key press function: SpotVisModuleUIFigure
        function SpotVisModuleUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'downarrow')
                val_=-1;
            elseif strcmp(key,'uparrow')
                val_=+1;
            else
                return
            end

            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));

            if val+val_==0 || val+val_>numel(app.SampleDropDown.ItemsData)
                return
            end
            app.selected_sample=app.SampleDropDown.ItemsData(val_+val);
            app.Spinner.Value=0;
            app.SampleDropDown.Value= app.SampleDropDown.ItemsData(val_+val);
            SampleDropDownValueChanged(app)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            region=sort(app.climits);
            if app.SampleDropDown.Visible==true
                selections=app.UITable_side.Data.Id(app.UITable_side.Data.Value<max(region) & app.UITable_side.Data.Value>min(region) & app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask==true);
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask(:)=false;
                for n=1:numel(selections)
                    app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask(find(ismember(app.UITable_side.Data.Id,selections(n))))=true;
                end

            else
                selections=app.UITable_side.Data.Id(app.UITable_side.Data.Value<max(region) & app.UITable_side.Data.Value>min(region) & app.DataTable.Data.Active==true);
                app.UITable_side.Data.active(selections)=true;
                app.UITable_side.Data.active(not(selections))=false;
            end

            update_table(app)
            update_plots(app);

            update_FigHistLive(app)

        end

        % Button pushed function: ToggleAllButton
        function ToggleAllButtonPushed(app, event)
            %toggle all
            if isempty(app.GroupListBox.Value)
                app.GroupListBox.Value=app.GroupListBox.ItemsData;
            else
                app.GroupListBox.Value=[];
            end
            update_plots(app); %split?
        end

        % Button pushed function: SelectinTableButton
        function SelectinTableButtonPushed(app, event)
            %
            selection=app.UITable_side.Data.Id(ismember(app.UITable_side.Data.Group,app.GroupListBox.Value));
            %app.UITable_side.Selection=[selection, ones(size(selection,1),1);selection, ones(size(selection,1),1).*2;selection, ones(size(selection,1),1).*3;selection, ones(size(selection,1),1).*4;selection, ones(size(selection,1),1).*5];

            app.UITable_side.Selection=[ selection, ones(size(selection,1),1).*3];

        end

        % Menu selected function: Classification2GroupsMenu
        function Classification2GroupsMenuSelected(app, event)
            app.UITable_side.Data.Group
            nanpoints= (app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask==false);% ->nan excluded

            [~,~,x,y]=get_profiles_to_plot(app,app.selected_sample,false,app.ListBox_Col2Plot.Value);

            y(:,nanpoints)=nan;

            %% how to classify
            % classification module with only one allowed return class


            mapsize=[size(y,2),1];
            %ATH change
            cmap=[];
            %ClassificationModule(app,  x, y, sort(app.UITable_side),app.UITable_side.Data.Id , mapsize,cmap);


        end

        % Menu selected function: EditImageNameMenu
        function EditImageNameMenuSelected(app, event)
            % find image id
            new_name=inputdlg('Enter New Image Name',...
                'Image_name', 1);
            if not(isempty(new_name))
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageNames{app.ListBox_img.ItemsData}=new_name{:};

            end
            update_UI(app)
            % change image name
        end

        % Menu selected function: AddImageMenu
        function AddImageMenuSelected(app, event)
            % open import module
            ReferenceMaps.MapPlotMatrix=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).Image{app.selected_image};
            ReferenceMapsName=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageNames{app.selected_image};
            MatrixLimits=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageLimits{app.selected_image};
            sampleinfo=[app.selected_image_group app.selected_image];

            [file, path]=uigetfile("MultiSelect",'on','*.jpg;*.gif;*.png;*.bmp;*.tif;*.tiff');
            if file==0
                return
            elseif iscell(file)

                for n=1:numel(file)
                    matrix_to_match(n).MapPlotMatrix=imread(fullfile(path,file));
                    matrix_to_matchNames{n}=file{n};
                end
            else
                matrix_to_match.MapPlotMatrix=imread(fullfile(path,file));

                matrix_to_matchNames={file};
            end

            % ImageMatchModule(app.mainapp, ReferenceMaps, ReferenceMapsName, sampleinfo, MatrixLimits, matrix_to_match, matrix_to_matchNames)
        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.reset_value=true;
            % update_UI(app)


            ResetButton_3Pushed(app)

        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.reset_value=true;
            update_FigHistLive(app)
            update_plots(app);


        end

        % Menu selected function: PlotProfileMenu
        function PlotProfileMenuSelected(app, event)
            if ismember('profile_distance',app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Properties.VariableNames)
                x=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.profile_distance;
            elseif ismember('ProfileDistance',app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.Properties.VariableNames)
                x=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable.ProfileDistance;
            else
                x=app.UITable_side.Data.Id;
            end
            y=app.UITable_side.Data.Value;
            active=app.UITable_side.Data.(1);
            groups=app.UITable_side.Data.Group;
            ug= unique(groups(not(isnan(groups))));

            cmap = PlotMap_UpdateColormap(app,numel(ug));
            %             if isempty(app.GroupListBox.Value)
            %                 ToggleAllButtonPushed(app)
            %             end

            figure
            % if numel(app.GroupListBox.Value)>1
            nexttile
            hold on
            title(app.ListBox_Col2Plot.Items(ismember(app.ListBox_Col2Plot.ItemsData,app.ListBox_Col2Plot.Value)),'Interpreter','none')
            for n=1:numel(ug)
                %if ismember(ug(n),app.GroupListBox.Value)
                plot(x(active& ismember(groups,ug(n))),y(active&ismember(groups,ug(n))),LineWidth=2,Color='k',LineStyle=":",Marker="o",MarkerFaceColor=cmap(n,:))
                %  end
            end
            % end


        end

        % Callback function
        function CallibrateThicknessExperimentalMenuSelected(app, event)
            if numel(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Thickness)==1
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Thickness=ones(size(app.UITable_side.Data,1),1).*app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Thickness;
            end

            t_in = max(app.UITable_side.Data.Thickness(app.UITable_side.Data.active));
            [~, id]= max(app.UITable_side.Data.Value(app.UITable_side.Data.active));

            definput = {num2str(id),num2str(t_in)};

            x  = inputdlg({'Id to calc Coefficient','thickness in µm'}, ['Parameters'],[1 15; 1 15],definput);
            if ~isempty(x)
                id2otcalc=str2num(x{1}); %#ok
                thickness=str2double(x(2));

            else
                return
            end
            if isnumeric(thickness) && not(isnan(thickness)) && not(isinf(thickness))
                OvertoneThicknessCoeff=app.UITable_side.Data.Value(id2otcalc)/thickness;
                newData=app.UITable_side.Data.Thickness;
                newData(app.UITable_side.Data.active)=app.UITable_side.Data.Value(app.UITable_side.Data.active)./OvertoneThicknessCoeff;
                newData=round(newData,1);
                app.UITable_side.Data.Thickness(app.UITable_side.Data.active,1)=newData(app.UITable_side.Data.active);
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Thickness=newData;
                app.mainapp.MinPlotXData.Sample(app.selected_sample).Meta(app.selected_sample).Thickness=mean(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Thickness(app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).Mask),"omitnan");
                update_table(app)
                update_plots(app);

                update_FigHistLive(app)
            end
        end

        % Menu selected function: ExtractGroupsMenu
        function ExtractGroupsMenuSelected(app, event)
            ExtractGroups(app.mainapp,app.IdEditField.Value)
        end

        % Menu selected function: Profile2Menu
        function Profile2MenuSelected(app, event)
            try
                for k= numel(app.selected_image)
                    Image=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).Image{app.selected_image(k)};
                    ImageLimits=app.mainapp.MinPlotXData.Sample(app.selected_sample).Images.Images(app.selected_image_group).ImageLimits{app.selected_image(k)};
                end
            catch ME
                Image=[];
                ImageLimits=[];
                disp('Error inProfile2MenuSelected ')
                disp(ME)

            end

            meta_tbl=app.mainapp.MinPlotXData.Sample(app.selected_sample).Input(app.calculation_id).MetaTable;

            X=meta_tbl.x;
            Y=meta_tbl.y;

            %%
            [~,~,y,x]=get_profiles_to_plot(app,app.selected_sample,false,app.ListBox_Col2Plot.Value);


            Id=app.UITable_side.Data.Id;
            value=app.UITable_side.Data.Value;
            mask=app.UITable_side.Data.active;

            active=app.UITable_side.Data.(1);
            groups=app.UITable_side.Data.Group;
            ug= unique(groups(not(isnan(groups))));
            cmap = PlotMap_UpdateColormap(app,numel(ug));
            xlimit=[app.EditField_xlimMax.Value app.EditField_xlimMin.Value];


            %             if isempty(app.GroupListBox.Value)
            %                 ToggleAllButtonPushed(app)
            %             end

            %   if numel(app.GroupListBox.Value)>=1
            for n=1:numel(ug)
                if ismember(ug(n),app.GroupListBox.Value)
                    %Id_check=meta_tbl.Fileindex;
                    plot_profiles_NG(X(active& ismember(groups,ug(n))),Y(active& ismember(groups,ug(n))),value(active& ismember(groups,ug(n))),Id(active& ismember(groups,ug(n))),mask(active& ismember(groups,ug(n))),x,y(:,active& ismember(groups,ug(n))),Image,ImageLimits,xlimit)
                end
                %   end
            end


            %%


        end

        % Value changed function: ListBox_Col2Plot, MapGenDropDown
        function MapGenDropDownValueChanged(app, event)

            xl=[app.EditField_xlimMin.Value app.EditField_xlimMax.Value];
            region=[app.EditField_xlimMax.Value app.EditField_xlimMin.Value];

            if min(region)<min(xl) || min(region)>max(xl) || max(region)>max(xl) || max(region)<min(xl)
                app.EditField_xlimMin.Value=min(xl);
                app.EditField_xlimMax.Value=max(xl);
            end


            update_table(app)
            update_plots(app);

            update_FigHistLive(app)
            ResetButton_3Pushed(app)

        end

        % Menu selected function: ExportTableMenu
        function ExportTableMenuSelected(app, event)
            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));
            if  app.EditField_xlimMin.Visible==true

                filename=[ app.SampleDropDown.Items{val} '_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value) '_export.csv'];
            else
                filename=[ app.SampleDropDown.Items{val} '_'  num2str(app.EditField_xlimMax.Value) '_export.csv'];
            end

            [filename, filepath] = uiputfile({'*.csv'}, 'Save the table:',fullfile(app.exportpath,filename));
            if not(filename==0)
                app.exportpath=filepath;
                FileName = fullfile(app.exportpath, filename);
                writetable(app.UITable_side.Data,FileName)
            end
        end

        % Callback function
        function ExportButtonPushed(app, event)
            val=find(ismember(app.SampleDropDown.ItemsData,app.selected_sample));
            if  app.EditField_xlimMin.Visible==true

                filename=[ app.SampleDropDown.Items{val} '_' num2str(app.EditField_xlimMin.Value) '_' num2str(app.EditField_xlimMax.Value) '_export.csv'];
            else
                filename=[ app.SampleDropDown.Items{val} '_'  num2str(app.EditField_xlimMax.Value) '_export.csv'];
            end

            [filename, filepath] = uiputfile({'*.csv'}, 'Save the table:',fullfile(app.exportpath,filename));
            if not(filename==0)
                app.exportpath=filepath;
                FileName = fullfile(app.exportpath, filename);
                writetable(app.UITable_side.Data,FileName)
            end
        end

        % Value changed function: AdvancedSettingsCheckBox
        function AdvancedSettingsCheckBoxValueChanged(app, event)

            app.ListBox.Visible=app.AdvancedSettingsCheckBox.Value;
            app.ListBox_2.Visible=app.AdvancedSettingsCheckBox.Value;
            app.nEditField.Visible=app.AdvancedSettingsCheckBox.Value;
            app.nEditFieldLabel.Visible=app.AdvancedSettingsCheckBox.Value;

            ListBoxValueChanged(app)

            if app.SampleDropDown.Visible==false
                ColNames=app.DataTable.Data.Properties.VariableNames(not(any(cellfun(@isnumeric,table2cell(app.DataTable.Data)))|any(cellfun(@islogical,table2cell(app.DataTable.Data)))));
            else
                T=get_compiled_table(app.mainapp,app.selected_sample);
                ColNames=T.Properties.VariableNames(not(any(cellfun(@isnumeric,table2cell(T)))|any(cellfun(@islogical,table2cell(T)))));
            end


            app.ListBox.Items=ColNames;

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            if app.SampleDropDown.Visible==false
                T=app.DataTable.Data;
            else
                T=get_compiled_table(app.mainapp,app.selected_sample);
            end
            if ismember(app.ListBox.Value,T.Properties.VariableNames)
                [x, ~]=  unique(T.(char(app.ListBox.Value)),'stable');

                app.nEditField.Value=numel(x);

                if app.AdvancedSettingsCheckBox.Value==false || numel(x)>50
                    app.ListBox_2.Visible=false;

                else
                    try
                        app.ListBox_2.Items=x;
                        app.ListBox_2.Value=x;
                    catch

                    end
                    app.ListBox_2.Visible=true;

                end
            else
                app.ListBox_2.Items={};
            end
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            if app.SampleDropDown.Visible==false
                T=app.DataTable.Data;
            else
                T=get_compiled_table(app.mainapp,app.selected_sample);
            end

            selection=app.UITable_side.Data.Id((ismember(T.(char(app.ListBox.Value)),app.ListBox_2.Value)));

            app.UITable_side.Selection=[ selection, ones(size(selection,1),1).*3];

            update_plots(app);

            update_FigHistLive(app)

        end

        % Value changed function: Options_ColormapDropDown
        function Options_ColormapDropDownValueChanged2(app, event)
            PlotMap_UpdateColormap(app,app.Options_ColormapResEditField.Value);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.SpotVisModuleUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {798, 798};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {412, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpotVisModuleUIFigure = uifigure('Visible', 'off');
            app.SpotVisModuleUIFigure.Name = 'SpotVisModule';
            app.SpotVisModuleUIFigure.Icon = 'symbols_1_Spotvis.png';
            app.SpotVisModuleUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.SpotVisModuleUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @SpotVisModuleUIFigureWindowButtonUp, true);
            app.SpotVisModuleUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @SpotVisModuleUIFigureWindowButtonMotion, true);
            app.SpotVisModuleUIFigure.KeyPressFcn = createCallbackFcn(app, @SpotVisModuleUIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.SpotVisModuleUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.FigHistLive = uiaxes(app.GridLayout4);
            app.FigHistLive.ButtonDownFcn = createCallbackFcn(app, @FigHistLiveButtonDown, true);
            app.ListBox_img = uilistbox(app.GridLayout4);
            app.ListBox_img.Items = {'Image 1', 'Image 2'};
            app.ListBox_img.Multiselect = 'on';
            app.ListBox_img.ValueChangedFcn = createCallbackFcn(app, @ListBox_imgValueChanged, true);
            app.ListBox_img.Visible = 'off';
            app.ListBox_img.Value = {'Image 1'};
            app.ImageButton = uibutton(app.GridLayout4, 'state');
            app.ImageButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.ImageButton.Enable = 'off';
            app.ImageButton.Text = 'Image';
            app.SpotsButton = uibutton(app.GridLayout4, 'state');
            app.SpotsButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpotsButton.Enable = 'off';
            app.SpotsButton.Text = 'Spots';
            app.SpotColorDropDownLabel = uilabel(app.GridLayout4);
            app.SpotColorDropDownLabel.Text = 'Spot Color';
            app.SpotColorDropDown = uidropdown(app.GridLayout4);
            app.SpotColorDropDown.Items = {'Value', 'Group', 'White', 'Black', 'Red', 'Green', 'Blue', 'Custom'};
            app.SpotColorDropDown.ValueChangedFcn = createCallbackFcn(app, @SpotColorDropDownValueChanged, true);
            app.SpotColorDropDown.Value = 'Value';
            app.LabelsButton = uibutton(app.GridLayout4, 'state');
            app.LabelsButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.LabelsButton.Enable = 'off';
            app.LabelsButton.Text = 'Labels';
            app.LabelColorDropDownLabel = uilabel(app.GridLayout4);
            app.LabelColorDropDownLabel.Text = 'Lable Color';
            app.LabelColorDropDown = uidropdown(app.GridLayout4);
            app.LabelColorDropDown.Items = {'Black', 'White', 'Red', 'Blue', 'Green', 'Custom'};
            app.LabelColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelColorDropDownValueChanged, true);
            app.LabelColorDropDown.Value = 'White';
            app.BEditField_2Label = uilabel(app.GridLayout4);
            app.BEditField_2Label.Visible = 'off';
            app.BEditField_2Label.Text = 'B';
            app.BEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.BEditField_2.Limits = [0 1];
            app.BEditField_2.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.BEditField_2.Visible = 'off';
            app.GEditField_2Label = uilabel(app.GridLayout4);
            app.GEditField_2Label.Visible = 'off';
            app.GEditField_2Label.Text = 'G';
            app.GEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.GEditField_2.Limits = [0 1];
            app.GEditField_2.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GEditField_2.Visible = 'off';
            app.REditField_2Label = uilabel(app.GridLayout4);
            app.REditField_2Label.Visible = 'off';
            app.REditField_2Label.Text = 'R';
            app.REditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.REditField_2.Limits = [0 1];
            app.REditField_2.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.REditField_2.Visible = 'off';
            app.SampleDropDown = uidropdown(app.GridLayout4);
            app.SampleDropDown.ValueChangedFcn = createCallbackFcn(app, @SampleDropDownValueChanged, true);
            app.SampleDropDown.Visible = 'off';
            app.InterpolationButton = uibutton(app.GridLayout4, 'state');
            app.InterpolationButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.InterpolationButton.Enable = 'off';
            app.InterpolationButton.Text = 'Interpolation';
            app.REditFieldLabel = uilabel(app.GridLayout4);
            app.REditFieldLabel.Visible = 'off';
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.GridLayout4, 'numeric');
            app.REditField.Limits = [0 1];
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.REditField.Visible = 'off';
            app.REditField.Value = 1;
            app.GEditFieldLabel = uilabel(app.GridLayout4);
            app.GEditFieldLabel.Visible = 'off';
            app.GEditFieldLabel.Text = 'G';
            app.GEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GEditField.Limits = [0 1];
            app.GEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GEditField.Visible = 'off';
            app.GEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.GridLayout4);
            app.BEditFieldLabel.Visible = 'off';
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.GridLayout4, 'numeric');
            app.BEditField.Limits = [0 1];
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.BEditField.Visible = 'off';
            app.BEditField.Value = 1;
            app.Spinner = uispinner(app.GridLayout4);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Visible = 'off';
            app.IdEditFieldLabel = uilabel(app.GridLayout4);
            app.IdEditFieldLabel.Visible = 'off';
            app.IdEditFieldLabel.Text = 'Id';
            app.IdEditField = uieditfield(app.GridLayout4, 'numeric');
            app.IdEditField.Editable = 'off';
            app.IdEditField.Visible = 'off';
            app.SpotsizeSpinnerLabel = uilabel(app.GridLayout4);
            app.SpotsizeSpinnerLabel.Text = 'Spotsize';
            app.SpotsizeSpinner = uispinner(app.GridLayout4);
            app.SpotsizeSpinner.Step = 10;
            app.SpotsizeSpinner.Limits = [1 1000];
            app.SpotsizeSpinner.RoundFractionalValues = 'on';
            app.SpotsizeSpinner.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpotsizeSpinner.Value = 250;
            app.EditField_climMin = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_climMin.ValueDisplayFormat = '%3.4g';
            app.EditField_climMin.ValueChangedFcn = createCallbackFcn(app, @EditField_climMaxValueChanged, true);
            app.EditField_climMax = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_climMax.ValueDisplayFormat = '%3.4g';
            app.EditField_climMax.ValueChangedFcn = createCallbackFcn(app, @EditField_climMaxValueChanged, true);
            app.EditField_climMax.Value = 1;
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'symbols_1_Mask Selection.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ResetMaskImageClicked, true);
            app.Button_2.Icon = 'symbols_1_Clear Mask.png';
            app.Button_2.Text = {''; ''};
            app.ResetButton_3 = uibutton(app.GridLayout4, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.AutoUpdateButton = uibutton(app.GridLayout4, 'state');
            app.AutoUpdateButton.Text = 'AutoUpdate';
            app.AutoUpdateButton.Value = true;
            app.MapGenDropDown = uidropdown(app.GridLayout4);
            app.MapGenDropDown.Items = {'Integration', 'Absolute', 'Ratio', 'Ratio Norm', 'Ratio log/log', 'Ratio NormLog', 'Entropy'};
            app.MapGenDropDown.ValueChangedFcn = createCallbackFcn(app, @MapGenDropDownValueChanged, true);
            app.MapGenDropDown.Visible = 'off';
            app.MapGenDropDown.Value = 'Integration';
            app.ListBox_Col2Plot = uilistbox(app.GridLayout4);
            app.ListBox_Col2Plot.Items = {};
            app.ListBox_Col2Plot.ValueChangedFcn = createCallbackFcn(app, @MapGenDropDownValueChanged, true);
            app.ListBox_Col2Plot.Value = {};
            app.AdvancedSettingsCheckBox = uicheckbox(app.GridLayout4);
            app.AdvancedSettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedSettingsCheckBoxValueChanged, true);
            app.AdvancedSettingsCheckBox.Text = 'Advanced Settings';
            app.ListBox = uilistbox(app.GridLayout4);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Visible = 'off';
            app.ListBox_2 = uilistbox(app.GridLayout4);
            app.ListBox_2.Multiselect = 'on';
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Visible = 'off';
            app.ListBox_2.Value = {'Item 1'};
            app.nEditFieldLabel = uilabel(app.GridLayout4);
            app.nEditFieldLabel.Visible = 'off';
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout4, 'numeric');
            app.nEditField.Editable = 'off';
            app.nEditField.Visible = 'off';
            app.SpotTransparencySlider = uislider(app.GridLayout4);
            app.SpotTransparencySlider.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.SpotTransparencySlider.Value = 100;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes_big = uiaxes(app.GridLayout3);
            xlabel(app.UIAxes_big, 'X')
            ylabel(app.UIAxes_big, 'Y')
            zlabel(app.UIAxes_big, 'Z')
            app.UITable_side = uitable(app.GridLayout3);
            app.UITable_side.ColumnName = {'Mask'; 'SpecIndex'; 'AnalysisName'; 'Value'; 'Group'; 'Comment'; 'x_pos'; 'y_pos'};
            app.UITable_side.RowName = {};
            app.UITable_side.ColumnSortable = [true true true true true true];
            app.UITable_side.ColumnEditable = [true false true false false false];
            app.UITable_side.CellEditCallback = createCallbackFcn(app, @UITable_sideCellEdit, true);
            app.MaskSelection = uiimage(app.GridLayout3);
            app.MaskSelection.ImageClickedFcn = createCallbackFcn(app, @MaskSelectionClicked, true);
            app.MaskSelection.ImageSource = 'symbols_1_Mask Selection.png';
            app.UnmaskSelection = uiimage(app.GridLayout3);
            app.UnmaskSelection.ImageClickedFcn = createCallbackFcn(app, @UnmaskSelectionImageClicked, true);
            app.UnmaskSelection.ImageSource = 'symbols_1_Substract from Mask.png';
            app.InvertMask = uiimage(app.GridLayout3);
            app.InvertMask.ImageClickedFcn = createCallbackFcn(app, @InvertMaskImageClicked, true);
            app.InvertMask.ImageSource = 'symbols_1_Invert Mask.png';
            app.ResetMask = uiimage(app.GridLayout3);
            app.ResetMask.ImageClickedFcn = createCallbackFcn(app, @ResetMaskImageClicked, true);
            app.ResetMask.ImageSource = 'symbols_1_Clear Mask.png';
            app.GridLayout_2 = uigridlayout(app.GridLayout3);
            app.ColorMapPreview = uiaxes(app.GridLayout_2);
            app.ColorMapPreview.XTick = [];
            app.ColorMapPreview.YTick = [];
            app.Options_LowerCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_LowerCheckBox.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_LowerCheckBox.Tooltip = {'Add a lower color layer'};
            app.Options_LowerCheckBox.Text = 'Lower';
            app.Options_UpperCheckBox = uicheckbox(app.GridLayout_2);
            app.Options_UpperCheckBox.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_UpperCheckBox.Tooltip = {'Add an upper color layer'};
            app.Options_UpperCheckBox.Text = 'Upper';
            app.Options_UpperColor = uidropdown(app.GridLayout_2);
            app.Options_UpperColor.Items = {'Black', 'White'};
            app.Options_UpperColor.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_UpperColor.Tooltip = {'Select the color for the upper layer'};
            app.Options_UpperColor.Value = 'Black';
            app.Options_ColormapResEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.Options_ColormapResEditField.Limits = [3 9999];
            app.Options_ColormapResEditField.RoundFractionalValues = 'on';
            app.Options_ColormapResEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_ColormapResEditField.Tooltip = {'Colormap resolution (def. 256)'};
            app.Options_ColormapResEditField.Value = 256;
            app.Options_LogColormap = uicheckbox(app.GridLayout_2);
            app.Options_LogColormap.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_LogColormap.Tooltip = {'Use a log colormap'};
            app.Options_LogColormap.Text = 'Log';
            app.ColormapDropDownLabel = uilabel(app.GridLayout_2);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.Options_ColormapDropDown = uidropdown(app.GridLayout_2);
            app.Options_ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @Options_ColormapDropDownValueChanged2, true);
            app.Options_ColormapDropDown.Tooltip = {'Select the active colormap'};
            app.Options_InvertColormap = uicheckbox(app.GridLayout_2);
            app.Options_InvertColormap.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_InvertColormap.Text = 'Invert';
            app.Options_LowerColor = uidropdown(app.GridLayout_2);
            app.Options_LowerColor.Items = {'Black', 'White'};
            app.Options_LowerColor.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.Options_LowerColor.Tooltip = {'Select the color for the lower layer'};
            app.Options_LowerColor.Value = 'Black';
            app.FigXlimLive = uiaxes(app.SpotVisModuleUIFigure);
            app.FigXlimLive.YTickLabel = '';
            app.FigXlimLive.Visible = 'off';
            app.FigXlimLive.ButtonDownFcn = createCallbackFcn(app, @FigXlimLiveButtonDown, true);
            app.EditField_xlimMin = uieditfield(app.SpotVisModuleUIFigure, 'numeric');
            app.EditField_xlimMin.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMin.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMin.Visible = 'off';
            app.MeanButton = uibutton(app.SpotVisModuleUIFigure, 'state');
            app.MeanButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.MeanButton.Visible = 'off';
            app.MeanButton.Text = 'Mean';
            app.MeanButton.Value = true;
            app.ResetButton_2 = uibutton(app.SpotVisModuleUIFigure, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Visible = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.EditField_xlimMax = uieditfield(app.SpotVisModuleUIFigure, 'numeric');
            app.EditField_xlimMax.ValueDisplayFormat = '%3.4g';
            app.EditField_xlimMax.ValueChangedFcn = createCallbackFcn(app, @EditField_xlimMaxValueChanged, true);
            app.EditField_xlimMax.Visible = 'off';
            app.EditField_xlimMax.Value = 9999;
            app.logCheckBox = uicheckbox(app.SpotVisModuleUIFigure);
            app.logCheckBox.Visible = 'off';
            app.logCheckBox.Text = 'log';
            app.NormalizeValuesCheckBox = uicheckbox(app.SpotVisModuleUIFigure);
            app.NormalizeValuesCheckBox.Visible = 'off';
            app.NormalizeValuesCheckBox.Text = 'Normalize Values';
            app.NormalizeSelectionCheckBox = uicheckbox(app.SpotVisModuleUIFigure);
            app.NormalizeSelectionCheckBox.Visible = 'off';
            app.NormalizeSelectionCheckBox.Text = 'Normalize Selection';
            app.ntoplotEditFieldLabel = uilabel(app.SpotVisModuleUIFigure);
            app.ntoplotEditFieldLabel.Visible = 'off';
            app.ntoplotEditFieldLabel.Text = 'n to plot';
            app.ntoplotEditField = uieditfield(app.SpotVisModuleUIFigure, 'numeric');
            app.ntoplotEditField.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.ntoplotEditField.Visible = 'off';
            app.ntoplotEditField.Value = 10;
            app.GroupsPanel = uipanel(app.SpotVisModuleUIFigure);
            app.GroupsPanel.Title = 'Groups';
            app.GroupsPanel.Visible = 'off';
            app.GridLayout5 = uigridlayout(app.GroupsPanel);
            app.GroupListBox = uilistbox(app.GridLayout5);
            app.GroupListBox.Items = {};
            app.GroupListBox.Multiselect = 'on';
            app.GroupListBox.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GroupListBox.Value = {};
            app.ToggleAllButton = uibutton(app.GridLayout5, 'push');
            app.ToggleAllButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleAllButtonPushed, true);
            app.ToggleAllButton.Text = 'Toggle All';
            app.SelectinTableButton = uibutton(app.GridLayout5, 'push');
            app.SelectinTableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinTableButtonPushed, true);
            app.SelectinTableButton.Text = 'Select in Table';
            app.GroupProfilesButton = uibutton(app.GridLayout5, 'state');
            app.GroupProfilesButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GroupProfilesButton.Text = 'Group Profiles';
            app.GroupMeanButton = uibutton(app.GridLayout5, 'state');
            app.GroupMeanButton.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.GroupMeanButton.Text = 'Group Mean';
            app.GroupMeanButton.Value = true;
            app.DropDown = uidropdown(app.GridLayout5);
            app.DropDown.Items = {'Default', 'Profile', 'Histogram'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @plot_update, true);
            app.DropDown.Visible = 'off';
            app.DropDown.Value = 'Default';
            app.ContextMenu = uicontextmenu(app.SpotVisModuleUIFigure);
            app.EditMenu = uimenu(app.ContextMenu);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenuSelected, true);
            app.EditMenu.Visible = 'off';
            app.EditMenu.Text = 'Edit';
            app.ExportTableMenu = uimenu(app.ContextMenu);
            app.ExportTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportTableMenuSelected, true);
            app.ExportTableMenu.Visible = 'off';
            app.ExportTableMenu.Text = 'ExportTable';
            app.Classification2GroupsMenu = uimenu(app.ContextMenu);
            app.Classification2GroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @Classification2GroupsMenuSelected, true);
            app.Classification2GroupsMenu.Visible = 'off';
            app.Classification2GroupsMenu.Text = 'Classification 2 Groups';
            app.PlotProfileMenu = uimenu(app.ContextMenu);
            app.PlotProfileMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotProfileMenuSelected, true);
            app.PlotProfileMenu.Text = 'Plot Profile';
            app.ExtractGroupsMenu = uimenu(app.ContextMenu);
            app.ExtractGroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractGroupsMenuSelected, true);
            app.ExtractGroupsMenu.Visible = 'off';
            app.ExtractGroupsMenu.Text = 'Extract Groups';
            app.DevMenu = uimenu(app.ContextMenu);
            app.DevMenu.Text = 'Dev';
            app.Profile2Menu = uimenu(app.DevMenu);
            app.Profile2Menu.MenuSelectedFcn = createCallbackFcn(app, @Profile2MenuSelected, true);
            app.Profile2Menu.Text = 'Profile2';
            % Assign app.ContextMenu
            app.UITable_side.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.SpotVisModuleUIFigure);
            app.AddImageMenu = uimenu(app.ContextMenu2);
            app.AddImageMenu.MenuSelectedFcn = createCallbackFcn(app, @AddImageMenuSelected, true);
            app.AddImageMenu.Text = 'Add Image';
            app.EditImageNameMenu = uimenu(app.ContextMenu2);
            app.EditImageNameMenu.MenuSelectedFcn = createCallbackFcn(app, @EditImageNameMenuSelected, true);
            app.EditImageNameMenu.Text = 'Edit Image Name';
            % Assign app.ContextMenu2
            app.ListBox_img.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.SpotVisModuleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpotVisMinPlotX(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpotVisModuleUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpotVisModuleUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpotVisModuleUIFigure)
        end
    end
end